classdef APP_FitArea < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FitmethodSwitch                 matlab.ui.control.Switch
        FitmethodSwitchLabel            matlab.ui.control.Label
        TRsignalinlogscaleCheckBox      matlab.ui.control.CheckBox
        InterruptButton                 matlab.ui.control.StateButton
        FLIMmapastauaveCheckBox         matlab.ui.control.CheckBox
        AmplitudethresholdEditField     matlab.ui.control.NumericEditField
        AmplitudethresholdLabel         matlab.ui.control.Label
        TausimilaritythresoldEditField  matlab.ui.control.NumericEditField
        TausimilaritythresoldEditFieldLabel  matlab.ui.control.Label
        ConstantEditField               matlab.ui.control.NumericEditField
        ConstantEditFieldLabel          matlab.ui.control.Label
        sigmaEditField                  matlab.ui.control.NumericEditField
        sigmaEditFieldLabel             matlab.ui.control.Label
        t0EditField                     matlab.ui.control.NumericEditField
        t0EditFieldLabel                matlab.ui.control.Label
        tau3EditField                   matlab.ui.control.NumericEditField
        tau3EditFieldLabel              matlab.ui.control.Label
        tau2EditField                   matlab.ui.control.NumericEditField
        tau2EditFieldLabel              matlab.ui.control.Label
        tau1EditField                   matlab.ui.control.NumericEditField
        tau1EditFieldLabel              matlab.ui.control.Label
        A3EditField                     matlab.ui.control.NumericEditField
        A3EditFieldLabel                matlab.ui.control.Label
        A2EditField                     matlab.ui.control.NumericEditField
        A2EditFieldLabel                matlab.ui.control.Label
        A1EditField                     matlab.ui.control.NumericEditField
        A1EditFieldLabel                matlab.ui.control.Label
        UBConstEditField                matlab.ui.control.NumericEditField
        LBConstEditField                matlab.ui.control.NumericEditField
        UBtau3EditField                 matlab.ui.control.NumericEditField
        UBtau2EditField                 matlab.ui.control.NumericEditField
        LBtau3EditField                 matlab.ui.control.NumericEditField
        LBtau2EditField                 matlab.ui.control.NumericEditField
        UBA3EditField                   matlab.ui.control.NumericEditField
        UBA2EditField                   matlab.ui.control.NumericEditField
        LBA3EditField                   matlab.ui.control.NumericEditField
        LBA2EditField                   matlab.ui.control.NumericEditField
        UBsigmaEditField                matlab.ui.control.NumericEditField
        LBsigmaEditField                matlab.ui.control.NumericEditField
        UBt0EditField                   matlab.ui.control.NumericEditField
        LBt0EditField                   matlab.ui.control.NumericEditField
        UBtau1EditField                 matlab.ui.control.NumericEditField
        LBtau1EditField                 matlab.ui.control.NumericEditField
        UBA1EditField                   matlab.ui.control.NumericEditField
        LBA1EditField                   matlab.ui.control.NumericEditField
        UpperLabel                      matlab.ui.control.Label
        LowerLabel                      matlab.ui.control.Label
        StartingPointsLabel             matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        DisplayfitineachpixelCheckBox   matlab.ui.control.CheckBox
        RedcirclepositionLabel          matlab.ui.control.Label
        chEditField                     matlab.ui.control.NumericEditField
        chEditFieldLabel                matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        YEditField_2                    matlab.ui.control.NumericEditField
        YEditField_2Label               matlab.ui.control.Label
        IntegralwithinthespectralROICheckBox  matlab.ui.control.CheckBox
        SaveButton                      matlab.ui.control.Button
        FittheareaButton                matlab.ui.control.Button
        DontfitbelowthisthresholdEditField  matlab.ui.control.NumericEditField
        DontfitbelowthisthresholdLabel  matlab.ui.control.Label
        ExitButton                      matlab.ui.control.Button
        LoadfromworkspaceButton         matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        pixel_histog
        im
        threshold
        sample
        h
        lifetime
        concentration
        residuals
        pixelxplore
        pointer
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'FLIM Map Generator';
            data = evalin('base','im');
            im = evalin('base','data');
            app.im = data;
            app.h = evalin('base','ROI');
            p = evalin('base','ROIpoints');
            N = evalin('base','N');
            typefun = evalin('base','type_fun');

            app.sample = imagesc(app.UIAxes,data); axis(app.UIAxes,'image');
            hold(app.UIAxes,'on');
            disableDefaultInteractivity(app.UIAxes)
            drawfreehand(app.UIAxes,'Position',app.h);
            app.pixel_histog = histogram(app.UIAxes2, data,numel(data));
            app.UIAxes2.YLim = [0 1.2*max(app.pixel_histog.Values)];
            app.threshold = line(app.UIAxes2,[app.DontfitbelowthisthresholdEditField.Value app.DontfitbelowthisthresholdEditField.Value],[0 app.UIAxes2.YLim(2)],'color','r');
            if app.IntegralwithinthespectralROICheckBox.Value == 1
                im = sum(im,2);
            end
            app.XEditField.Value = p(1,2);
            app.YEditField_2.Value = p(1,1);
            app.pointer = plot(app.UIAxes,p(1,1),p(1,2),'marker','o','MarkerSize',10,'Color','red');
            app.pixelxplore = plot(app.UIAxes3,evalin('base','t'),squeeze(im(:,app.chEditField.Value,1)),':.');
            ylim(app.UIAxes3,[min(im(:)) max(im(:))]);
            
            grid(app.UIAxes3,'minor')
            box(app.UIAxes3,'on')
            
            app.DontfitbelowthisthresholdEditField.Value = round(mean(mean(data)));
            DontfitbelowthisthresholdEditFieldValueChanged(app);
            
            % Load conditions from global fit
            start_points = evalin('base','start_points');
            opts = evalin('base','opts');
            res = coeffvalues(start_points);
            opts.StartPoint = res;
            
            % Adjust conditions to single pixel fit
            if strcmp(typefun,'Exp. Decay + IRF')
                opts.Upper(1) = 1.05.*res(1);
                opts.Lower(1) = 0.95.*res(1);
                opts.Upper(2) = 1.05.*res(2);
                opts.Lower(2) = 0.95.*res(2);
                app.t0EditField.Enable = 1;
                app.UBt0EditField.Enable = 1;
                app.LBt0EditField.Enable = 1;
                app.sigmaEditField.Enable = 1;
                app.LBsigmaEditField.Enable = 1;
                app.UBsigmaEditField.Enable = 1;
            end
            opts.StartPoint(end) = opts.StartPoint(end)/(size(app.im,1));
            opts.Upper(end) = 1.5.*opts.StartPoint(end);
            opts.Lower(end) = 0;
            
            if strcmp(typefun,'Exp. Decay + IRF')
                opts.StartPoint(3:2:(end-1)) = max(im(:))/N;
                opts.Upper(3:2:(end-1)) = 2*max(im(:));
            else
                opts.StartPoint(1:2:(end-1)) = max(im(:))/N;
                opts.Upper(1:2:(end-1)) = 2*max(im(:));
            end
            
            % Pairing initial conditions vs GUI        
            if strcmp(typefun,'Exponential Decay')
            switch N
                case 1
                    app.A1EditField.Value = opts.StartPoint(1);
                    app.UBA1EditField.Value = opts.Upper(1);
                    app.LBA1EditField.Value = opts.Lower(1);
                    app.tau1EditField.Value = 1/opts.StartPoint(2);
                    app.UBtau1EditField.Value = 1/opts.Lower(2);
                    app.LBtau1EditField.Value = 1/opts.Upper(2);
                    app.ConstantEditField.Value = opts.StartPoint(3);
                    app.UBConstEditField.Value = opts.Upper(end);
                    app.LBConstEditField.Value = opts.Lower(end);
                case 2
                    app.A1EditField.Value = opts.StartPoint(1);
                    app.UBA1EditField.Value = opts.Upper(1);
                    app.LBA1EditField.Value = opts.Lower(1);
                    app.tau1EditField.Value = 1/opts.StartPoint(2);
                    app.UBtau1EditField.Value = 1/opts.Lower(2);
                    app.LBtau1EditField.Value = 1/opts.Upper(2);
                    app.A2EditField.Value = opts.StartPoint(3);
                    app.UBA2EditField.Value = opts.Upper(3);
                    app.LBA2EditField.Value = opts.Lower(3);
                    app.tau2EditField.Value = 1/opts.StartPoint(4);
                    app.UBtau2EditField.Value = 1/opts.Lower(4);
                    app.LBtau2EditField.Value = 1/opts.Upper(4);
                    app.ConstantEditField.Value = opts.StartPoint(5);
                    app.UBConstEditField.Value = opts.Upper(end);
                    app.LBConstEditField.Value = opts.Lower(end);
                    app.A2EditField.Enable = 1;
                    app.UBA2EditField.Enable = 1;
                    app.LBA2EditField.Enable = 1;
                    app.tau2EditField.Enable = 1;
                    app.UBtau2EditField.Enable = 1;
                    app.LBtau2EditField.Enable = 1;
                case 3
                    app.A1EditField.Value = opts.StartPoint(1);
                    app.UBA1EditField.Value = opts.Upper(1);
                    app.LBA1EditField.Value = opts.Lower(1);
                    app.tau1EditField.Value = 1/opts.StartPoint(2);
                    app.UBtau1EditField.Value = 1/opts.Lower(2);
                    app.LBtau1EditField.Value = 1/opts.Upper(2);
                    app.A2EditField.Value = opts.StartPoint(3);
                    app.UBA2EditField.Value = opts.Upper(3);
                    app.LBA2EditField.Value = opts.Lower(3);
                    app.tau2EditField.Value = 1/opts.StartPoint(4);
                    app.UBtau2EditField.Value = 1/opts.Lower(4);
                    app.LBtau2EditField.Value = 1/opts.Upper(4);
                    app.A3EditField.Value = opts.StartPoint(5);
                    app.UBA3EditField.Value = opts.Upper(5);
                    app.LBA3EditField.Value = opts.Lower(5);
                    app.tau3EditField.Value = 1/opts.StartPoint(6);
                    app.UBtau3EditField.Value = 1/opts.Lower(6);
                    app.LBtau3EditField.Value = 1/opts.Upper(6);
                    app.ConstantEditField.Value = opts.StartPoint(7);
                    app.UBConstEditField.Value = opts.Upper(end);
                    app.LBConstEditField.Value = opts.Lower(end);
                    app.A2EditField.Enable = 1;
                    app.UBA2EditField.Enable = 1;
                    app.LBA2EditField.Enable = 1;
                    app.tau2EditField.Enable = 1;
                    app.UBtau2EditField.Enable = 1;
                    app.LBtau2EditField.Enable = 1;
                    app.A3EditField.Enable = 1;
                    app.UBA3EditField.Enable = 1;
                    app.LBA3EditField.Enable = 1;
                    app.tau3EditField.Enable = 1;
                    app.UBtau3EditField.Enable = 1;
                    app.LBtau3EditField.Enable = 1;
            end
            else
                switch N
                case 1
                    app.t0EditField.Value = opts.StartPoint(1);
                    app.UBt0EditField.Value = opts.Upper(1);
                    app.LBt0EditField.Value = opts.Lower(1);
                    app.sigmaEditField.Value = opts.StartPoint(2);
                    app.UBsigmaEditField.Value = opts.Upper(2);
                    app.LBsigmaEditField.Value = opts.Lower(2);
                    app.A1EditField.Value = opts.StartPoint(3);
                    app.UBA1EditField.Value = opts.Upper(3);
                    app.LBA1EditField.Value = opts.Lower(3);
                    app.tau1EditField.Value = 1/opts.StartPoint(4);
                    app.UBtau1EditField.Value = 1/opts.Lower(4);
                    app.LBtau1EditField.Value = 1/opts.Upper(4);
                    app.ConstantEditField.Value = opts.StartPoint(5);
                    app.UBConstEditField.Value = opts.Upper(end);
                    app.LBConstEditField.Value = opts.Lower(end);
                case 2
                    app.t0EditField.Value = opts.StartPoint(1);
                    app.UBt0EditField.Value = opts.Upper(1);
                    app.LBt0EditField.Value = opts.Lower(1);
                    app.sigmaEditField.Value = opts.StartPoint(2);
                    app.UBsigmaEditField.Value = opts.Upper(2);
                    app.LBsigmaEditField.Value = opts.Lower(2);
                    app.A1EditField.Value = opts.StartPoint(3);
                    app.UBA1EditField.Value = opts.Upper(3);
                    app.LBA1EditField.Value = opts.Lower(3);
                    app.tau1EditField.Value = 1/opts.StartPoint(4);
                    app.UBtau1EditField.Value = 1/opts.Lower(4);
                    app.LBtau1EditField.Value = 1/opts.Upper(4);
                    app.A2EditField.Value = opts.StartPoint(5);
                    app.UBA2EditField.Value = opts.Upper(5);
                    app.LBA2EditField.Value = opts.Lower(5);
                    app.tau2EditField.Value = 1/opts.StartPoint(6);
                    app.UBtau2EditField.Value = 1/opts.Lower(6);
                    app.LBtau2EditField.Value = 1/opts.Upper(6);
                    app.ConstantEditField.Value = opts.StartPoint(7);
                    app.UBConstEditField.Value = opts.Upper(7);
                    app.LBConstEditField.Value = opts.Lower(7);
                    app.A2EditField.Enable = 1;
                    app.UBA2EditField.Enable = 1;
                    app.LBA2EditField.Enable = 1;
                    app.tau2EditField.Enable = 1;
                    app.UBtau2EditField.Enable = 1;
                    app.LBtau2EditField.Enable = 1;
                 case 3
                    app.t0EditField.Value = opts.StartPoint(1);
                    app.UBt0EditField.Value = opts.Upper(1);
                    app.LBt0EditField.Value = opts.Lower(1);
                    app.sigmaEditField.Value = opts.StartPoint(2);
                    app.UBsigmaEditField.Value = opts.Upper(2);
                    app.LBsigmaEditField.Value = opts.Lower(2);
                    app.A1EditField.Value = opts.StartPoint(3);
                    app.UBA1EditField.Value = opts.Upper(3);
                    app.LBA1EditField.Value = opts.Lower(3);
                    app.tau1EditField.Value = 1/opts.StartPoint(4);
                    app.UBtau1EditField.Value = 1/opts.Lower(4);
                    app.LBtau1EditField.Value = 1/opts.Upper(4);
                    app.A2EditField.Value = opts.StartPoint(5);
                    app.UBA2EditField.Value = opts.Upper(5);
                    app.LBA2EditField.Value = opts.Lower(5);
                    app.tau2EditField.Value = 1/opts.StartPoint(6);
                    app.UBtau2EditField.Value = 1/opts.Lower(6);
                    app.LBtau2EditField.Value = 1/opts.Upper(6);
                    app.A3EditField.Value = opts.StartPoint(7);
                    app.UBA3EditField.Value = opts.Upper(7);
                    app.LBA3EditField.Value = opts.Lower(7);
                    app.tau3EditField.Value = 1/opts.StartPoint(8);
                    app.UBtau3EditField.Value = 1/opts.Lower(8);
                    app.LBtau3EditField.Value = 1/opts.Upper(8);
                    app.ConstantEditField.Value = opts.StartPoint(9);
                    app.UBConstEditField.Value = opts.Upper(9);
                    app.LBConstEditField.Value = opts.Lower(9);
                    app.A2EditField.Enable = 1;
                    app.UBA2EditField.Enable = 1;
                    app.LBA2EditField.Enable = 1;
                    app.tau2EditField.Enable = 1;
                    app.UBtau2EditField.Enable = 1;
                    app.LBtau2EditField.Enable = 1;
                    app.A3EditField.Enable = 1;
                    app.UBA3EditField.Enable = 1;
                    app.LBA3EditField.Enable = 1;
                    app.tau3EditField.Enable = 1;
                    app.UBtau3EditField.Enable = 1;
                    app.LBtau3EditField.Enable = 1;
                end
            end
            
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete;
        end

        % Value changed function: DontfitbelowthisthresholdEditField
        function DontfitbelowthisthresholdEditFieldValueChanged(app, event)
            value = app.DontfitbelowthisthresholdEditField.Value;
            app.threshold.XData = [value value];
            area = app.im;
            area(area<value) = 0;
            app.sample.CData = area;
            colormap(app.UIAxes, [0 0 0; parula(256)] )
            drawnow
        end

        % Button pushed function: FittheareaButton
        function FittheareaButtonPushed(app, event)
            app.FittheareaButton.Enable = 0;
            data = evalin('base','im');
            im = evalin('base','data');
            t = evalin('base','t');
            ft = evalin('base','func');
            typefun = evalin('base','type_fun');
            N = evalin('base','N');
            opts = evalin('base','opts');
            pos = evalin('base','ROIpoints');

            if strcmp(app.FitmethodSwitch.Value,'NLLS')
            if strcmp(typefun,'Exponential Decay')
                switch N
                    case 1
                        opts.StartPoint = [app.A1EditField.Value 1/app.tau1EditField.Value app.ConstantEditField.Value];
                        opts.Lower = [app.LBA1EditField.Value 1/app.UBtau1EditField.Value app.LBConstEditField.Value];
                        opts.Upper = [app.UBA1EditField.Value 1/app.LBtau1EditField.Value app.UBConstEditField.Value];
                    case 2
                        opts.StartPoint = [app.A1EditField.Value 1/app.tau1EditField.Value app.A2EditField.Value ...
                            1/app.tau2EditField.Value app.ConstantEditField.Value];
                        opts.Lower = [app.LBA1EditField.Value 1/app.UBtau1EditField.Value app.LBA2EditField.Value 1/app.UBtau2EditField.Value app.LBConstEditField.Value];
                        opts.Upper = [app.UBA1EditField.Value 1/app.LBtau1EditField.Value app.UBA2EditField.Value 1/app.LBtau2EditField.Value app.UBConstEditField.Value];
                    case 3
                        opts.StartPoint = [app.A1EditField.Value 1/app.tau1EditField.Value app.A2EditField.Value ...
                            1/app.tau2EditField.Value app.A3EditField.Value 1/app.tau3EditField.Value app.ConstantEditField.Value];
                        opts.Lower = [app.LBA1EditField.Value 1/app.UBtau1EditField.Value app.LBA2EditField.Value 1/app.UBtau2EditField.Value app.LBA3EditField.Value 1/app.UBtau3EditField.Value app.LBConstEditField.Value];
                        opts.Upper = [app.UBA1EditField.Value 1/app.LBtau1EditField.Value app.UBA2EditField.Value 1/app.LBtau2EditField.Value app.UBA3EditField.Value 1/app.LBtau3EditField.Value app.UBConstEditField.Value];
                        
                end
            elseif strcmp(typefun,'Exp. Decay + IRF')
                switch N
                    case 1
                        opts.StartPoint = [app.t0EditField.Value app.sigmaEditField.Value app.A1EditField.Value 1/app.tau1EditField.Value app.ConstantEditField.Value];
                        opts.Lower = [app.LBt0EditField.Value app.LBsigmaEditField.Value app.LBA1EditField.Value 1/app.UBtau1EditField.Value app.LBConstEditField.Value];
                        opts.Upper = [app.UBt0EditField.Value app.UBsigmaEditField.Value app.UBA1EditField.Value 1/app.LBtau1EditField.Value app.UBConstEditField.Value];
                    case 2
                        opts.StartPoint = [app.t0EditField.Value app.sigmaEditField.Value app.A1EditField.Value 1/app.tau1EditField.Value app.A2EditField.Value ...
                            1/app.tau2EditField.Value app.ConstantEditField.Value];
                        opts.Lower = [app.LBt0EditField.Value app.LBsigmaEditField.Value app.LBA1EditField.Value 1/app.UBtau1EditField.Value app.LBA2EditField.Value 1/app.UBtau2EditField.Value app.LBConstEditField.Value];
                        opts.Upper = [app.UBt0EditField.Value app.UBsigmaEditField.Value app.UBA1EditField.Value 1/app.LBtau1EditField.Value app.UBA2EditField.Value 1/app.LBtau2EditField.Value app.UBConstEditField.Value];
                    case 3
                        opts.StartPoint = [app.t0EditField.Value app.sigmaEditField.Value app.A1EditField.Value 1/app.tau1EditField.Value app.A2EditField.Value ...
                            1/app.tau2EditField.Value app.A3EditField.Value 1/app.tau3EditField.Value app.ConstantEditField.Value];
                        opts.Lower = [app.LBt0EditField.Value app.LBsigmaEditField.Value app.LBA1EditField.Value 1/app.UBtau1EditField.Value app.LBA2EditField.Value 1/app.UBtau2EditField.Value app.LBA3EditField.Value 1/app.UBtau3EditField.Value app.LBConstEditField.Value];
                        opts.Upper = [app.UBt0EditField.Value app.UBsigmaEditField.Value app.UBA1EditField.Value 1/app.LBtau1EditField.Value app.UBA2EditField.Value 1/app.LBtau2EditField.Value app.UBA3EditField.Value 1/app.LBtau3EditField.Value app.UBConstEditField.Value];
                end
            end

            if app.IntegralwithinthespectralROICheckBox.Value == 1
                im = sum(im,2);
            end
            
            app.lifetime = zeros(size(im,2),size(data,1),size(data,2),N);
            app.residuals = zeros(size(im,2),size(data,1),size(data,2));
            app.concentration = zeros(size(im,2),size(data,1),size(data,2),N);
            
            
            for li = 1:size(app.lifetime,1)
                %xi = 1;
                for x = 1:size(im,3)
                    %yi = 1;
                    %for y = pos(:,2)
                        if sum(im(:,:,x),1:2) > app.DontfitbelowthisthresholdEditField.Value
                            %for xi = 1:length(app.pos(:,1))
                            %    c(:,:,xi) = app.data(:,app.l_start:app.l_stop,app.pos(xi,1),app.pos(xi,2));
                            %end
                            %curve = squeeze(sum(c,[2 3]));
                            [fitresult,gof] = fit(t, im(:,li,x), ft, opts);
                            %figure,plot(fitresult,t, im(:,li,xi,yi))
                            res = coeffvalues(fitresult);
                            app.chEditField.Value = li;
                            if strcmp(typefun,'Exp. Decay + IRF')
                                res(1:2) = [];
                            end
                            
                            app.lifetime(li,pos(x,1),pos(x,2),:) = 1./res(2:2:end);
                            app.concentration(li,pos(x,1),pos(x,2),:) = res(1:2:(end-1));%./sum(res(1:2:(end-1)));
                            %A = squeeze(im(:,li,x));
                            %B = A-fitresult(t);
                            app.residuals(li,pos(x,1),pos(x,2)) = gof.rsquare;%sum((B(A>1)).^2./A(A>1))./length(t(A>1));
                            app.YEditField_2.Value = pos(x,1);
                            app.XEditField.Value = pos(x,2);
                            app.pointer.XData = app.XEditField.Value;
                            app.pointer.YData = app.YEditField_2.Value;
                            
                            if app.DisplayfitineachpixelCheckBox.Value == 1
                                app.pixelxplore.YData = squeeze(im(:,li,x));
                                if exist('FitPlot') == 0
                                    hold(app.UIAxes3,'on')
                                    FitPlot = plot(app.UIAxes3,t,fitresult(t));
                                    ResPlot = plot(app.UIAxes4,t,(fitresult(t)-squeeze(im(:,li,x)))./real(sqrt(squeeze(im(:,li,x)))));
                                else
                                    FitPlot.YData = fitresult(t);
                                    ResPlot.YData = (fitresult(t)-squeeze(im(:,li,x)))./real(sqrt(squeeze(im(:,li,x))));
                                end
                                pause(1);
                            end
                            drawnow
                            if app.InterruptButton.Value
                                app.InterruptButton.Value = 0;
                                app.FittheareaButton.Enable = 1;
                                return;
                            end
                            
                        end
                        %yi = yi + 1;
                    %end
                    %xi = xi + 1;
                end
            end
            
            else
                app.lifetime = zeros(1,size(data,1),size(data,2),N);
                app.residuals = zeros(1,size(data,1),size(data,2));
                app.concentration = zeros(1,size(data,1),size(data,2),N);
                for x = 1:size(im,3)
                    if sum(im(:,:,x),1:2) > app.DontfitbelowthisthresholdEditField.Value
                        STmap = squeeze(im(:,:,x))';
                        [~,B,~] = svd(STmap);
                        [~,D,~] = svd(real(sqrt(STmap)));
                        D = diag(D);
                        N = 1;
                        N = sum(diag(B(1:(size(B,1)-1),1:(size(B,1)-1))+diag(B(2:size(B,1),2:size(B,1))))>=D(1));
                        %N = sum(diag(B)>1);% use this for noise free data (simulated)
                        if N>evalin('base','N')
                            N = evalin('base','N');
                        end
                        if N > 0
                        Nmax = evalin('base','N');
                        t0max = 4;
                        x0 = rand(N,1)+(((1:N)-1)*t0max/N)';
                        [tau,DAS,~,spcFit] = GlobalFit(t',1:size(im,2),STmap,x0',0,0);
                        if sum(tau>=100)==0
                        taus = zeros(1,Nmax);
                        DASs = zeros(1,Nmax);
                        %tau = sum(sum(DAS,2).*tau'./sum(DAS,1:2));
                        taus(1:length(tau)) = tau;
                        DASs(1:length(tau)) = sum(DAS,2);
                        app.lifetime(1,pos(x,1),pos(x,2),:) = taus'.*(sum(STmap(:))>abs(DASs'));
                        app.concentration(1,pos(x,1),pos(x,2),:) = DASs'.*(sum(STmap(:))>abs(DASs'));
                        app.residuals = sum(im(:,:,x),1:2)-sum(spcFit(:));
                        end
                        app.YEditField_2.Value = pos(x,1);
                        app.XEditField.Value = pos(x,2);
                        app.pointer.XData = app.XEditField.Value;
                        app.pointer.YData = app.YEditField_2.Value;
                        if app.InterruptButton.Value
                                app.InterruptButton.Value = 0;
                                app.FittheareaButton.Enable = 1;
                                return;
                        end
                        drawnow;
                        end
                    end
                end
                N = 1;
            end
            % Data regulatization
            [app.lifetime,app.concentration] = CheckDataConsistencyFLIM(app.lifetime,app.concentration,app.AmplitudethresholdEditField.Value,app.TausimilaritythresoldEditField.Value);
            
            if app.FLIMmapastauaveCheckBox.Value && size(app.lifetime,4)>1
                [app.lifetime,app.concentration,N] = Convert2TauAverage(app.lifetime,app.concentration);
            end
            assignin('base','lifetime',app.lifetime)
            assignin('base','concentration',app.concentration)
            app.FittheareaButton.Enable = 1;     
            FLIMSetColorScaleAndPlot4D(app.lifetime,app.concentration);
            %DrawFLIMMap(app.lifetime,app.concentration,N,app.residuals);
        end

        % Button pushed function: LoadfromworkspaceButton
        function LoadfromworkspaceButtonPushed(app, event)
            startupFcn(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            path = evalin('base','path');
            lifetime = app.lifetime;
            residuals = app.residuals;
            concentrations = app.concentration;
            path = path(1:end-4);
            uisave(["lifetime","residuals","concentrations"],[path,'_FLIM.mat'])
        end

        % Value changed function: YEditField_2
        function YEditField_2ValueChanged(app, event)
            data = evalin('base','data');
            pos = evalin('base','ROIpoints');
            if app.IntegralwithinthespectralROICheckBox.Value == 1
                data = sum(data,2);
            end
            %app.YEditField_2.Value(app.YEditField_2.Value<min(pos(:,1))) = min(pos(:,1));
            %app.YEditField_2.Value(app.YEditField_2.Value > max(pos(:,2))) = max(pos(:,2));
            [tf, ~]=ismember(pos,[app.YEditField_2.Value app.XEditField.Value],'rows');
            if sum(tf)==0
                a = find(pos(:,2)==app.YEditField_2.Value);
                app.XEditField.Value = pos(a(1),1);
                [tf, ~]=ismember(pos,[app.YEditField_2.Value app.XEditField.Value],'rows');
            end
            app.pixelxplore.YData = squeeze(data(:,app.chEditField.Value,tf));
            app.pointer.XData = app.XEditField.Value;
            app.pointer.YData = app.YEditField_2.Value;
            drawnow
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            data = evalin('base','data');
            pos = evalin('base','ROIpoints');
            if app.IntegralwithinthespectralROICheckBox.Value == 1
                data = sum(data,2);
            end
            %app.YEditField_2.Value(app.YEditField_2.Value<min(pos(:,1))) = min(pos(:,1));
            %app.YEditField_2.Value(app.YEditField_2.Value > max(pos(:,2))) = max(pos(:,2));
            [tf, ~]=ismember(pos,[app.YEditField_2.Value app.XEditField.Value],'rows');
            if sum(tf)==0
                a = find(pos(:,1)==app.XEditField.Value);
                app.YEditField_2.Value = pos(a(1),2);
                [tf, ~]=ismember(pos,[app.YEditField_2.Value app.XEditField.Value],'rows');
            end
            app.pixelxplore.YData = squeeze(data(:,app.chEditField.Value,tf));
            app.pointer.XData = app.XEditField.Value;
            app.pointer.YData = app.YEditField_2.Value;
            drawnow
        end

        % Value changed function: chEditField
        function chEditFieldValueChanged(app, event)
            data = evalin('base','data');
            if app.IntegralwithinthespectralROICheckBox.Value == 1
                data = sum(data,2);
            end
            app.chEditField.Value(app.chEditField.Value<1) = 1;
            app.chEditField.Value(app.chEditField.Value > size(data,2)) = size(data,2);
            %app.pixelxplore.YData = squeeze(data(:,app.chEditField.Value,app.XEditField.Value-app.h(2)+1,app.YEditField_2.Value-app.h(1)+1));
            drawnow
        end

        % Value changed function: IntegralwithinthespectralROICheckBox
        function IntegralwithinthespectralROICheckBoxValueChanged(app, event)
            data = evalin('base','data');
            if app.IntegralwithinthespectralROICheckBox.Value == 1
                data = sum(data,2);
                app.chEditField.Value = 1;
            end
            %app.pixelxplore.YData = squeeze(data(:,app.chEditField.Value,app.XEditField.Value-app.h(2)+1,app.YEditField_2.Value-app.h(1)+1));
            drawnow
        end

        % Value changed function: TRsignalinlogscaleCheckBox
        function TRsignalinlogscaleCheckBoxValueChanged(app, event)
            value = app.TRsignalinlogscaleCheckBox.Value;
            if value
                app.UIAxes3.YScale = 'log';
            else
                app.UIAxes3.YScale = 'linear';
            end
        end

        % Value changed function: FitmethodSwitch
        function FitmethodSwitchValueChanged(app, event)
            value = app.FitmethodSwitch.Value;
            if strcmp(value,'Global Analysis')
               app.IntegralwithinthespectralROICheckBox.Value = 0;
               app.IntegralwithinthespectralROICheckBox.Enable = 0;
               app.DisplayfitineachpixelCheckBox.Enable = 0;
               app.TRsignalinlogscaleCheckBox.Enable = 0;
            else
               app.IntegralwithinthespectralROICheckBox.Value = 1;
               app.IntegralwithinthespectralROICheckBox.Enable = 1;
               app.DisplayfitineachpixelCheckBox.Enable = 1;
               app.TRsignalinlogscaleCheckBox.Enable = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'parula')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Intensity of each pixel within the image')
            xlabel(app.UIAxes2, 'Intensity')
            ylabel(app.UIAxes2, 'Number of pixel of a given intensity')
            zlabel(app.UIAxes2, 'Z')
            colormap(app.UIAxes2, 'parula')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Pixel explorer')
            xlabel(app.UIAxes3, 'Time [ns]')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            colormap(app.UIAxes3, 'parula')
            app.UIAxes4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes4, 'Time [ns]')
            zlabel(app.UIAxes4, 'Z')
            colormap(app.UIAxes4, 'parula')
            app.LoadfromworkspaceButton = uibutton(app.UIFigure, 'push');
            app.LoadfromworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromworkspaceButtonPushed, true);
            app.LoadfromworkspaceButton.Text = 'Load from workspace';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.DontfitbelowthisthresholdLabel = uilabel(app.UIFigure);
            app.DontfitbelowthisthresholdLabel.Text = 'Don''t''fit below this threshold:';
            app.DontfitbelowthisthresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.DontfitbelowthisthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @DontfitbelowthisthresholdEditFieldValueChanged, true);
            app.FittheareaButton = uibutton(app.UIFigure, 'push');
            app.FittheareaButton.ButtonPushedFcn = createCallbackFcn(app, @FittheareaButtonPushed, true);
            app.FittheareaButton.Text = 'Fit the area';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.IntegralwithinthespectralROICheckBox = uicheckbox(app.UIFigure);
            app.IntegralwithinthespectralROICheckBox.ValueChangedFcn = createCallbackFcn(app, @IntegralwithinthespectralROICheckBoxValueChanged, true);
            app.IntegralwithinthespectralROICheckBox.Text = 'Integral within the spectral ROI';
            app.IntegralwithinthespectralROICheckBox.Value = true;
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_2.ValueChangedFcn = createCallbackFcn(app, @YEditField_2ValueChanged, true);
            app.YEditField_2.Value = 1;
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.XEditField.Value = 1;
            app.chEditFieldLabel = uilabel(app.UIFigure);
            app.chEditFieldLabel.Text = 'ch';
            app.chEditField = uieditfield(app.UIFigure, 'numeric');
            app.chEditField.ValueChangedFcn = createCallbackFcn(app, @chEditFieldValueChanged, true);
            app.chEditField.Value = 1;
            app.RedcirclepositionLabel = uilabel(app.UIFigure);
            app.RedcirclepositionLabel.Text = 'Red circle position:';
            app.DisplayfitineachpixelCheckBox = uicheckbox(app.UIFigure);
            app.DisplayfitineachpixelCheckBox.Text = 'Display fit in each pixel';
            app.Panel = uipanel(app.UIFigure);
            app.StartingPointsLabel = uilabel(app.UIFigure);
            app.StartingPointsLabel.Text = 'Starting Points';
            app.LowerLabel = uilabel(app.UIFigure);
            app.LowerLabel.Text = 'Lower ';
            app.UpperLabel = uilabel(app.UIFigure);
            app.UpperLabel.Text = 'Upper';
            app.LBA1EditField = uieditfield(app.UIFigure, 'numeric');
            app.UBA1EditField = uieditfield(app.UIFigure, 'numeric');
            app.LBtau1EditField = uieditfield(app.UIFigure, 'numeric');
            app.UBtau1EditField = uieditfield(app.UIFigure, 'numeric');
            app.LBt0EditField = uieditfield(app.UIFigure, 'numeric');
            app.LBt0EditField.Enable = 'off';
            app.UBt0EditField = uieditfield(app.UIFigure, 'numeric');
            app.UBt0EditField.Enable = 'off';
            app.LBsigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.LBsigmaEditField.Enable = 'off';
            app.UBsigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.UBsigmaEditField.Enable = 'off';
            app.LBA2EditField = uieditfield(app.UIFigure, 'numeric');
            app.LBA2EditField.Enable = 'off';
            app.LBA3EditField = uieditfield(app.UIFigure, 'numeric');
            app.LBA3EditField.Enable = 'off';
            app.UBA2EditField = uieditfield(app.UIFigure, 'numeric');
            app.UBA2EditField.Enable = 'off';
            app.UBA3EditField = uieditfield(app.UIFigure, 'numeric');
            app.UBA3EditField.Enable = 'off';
            app.LBtau2EditField = uieditfield(app.UIFigure, 'numeric');
            app.LBtau2EditField.Enable = 'off';
            app.LBtau3EditField = uieditfield(app.UIFigure, 'numeric');
            app.LBtau3EditField.Enable = 'off';
            app.UBtau2EditField = uieditfield(app.UIFigure, 'numeric');
            app.UBtau2EditField.Enable = 'off';
            app.UBtau3EditField = uieditfield(app.UIFigure, 'numeric');
            app.UBtau3EditField.Enable = 'off';
            app.LBConstEditField = uieditfield(app.UIFigure, 'numeric');
            app.UBConstEditField = uieditfield(app.UIFigure, 'numeric');
            app.A1EditFieldLabel = uilabel(app.UIFigure);
            app.A1EditFieldLabel.Text = 'A1';
            app.A1EditField = uieditfield(app.UIFigure, 'numeric');
            app.A2EditFieldLabel = uilabel(app.UIFigure);
            app.A2EditFieldLabel.Text = 'A2';
            app.A2EditField = uieditfield(app.UIFigure, 'numeric');
            app.A2EditField.Enable = 'off';
            app.A3EditFieldLabel = uilabel(app.UIFigure);
            app.A3EditFieldLabel.Text = 'A3';
            app.A3EditField = uieditfield(app.UIFigure, 'numeric');
            app.A3EditField.Enable = 'off';
            app.tau1EditFieldLabel = uilabel(app.UIFigure);
            app.tau1EditFieldLabel.Text = 'tau1';
            app.tau1EditField = uieditfield(app.UIFigure, 'numeric');
            app.tau2EditFieldLabel = uilabel(app.UIFigure);
            app.tau2EditFieldLabel.Text = 'tau2';
            app.tau2EditField = uieditfield(app.UIFigure, 'numeric');
            app.tau2EditField.Enable = 'off';
            app.tau3EditFieldLabel = uilabel(app.UIFigure);
            app.tau3EditFieldLabel.Text = 'tau3';
            app.tau3EditField = uieditfield(app.UIFigure, 'numeric');
            app.tau3EditField.Enable = 'off';
            app.t0EditFieldLabel = uilabel(app.UIFigure);
            app.t0EditFieldLabel.Text = 't0';
            app.t0EditField = uieditfield(app.UIFigure, 'numeric');
            app.t0EditField.Enable = 'off';
            app.sigmaEditFieldLabel = uilabel(app.UIFigure);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.sigmaEditField.Enable = 'off';
            app.ConstantEditFieldLabel = uilabel(app.UIFigure);
            app.ConstantEditFieldLabel.Text = 'Constant';
            app.ConstantEditField = uieditfield(app.UIFigure, 'numeric');
            app.TausimilaritythresoldEditFieldLabel = uilabel(app.UIFigure);
            app.TausimilaritythresoldEditFieldLabel.Text = 'Tau similarity thresold [%]';
            app.TausimilaritythresoldEditField = uieditfield(app.UIFigure, 'numeric');
            app.TausimilaritythresoldEditField.Limits = [0 100];
            app.TausimilaritythresoldEditField.Value = 4;
            app.AmplitudethresholdLabel = uilabel(app.UIFigure);
            app.AmplitudethresholdLabel.Text = 'Amplitude threshold';
            app.AmplitudethresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudethresholdEditField.Limits = [0 10000];
            app.AmplitudethresholdEditField.Value = 0.1;
            app.FLIMmapastauaveCheckBox = uicheckbox(app.UIFigure);
            app.FLIMmapastauaveCheckBox.Text = 'FLIM map as tau ave.';
            app.InterruptButton = uibutton(app.UIFigure, 'state');
            app.InterruptButton.Text = 'Interrupt';
            app.TRsignalinlogscaleCheckBox = uicheckbox(app.UIFigure);
            app.TRsignalinlogscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @TRsignalinlogscaleCheckBoxValueChanged, true);
            app.TRsignalinlogscaleCheckBox.Text = 'TR signal in log scale';
            app.FitmethodSwitchLabel = uilabel(app.UIFigure);
            app.FitmethodSwitchLabel.Text = 'Fit method';
            app.FitmethodSwitch = uiswitch(app.UIFigure, 'slider');
            app.FitmethodSwitch.Items = {'NLLS', 'Global Analysis'};
            app.FitmethodSwitch.ValueChangedFcn = createCallbackFcn(app, @FitmethodSwitchValueChanged, true);
            app.FitmethodSwitch.Value = 'NLLS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_FitArea

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end