classdef APP_FitCfTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DefaultButtonLambda            matlab.ui.control.Button
        SpectralbinnmEditField         matlab.ui.control.NumericEditField
        SpectralbinnmEditFieldLabel    matlab.ui.control.Label
        RescaleSpectrum                matlab.ui.control.StateButton
        DeleteLoadedSpectrum           matlab.ui.control.Button
        UploadSpectrum                 matlab.ui.control.Button
        DownloadSpectrum               matlab.ui.control.Button
        ColormapLimitsLower            matlab.ui.control.NumericEditField
        ColormapLimitsEditFieldLabel   matlab.ui.control.Label
        ColormapLimitsUpper            matlab.ui.control.NumericEditField
        SendfittoworkspaceButton       matlab.ui.control.Button
        ExcludenegativepointsCheckBox  matlab.ui.control.CheckBox
        BintypeSwitch                  matlab.ui.control.Switch
        DefaultButton                  matlab.ui.control.Button
        TemporalbinnsEditField         matlab.ui.control.NumericEditField
        TemporalbinnsEditFieldLabel    matlab.ui.control.Label
        SenddatatoCftoolButton         matlab.ui.control.Button
        FilePathEditField              matlab.ui.control.EditField
        ResetExceptionsButton          matlab.ui.control.Button
        ExcludepointsButton            matlab.ui.control.Button
        DiffMinChangeEditField         matlab.ui.control.NumericEditField
        DiffMinChangeLabel             matlab.ui.control.Label
        RobustDropDown                 matlab.ui.control.DropDown
        RobustDropDownLabel            matlab.ui.control.Label
        TolFunEditField                matlab.ui.control.NumericEditField
        TolFunEditFieldLabel           matlab.ui.control.Label
        MaxIterEditField               matlab.ui.control.NumericEditField
        MaxIterEditFieldLabel          matlab.ui.control.Label
        MaxFunEvalsEditField           matlab.ui.control.NumericEditField
        MaxFunEvalsEditFieldLabel      matlab.ui.control.Label
        DiffMaxChangeEditField         matlab.ui.control.NumericEditField
        DiffMaxChangeLabel             matlab.ui.control.Label
        AlgorithmDropDown              matlab.ui.control.DropDown
        AlgorithmDropDownLabel         matlab.ui.control.Label
        FitresultTextArea              matlab.ui.control.TextArea
        UBConstEditField               matlab.ui.control.NumericEditField
        LBConstEditField               matlab.ui.control.NumericEditField
        UBtau3EditField                matlab.ui.control.NumericEditField
        UBtau2EditField                matlab.ui.control.NumericEditField
        LBtau3EditField                matlab.ui.control.NumericEditField
        LBtau2EditField                matlab.ui.control.NumericEditField
        UBA3EditField                  matlab.ui.control.NumericEditField
        UBA2EditField                  matlab.ui.control.NumericEditField
        LBA3EditField                  matlab.ui.control.NumericEditField
        LBA2EditField                  matlab.ui.control.NumericEditField
        FitsigmaEditField              matlab.ui.control.NumericEditField
        Fitt0EditField                 matlab.ui.control.NumericEditField
        FitConstEditField              matlab.ui.control.NumericEditField
        Fittau3EditField               matlab.ui.control.NumericEditField
        Fittau2EditField               matlab.ui.control.NumericEditField
        Fittau1EditField               matlab.ui.control.NumericEditField
        FitA3EditField                 matlab.ui.control.NumericEditField
        FitA2EditField                 matlab.ui.control.NumericEditField
        FitA1EditField                 matlab.ui.control.NumericEditField
        FitLabel                       matlab.ui.control.Label
        ConstantEditField              matlab.ui.control.NumericEditField
        ConstantEditFieldLabel         matlab.ui.control.Label
        TolXEditField                  matlab.ui.control.NumericEditField
        TolXEditFieldLabel             matlab.ui.control.Label
        sigmaEditField                 matlab.ui.control.NumericEditField
        sigmaEditFieldLabel            matlab.ui.control.Label
        t0EditField                    matlab.ui.control.NumericEditField
        t0EditFieldLabel               matlab.ui.control.Label
        tau3EditField                  matlab.ui.control.NumericEditField
        tau3EditFieldLabel             matlab.ui.control.Label
        tau2EditField                  matlab.ui.control.NumericEditField
        tau2EditFieldLabel             matlab.ui.control.Label
        tau1EditField                  matlab.ui.control.NumericEditField
        tau1EditFieldLabel             matlab.ui.control.Label
        A3EditField                    matlab.ui.control.NumericEditField
        A3EditFieldLabel               matlab.ui.control.Label
        A2EditField                    matlab.ui.control.NumericEditField
        A2EditFieldLabel               matlab.ui.control.Label
        A1EditField                    matlab.ui.control.NumericEditField
        A1EditFieldLabel               matlab.ui.control.Label
        UBsigmaEditField               matlab.ui.control.NumericEditField
        LBsigmaEditField               matlab.ui.control.NumericEditField
        UBt0EditField                  matlab.ui.control.NumericEditField
        LBt0EditField                  matlab.ui.control.NumericEditField
        UBtau1EditField                matlab.ui.control.NumericEditField
        LBtau1EditField                matlab.ui.control.NumericEditField
        UBA1EditField                  matlab.ui.control.NumericEditField
        LBA1EditField                  matlab.ui.control.NumericEditField
        UpperLabel                     matlab.ui.control.Label
        LowerLabel                     matlab.ui.control.Label
        StartingPointsLabel            matlab.ui.control.Label
        FitthecurveButton              matlab.ui.control.Button
        LogCheckBox                    matlab.ui.control.CheckBox
        WLROIendEditField              matlab.ui.control.NumericEditField
        WLROInmEditField               matlab.ui.control.NumericEditField
        WLROInmEditFieldLabel          matlab.ui.control.Label
        TimeROI2EditField              matlab.ui.control.NumericEditField
        TimeROInsEditField             matlab.ui.control.NumericEditField
        TimeROInsEditFieldLabel        matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        ReleasefileButton              matlab.ui.control.Button
        DecaysSpinner                  matlab.ui.control.Spinner
        DecaysSpinnerLabel             matlab.ui.control.Label
        DrawaROIButton                 matlab.ui.control.Button
        FitModelDropDown               matlab.ui.control.DropDown
        FitModelDropDownLabel          matlab.ui.control.Label
        LoaddataButton                 matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        InitialguessButton             matlab.ui.control.Button
        FittheareaButton               matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        NonlinearLeastSquareSettingsPanel  matlab.ui.container.Panel
        WeightsCheckBox                matlab.ui.control.CheckBox
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        lambda
        t
        pos
        l_start
        l_stop
        data
        data_nobin
        t_nobin
        lambda_nobin
        fitresult
        gof
        t_start
        t_stop
        excl
        Image
        spectrum
        decay
        WLROIStart
        WLROIStop
        TROIStart
        TROIStop
        ImageROI
        fit_plot_handle
        residuals
        hexclude
        ref_func
        fit_result_structure
        fitnumber
        overlaySpectrum
        overlayDecay
        overlayROI
    end
    
    methods (Access = private)
        
        function refresh_plots(app)
            
            [~,app.t_start] = min(abs(app.t - app.TimeROInsEditField.Value));
            [~,app.t_stop] = min(abs(app.t - app.TimeROI2EditField.Value));
            [~,app.l_start] = min(abs(app.lambda - app.WLROInmEditField.Value));
            [~,app.l_stop] = min(abs(app.lambda - app.WLROIendEditField.Value));
            
            if length(size(app.data)) == 4
                app.Image.CData = squeeze(sum(app.data(app.t_start:app.t_stop,app.l_start:app.l_stop,:,:),1:2));
            else
                app.Image.CData = app.data(app.t_start:app.t_stop,app.l_start:app.l_stop);
                app.Image.XData = app.lambda(app.l_start:app.l_stop);
                app.Image.YData = app.t(app.t_start:app.t_stop);
            end
            
            for xi = 1:length(app.pos(:,1))
                s(:,:,xi) = app.data(app.t_start:app.t_stop,:,app.pos(xi,1),app.pos(xi,2));
                d(:,:,xi) = app.data(:,app.l_start:app.l_stop,app.pos(xi,1),app.pos(xi,2));
            end
            
            
            app.spectrum.YData = squeeze(sum(s,[1 3]));
            app.spectrum.XData = app.lambda;
            app.UIAxes2.YLim = [0 1.2*max(app.spectrum.YData)];
            app.WLROIStart.YData = [0 1.2*max(app.spectrum.YData)];
            app.WLROIStart.XData = [app.WLROInmEditField.Value app.WLROInmEditField.Value];
            app.WLROIStop.YData =  [0 1.2*max(app.spectrum.YData)];
            app.WLROIStop.XData = [app.WLROIendEditField.Value app.WLROIendEditField.Value];
            
            app.decay.YData = squeeze(sum(d,[2 3]));
            app.decay.XData = app.t;
            if size(app.fit_plot_handle,1)>0
                %start = 1;
                start = 0.8*min(app.decay.YData);
            else
                start = 0;
            end
            try
                app.UIAxes3.YLim = [start 1.2*max(app.decay.YData)];
            catch ME
                app.UIAxes3.YLim = flip([start 1.2*max(app.decay.YData)]);
            end
            app.TROIStart.YData = [start 1.2*max(app.decay.YData)];
            app.TROIStart.XData = [app.TimeROInsEditField.Value app.TimeROInsEditField.Value];
            app.TROIStop.YData =  [start 1.2*max(app.decay.YData)];
            app.TROIStop.XData = [app.TimeROI2EditField.Value app.TimeROI2EditField.Value];
            drawnow;
        end
        
        function [fitresult,gof,t_fit] = fit2data(app, curve)
            [~, pos_max] = max(curve);
            curve = curve(app.t_start:app.t_stop,:,:,:);
            
            if strcmp(app.FitModelDropDown.Value,'Exponential Decay')
                
                t_fit = app.t - app.t(pos_max);
                switch app.DecaysSpinner.Value
                    case 1
                        app.ref_func = 'a*exp(-b*x)+c';
                        start = [app.A1EditField.Value 1/app.tau1EditField.Value app.ConstantEditField.Value];
                        lb = [app.LBA1EditField.Value 1/app.UBtau1EditField.Value app.LBConstEditField.Value];
                        ub = [app.UBA1EditField.Value 1/app.LBtau1EditField.Value app.UBConstEditField.Value];
                    case 2
                        app.ref_func = 'a*exp(-b*x)+c*exp(-d*x) + e';
                        start = [app.A1EditField.Value 1/app.tau1EditField.Value app.A2EditField.Value ...
                            1/app.tau2EditField.Value app.ConstantEditField.Value];
                        lb = [app.LBA1EditField.Value 1/app.UBtau1EditField.Value app.LBA2EditField.Value 1/app.UBtau2EditField.Value app.LBConstEditField.Value];
                        ub = [app.UBA1EditField.Value 1/app.LBtau1EditField.Value app.UBA2EditField.Value 1/app.LBtau2EditField.Value app.UBConstEditField.Value];
                    case 3
                        app.ref_func = 'a*exp(-b*x)+c*exp(-d*x) + e*exp(-f*x) + g';
                        start = [app.A1EditField.Value 1/app.tau1EditField.Value app.A2EditField.Value ...
                            1/app.tau2EditField.Value app.A3EditField.Value 1/app.tau3EditField.Value app.ConstantEditField.Value];
                        lb = [app.LBA1EditField.Value 1/app.UBtau1EditField.Value app.LBA2EditField.Value 1/app.UBtau2EditField.Value app.LBA3EditField.Value 1/app.UBtau3EditField.Value app.LBConstEditField.Value];
                        ub = [app.UBA1EditField.Value 1/app.LBtau1EditField.Value app.UBA2EditField.Value 1/app.LBtau2EditField.Value app.UBA3EditField.Value 1/app.LBtau3EditField.Value app.UBConstEditField.Value];
                        
                end
            elseif strcmp(app.FitModelDropDown.Value,'Exp. Decay + IRF')
                t_fit = app.t;
                switch app.DecaysSpinner.Value
                    case 1
                        app.ref_func = '(x(2)-x(1))*conv(normpdf(x,a+x(length(x))/2,b),c*exp(-d*x)+e,''same'')';
                        start = [app.t0EditField.Value app.sigmaEditField.Value app.A1EditField.Value 1/app.tau1EditField.Value app.ConstantEditField.Value];
                        lb = [app.LBt0EditField.Value app.LBsigmaEditField.Value app.LBA1EditField.Value 1/app.UBtau1EditField.Value app.LBConstEditField.Value];
                        ub = [app.UBt0EditField.Value app.UBsigmaEditField.Value app.UBA1EditField.Value 1/app.LBtau1EditField.Value app.UBConstEditField.Value];
                    case 2
                        app.ref_func = '(x(2)-x(1))*conv(normpdf(x,a+x(length(x))/2,b),c*exp(-d*x)+e*exp(-f*x)+g,''same'')';
                        start = [app.t0EditField.Value app.sigmaEditField.Value app.A1EditField.Value 1/app.tau1EditField.Value app.A2EditField.Value ...
                            1/app.tau2EditField.Value app.ConstantEditField.Value];
                        lb = [app.LBt0EditField.Value app.LBsigmaEditField.Value app.LBA1EditField.Value 1/app.UBtau1EditField.Value app.LBA2EditField.Value 1/app.UBtau2EditField.Value app.LBConstEditField.Value];
                        ub = [app.UBt0EditField.Value app.UBsigmaEditField.Value app.UBA1EditField.Value 1/app.LBtau1EditField.Value app.UBA2EditField.Value 1/app.LBtau2EditField.Value app.UBConstEditField.Value];
                    case 3
                        app.ref_func = '(x(2)-x(1))*conv(normpdf(x,a+x(length(x))/2,b),c*exp(-d*x)+e*exp(-f*x)+g*exp(-h*x) + l,''same'')';
                        start = [app.t0EditField.Value app.sigmaEditField.Value app.A1EditField.Value 1/app.tau1EditField.Value app.A2EditField.Value ...
                            1/app.tau2EditField.Value app.A3EditField.Value 1/app.tau3EditField.Value app.ConstantEditField.Value];
                        lb = [app.LBt0EditField.Value app.LBsigmaEditField.Value app.LBA1EditField.Value 1/app.UBtau1EditField.Value app.LBA2EditField.Value 1/app.UBtau2EditField.Value app.LBA3EditField.Value 1/app.UBtau3EditField.Value app.LBConstEditField.Value];
                        ub = [app.UBt0EditField.Value app.UBsigmaEditField.Value app.UBA1EditField.Value 1/app.LBtau1EditField.Value app.UBA2EditField.Value 1/app.LBtau2EditField.Value app.UBA3EditField.Value 1/app.LBtau3EditField.Value app.UBConstEditField.Value];
                end
            end
            ft = fittype( app.ref_func, 'independent', 'x', 'dependent', 'y' );
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Algorithm = app.AlgorithmDropDown.Value;
            opts.DiffMaxChange = app.DiffMaxChangeEditField.Value;
            opts.DiffMinChange = app.DiffMinChangeEditField.Value;
            opts.Display = 'Off';
            opts.MaxFunEvals = app.MaxFunEvalsEditField.Value;
            opts.MaxIter = app.MaxIterEditField.Value;
            opts.Robust = app.RobustDropDown.Value;
            opts.TolFun = app.TolFunEditField.Value;
            opts.TolX = app.TolXEditField.Value;
            if app.WeightsCheckBox.Value == 1
                opts.Weights = sqrt(curve);
            end
            opts.StartPoint = start;
            opts.Upper = ub;
            opts.Lower = lb;
            if size(app.excl,1)>0
                excludedPoints = excludedata( t_fit(app.t_start:app.t_stop)', curve, 'Indices', app.excl - app.t_start + 1);
                opts.Exclude = excludedPoints;
            end
            if size(t_fit,2)==1
                t_fit = t_fit';
            end
            [fitresult, gof] = fit( t_fit(app.t_start:app.t_stop)', curve, ft, opts );
            assignin('base','opts',opts)
            assignin('base','t',t_fit(app.t_start:app.t_stop)');
            assignin('base','func',app.ref_func);
            assignin('base','start_points',fitresult);
            assignin('base','N',app.DecaysSpinner.Value);
            assignin('base','type_fun',app.FitModelDropDown.Value);
            assignin('base','Tbin',app.TemporalbinnsEditField.Value);
            
            if strcmp(app.FitModelDropDown.Value,'Exponential Decay')
                switch app.DecaysSpinner.Value
                    case 1
                        app.FitA1EditField.Value = fitresult.a;
                        app.Fittau1EditField.Value = 1/fitresult.b;
                        app.FitConstEditField.Value = fitresult.c;
                    case 2
                        app.FitA1EditField.Value = fitresult.a;
                        app.Fittau1EditField.Value = 1/fitresult.b;
                        app.Fittau2EditField.Value = 1/fitresult.d;
                        app.FitA2EditField.Value = fitresult.c;
                        app.FitConstEditField.Value = fitresult.e;
                    case 3
                        app.FitA1EditField.Value = fitresult.a;
                        app.Fittau1EditField.Value = 1/fitresult.b;
                        app.Fittau2EditField.Value = 1/fitresult.d;
                        app.FitA2EditField.Value = fitresult.c;
                        app.FitA3EditField.Value = fitresult.e;
                        app.Fittau3EditField.Value = 1/fitresult.f;
                        app.FitConstEditField.Value = fitresult.g;
                end
            else
                switch app.DecaysSpinner.Value
                    case 1
                        app.Fitt0EditField.Value = fitresult.a;
                        app.FitsigmaEditField.Value = fitresult.b;
                        app.FitA1EditField.Value = fitresult.c;
                        app.Fittau1EditField.Value = 1/fitresult.d;
                        app.FitConstEditField.Value = fitresult.e;
                    case 2
                        app.Fitt0EditField.Value = fitresult.a;
                        app.FitsigmaEditField.Value = fitresult.b;
                        app.FitA1EditField.Value = fitresult.c;
                        app.Fittau1EditField.Value = 1/fitresult.d;
                        app.Fittau2EditField.Value = 1/fitresult.f;
                        app.FitA2EditField.Value = fitresult.e;
                        app.FitConstEditField.Value = fitresult.g;
                    case 3
                        app.Fitt0EditField.Value = fitresult.a;
                        app.FitsigmaEditField.Value = fitresult.b;
                        app.FitA1EditField.Value = fitresult.c;
                        app.Fittau1EditField.Value = 1/fitresult.d;
                        app.Fittau2EditField.Value = 1/fitresult.f;
                        app.FitA2EditField.Value = fitresult.e;
                        app.FitA3EditField.Value = fitresult.g;
                        app.Fittau3EditField.Value = 1/fitresult.h;
                        app.FitConstEditField.Value = fitresult.l;
                end
            end
            
            lifetimes = [app.Fittau1EditField.Value app.Fittau2EditField.Value app.Fittau3EditField.Value];
            lifetimes = lifetimes(1:app.DecaysSpinner.Value);
            
            app.fit_result_structure.lifetime = lifetimes;
            app.fit_result_structure.opts = opts;
            app.fit_result_structure.t_interval = [app.t_start app.t_stop];
            app.fit_result_structure.T_bin = app.TemporalbinnsEditField.Value;
            app.fit_result_structure.func = app.ref_func;
            app.fit_result_structure.fitresult = fitresult;
            app.fit_result_structure.N = app.DecaysSpinner.Value;
            app.fit_result_structure.type_fun = app.FitModelDropDown.Value;
            app.fit_result_structure.lambda = [app.l_start app.l_stop];
            app.fit_result_structure.ROI = app.pos;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'λFLIM Compressive Microscopy Visualizing and Fitting Tool ';
            app.UIFigure.Position = [100 50 1074 755];
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            addpath './common/'
            %app.pos = [1 1 31 31];

            if strcmp(app.FilePathEditField.Value,'')==1
            [file, path] = uigetfile('*.mat');
            figure(app.UIFigure)
            if file == 0
                return;
            end
            app.FilePathEditField.Value = [path file];
            a = load([path file]);
            else
               a = load(app.FilePathEditField.Value);
            end


             if isfield(a,'im_fused') && isfield(a,'im')
                 if size(a.im_fused,1)>0
                   selection = uiconfirm(app.UIFigure,'Detected two dataset','Select','Icon','warning','Options',{'im','im_fused'});  
                   if strcmp(selection,'im_fused')
                       a.im = a.im_fused;
                   end
                 end
             end
 
             if isfield(a,'im_fused') && not(isfield(a,'im'))
                 if size(a.im_fused,1)>0
                  %selection = uiconfirm(app.UIFigure,'Detected two dataset','Select','Icon','warning','Options',{'im','im_fused'});  
                  %if strcmp(selection,'im_fused')
                      a.im = a.im_fused;
                  %end
                 end
             end
            
            if isfield(a,'im')
                app.data = a.im;
                if size(app.data,3)<size(app.data,4)
                app.data(:,:,(size(app.data,3)+1):size(app.data,4),:) = zeros(size(app.data,1),size(app.data,2),size(app.data,4)-size(app.data,3),size(app.data,4));
                else
                app.data(:,:,:,(size(app.data,4)+1):size(app.data,3)) = zeros(size(app.data,1),size(app.data,2),size(app.data,3),size(app.data,3)-size(app.data,4));    
                end
                row = repmat(1:size(app.data,3),[1 size(app.data,3)])';
                col = repmat(1:size(app.data,4),[size(app.data,4) 1]);
                col = col(:);
                app.pos = [row col];
                if size(app.data,1) == 1
                    warndlg('Data does not contain a temporal decay.')
                    return;
                end
            else
                app.data = squeeze(a.spc(:,:,1));
                app.pos = [1 1];
                app.DrawaROIButton.Enable = 0;
                app.FittheareaButton.Enable = 0;
            end
            app.lambda = a.lambda;
            app.t = a.t;
            app.TimeROInsEditField.Value = app.t(1);
            app.TimeROI2EditField.Value = app.t(end);
            app.WLROInmEditField.Value = app.lambda(1);
            app.WLROIendEditField.Value = app.lambda(end);
            hold(app.UIAxes2,'off')
            hold(app.UIAxes3,'off')
            
            if size(app.data,3) > 1
                app.Image = imagesc(app.UIAxes,squeeze(sum(app.data,1:2)));
                axis(app.UIAxes,'image'),colormap(app.UIAxes,'gray')
            else
                app.Image = imagesc(app.UIAxes,app.t,app.lambda,squeeze(app.data));
            end
            app.ColormapLimitsUpper.Value = app.UIAxes.CLim(2);
            app.ColormapLimitsLower.Value = app.UIAxes.CLim(1);
            
            disableDefaultInteractivity(app.UIAxes)
            
            for xi = 1:length(app.pos(:,1))
                s(:,:,xi) = app.data(app.t_start:app.t_stop,:,app.pos(xi,1),app.pos(xi,2));
                d(:,:,xi) = app.data(:,app.l_start:app.l_stop,app.pos(xi,1),app.pos(xi,2));
            end
               
            app.spectrum = plot(app.UIAxes2,app.lambda,squeeze(sum(s,[1 3])),'o-');
            hold(app.UIAxes2,'on')
            app.WLROIStart = plot(app.UIAxes2,[app.WLROInmEditField.Value app.WLROInmEditField.Value],app.UIAxes2.YLim,'color','k');
            app.WLROIStop = plot(app.UIAxes2,[app.WLROIendEditField.Value app.WLROIendEditField.Value],app.UIAxes2.YLim,'color','k');
            app.decay = plot(app.UIAxes3,app.t,squeeze(sum(d,[2:3])),'.');
            hold(app.UIAxes3,'on')
            app.TROIStop = plot(app.UIAxes3,[app.TimeROI2EditField.Value app.TimeROI2EditField.Value],app.UIAxes3.YLim,'color','k');
            app.TROIStart = plot(app.UIAxes3,[app.TimeROInsEditField.Value app.TimeROInsEditField.Value],app.UIAxes3.YLim,'color','k');
            
            app.TemporalbinnsEditField.Value = app.t(2)-app.t(1);
            if length(app.lambda)>1
            app.SpectralbinnmEditField.Value = app.lambda(2)-app.lambda(1);
            else
            app.SpectralbinnmEditField.Value = 0;
            end
            refresh_plots(app)
            app.data_nobin = app.data;
            app.t_nobin = app.t;
            app.lambda_nobin = app.lambda;
            app.fitnumber = 1;
           
        end

        % Button pushed function: ReleasefileButton
        function ReleasefileButtonPushed(app, event)
            app.UIFigure.Visible = 'off';
            APP_FitCfTool();
            % Delete old instance
            close(app.UIFigure)
        end

        % Value changed function: LogCheckBox
        function LogCheckBoxValueChanged(app, event)
            value = app.LogCheckBox.Value;
            if value
                app.UIAxes3.YScale = 'log';
                %refresh_plots(app);
                %app.UIAxes3.YLim(1) = 1;
            else
                app.UIAxes3.YScale = 'linear';
                %refresh_plots(app);
            end
        end

        % Value changed function: TimeROInsEditField
        function TimeROInsEditFieldValueChanged(app, event)
            [~,b] = min(abs(app.t - app.TimeROInsEditField.Value));
            app.TimeROInsEditField.Value = app.t(b);
            refresh_plots(app)
        end

        % Value changed function: TimeROI2EditField
        function TimeROI2EditFieldValueChanged(app, event)
            [~,b] = min(abs(app.t - app.TimeROI2EditField.Value));
            app.TimeROI2EditField.Value = app.t(b);
            refresh_plots(app)
        end

        % Value changed function: WLROInmEditField
        function WLROInmEditFieldValueChanged(app, event)
            if app.WLROInmEditField.Value > app.WLROIendEditField.Value
                app.WLROInmEditField.Value = app.WLROIendEditField.Value;
            end
            [~,b] = min(abs(app.lambda - app.WLROInmEditField.Value));
            app.WLROInmEditField.Value = app.lambda(b);
            refresh_plots(app)
        end

        % Value changed function: WLROIendEditField
        function WLROIendEditFieldValueChanged(app, event)
            if app.WLROIendEditField.Value < app.WLROInmEditField.Value
                app.WLROIendEditField.Value = app.WLROInmEditField.Value;
            end
            [~,b] = min(abs(app.lambda - app.WLROIendEditField.Value));
            app.WLROIendEditField.Value = app.lambda(b);
            refresh_plots(app)
        end

        % Button pushed function: DrawaROIButton
        function DrawaROIButtonPushed(app, event)
            if size(app.ImageROI,1) > 0
                delete(app.ImageROI)
            end
            app.ImageROI = drawfreehand(app.UIAxes);
            app.ImageROI.Position = round(app.ImageROI.Position);%-0.5;
            [row,col]=find(createMask(app.ImageROI));
            
            if (size(row,1)==0 && size(col,1)==0)||(size(row,1)==1 && size(col,1)==1&&size(unique(round(app.ImageROI.Position),'rows'),1)>1)
                row = app.ImageROI.Position(:,2);%-0.5;
                col = app.ImageROI.Position(:,1);%-0.5;
                app.ImageROI.Position = round(app.ImageROI.Position);%-1;
                %row = app.ImageROI.Position(2);
                %col = app.ImageROI.Position(1);
            end
            
            app.pos = [row col];

            refresh_plots(app)
        end

        % Value changed function: FitModelDropDown
        function FitModelDropDownValueChanged(app, event)
            value = app.FitModelDropDown.Value;
            switch value
                case 'Exponential Decay'
                    app.Fitt0EditField.Enable = 0;
                    app.FitsigmaEditField.Enable = 0;
                    app.t0EditField.Enable = 0;
                    app.t0EditFieldLabel.Enable = 0;
                    app.UBt0EditField.Enable = 0;
                    app.LBt0EditField.Enable = 0;
                    app.sigmaEditField.Enable = 0;
                    app.sigmaEditFieldLabel.Enable = 0;
                    app.UBsigmaEditField.Enable = 0;
                    app.LBsigmaEditField.Enable = 0;
                    app.TimeROInsEditField.Enable = 1;
                    app.ExcludepointsButton.Enable = 1;
                case 'Exp. Decay + IRF'
                    app.TimeROInsEditField.Value = app.t(1);
                    refresh_plots(app);
                    app.TimeROInsEditField.Enable = 0;
                    app.Fitt0EditField.Enable = 1;
                    app.FitsigmaEditField.Enable = 1;
                    app.t0EditField.Enable = 1;
                    app.t0EditFieldLabel.Enable = 1;
                    app.UBt0EditField.Enable = 1;
                    app.LBt0EditField.Enable = 1;
                    app.sigmaEditField.Enable = 1;
                    app.sigmaEditFieldLabel.Enable = 1;
                    app.UBsigmaEditField.Enable = 1;
                    app.LBsigmaEditField.Enable = 1;
                    ResetExceptionsButtonPushed(app,1);
                    app.ExcludepointsButton.Enable = 0;
            end
            
        end

        % Button pushed function: FitthecurveButton
        function FitthecurveButtonPushed(app, event)
            
            for xi = 1:length(app.pos(:,1))
                c(:,:,xi) = app.data(:,app.l_start:app.l_stop,app.pos(xi,1),app.pos(xi,2));
            end
            curve = squeeze(sum(c,[2 3]));
            
            assignin('base','data', c(app.t_start:app.t_stop,:,:));
            assignin('base', 'im', squeeze(sum(app.data(app.t_start:app.t_stop,app.l_start:app.l_stop,:,:),1:2)));
            
            if size(app.ImageROI,1)>0
            assignin('base','ROI',app.ImageROI.Position);
            end
            
            assignin('base','ROIpoints',app.pos);
            
            [app.fitresult,app.gof,t_fit] = fit2data(app, curve);
            
            if strcmp(app.FitModelDropDown.Value,'Exponential Decay')
                [~, pos_max] = max(curve);
                T_OS = app.t(pos_max);
            else
                T_OS = 0;
            end
            
            if size(app.fit_plot_handle,1) > 0
                app.fit_plot_handle.YData = app.fitresult(t_fit-T_OS);
                app.residuals.YData = (app.fitresult(t_fit(app.t_start:app.t_stop))-curve(app.t_start:app.t_stop))./real(sqrt(curve(app.t_start:app.t_stop)));
                app.fit_plot_handle.XData = t_fit;
                app.residuals.XData = t_fit(app.t_start:app.t_stop);
            else
                app.fit_plot_handle = plot(app.UIAxes3,t_fit,app.fitresult(t_fit-T_OS),'color','r');
                app.residuals = plot(app.UIAxes4,t_fit(app.t_start:app.t_stop),(app.fitresult(t_fit(app.t_start:app.t_stop))-curve(app.t_start:app.t_stop))./real(sqrt(curve(app.t_start:app.t_stop))));
            end
            try
                ylim(app.UIAxes3, [0.5e-1,max(curve).*1.2])
            catch ME
                ylim(app.UIAxes3, [0.0001.*max(curve).*1.2,max(curve).*1.2])
            end
            drawnow;
            
            text = "SSE: " + num2str(app.gof.sse) + "\n" + "Rsquare: " + num2str(app.gof.rsquare);
            app.FitresultTextArea.Value = splitlines(compose(text));
            app.FittheareaButton.Enable = 1;
        end

        % Value changed function: DecaysSpinner
        function DecaysSpinnerValueChanged(app, event)
            value = app.DecaysSpinner.Value;
            switch value
                case 1
                    app.A2EditField.Enable = 0;
                    app.A3EditField.Enable = 0;
                    app.LBA2EditField.Enable = 0;
                    app.UBA2EditField.Enable = 0;
                    app.LBA3EditField.Enable = 0;
                    app.UBA3EditField.Enable = 0;
                    app.FitA2EditField.Enable = 0;
                    app.FitA3EditField.Enable = 0;
                    app.tau2EditField.Enable = 0;
                    app.tau3EditField.Enable = 0;
                    app.LBtau2EditField.Enable = 0;
                    app.UBtau2EditField.Enable = 0;
                    app.LBtau3EditField.Enable = 0;
                    app.UBtau3EditField.Enable = 0;
                    app.Fittau2EditField.Enable = 0;
                    app.Fittau3EditField.Enable = 0;
                    app.A2EditFieldLabel.Enable = 0;
                    app.A3EditFieldLabel.Enable = 0;
                    app.tau2EditFieldLabel.Enable = 0;
                    app.tau3EditFieldLabel.Enable = 0;
                case 2
                    app.A2EditField.Enable = 1;
                    app.A3EditField.Enable = 0;
                    app.LBA2EditField.Enable = 1;
                    app.UBA2EditField.Enable = 1;
                    app.LBA3EditField.Enable = 0;
                    app.UBA3EditField.Enable = 0;
                    app.FitA2EditField.Enable = 1;
                    app.FitA3EditField.Enable = 0;
                    app.tau2EditField.Enable = 1;
                    app.tau3EditField.Enable = 0;
                    app.LBtau2EditField.Enable = 1;
                    app.UBtau2EditField.Enable = 1;
                    app.LBtau3EditField.Enable = 0;
                    app.UBtau3EditField.Enable = 0;
                    app.Fittau2EditField.Enable = 1;
                    app.Fittau3EditField.Enable = 0;
                    app.A2EditFieldLabel.Enable = 1;
                    app.A3EditFieldLabel.Enable = 0;
                    app.tau2EditFieldLabel.Enable = 1;
                    app.tau3EditFieldLabel.Enable = 0;
                case 3
                    app.A2EditField.Enable = 1;
                    app.A3EditField.Enable = 1;
                    app.LBA2EditField.Enable = 1;
                    app.UBA2EditField.Enable = 1;
                    app.LBA3EditField.Enable = 1;
                    app.UBA3EditField.Enable = 1;
                    app.FitA2EditField.Enable = 1;
                    app.FitA3EditField.Enable = 1;
                    app.tau2EditField.Enable = 1;
                    app.tau3EditField.Enable = 1;
                    app.LBtau2EditField.Enable = 1;
                    app.UBtau2EditField.Enable = 1;
                    app.LBtau3EditField.Enable = 1;
                    app.UBtau3EditField.Enable = 1;
                    app.Fittau2EditField.Enable = 1;
                    app.Fittau3EditField.Enable = 1;
                    app.A2EditFieldLabel.Enable = 1;
                    app.A3EditFieldLabel.Enable = 1;
                    app.tau2EditFieldLabel.Enable = 1;
                    app.tau3EditFieldLabel.Enable = 1;
            end
        end

        % Button pushed function: InitialguessButton
        function InitialguessButtonPushed(app, event)
            for xi = 1:length(app.pos(:,1))
                c(:,:,xi) = app.data(:,:,app.pos(xi,1),app.pos(xi,2));
            end            
            
            curve = squeeze(sum(c,[2 3]));
            [val, pos_max] = max(curve);
            t_est = app.t(pos_max:end) - app.t(pos_max);
            app.A1EditField.Value = val/app.DecaysSpinner.Value;
            
            if strcmp(app.FitModelDropDown.Value,'Exp. Decay + IRF')
                app.t0EditField.Value = app.t(pos_max);
                app.UBt0EditField.Value = 1.2*app.t(pos_max);
                app.LBt0EditField.Value = 0.8*app.t(pos_max);
                app.sigmaEditField.Value = 10*(app.t(2)-app.t(1));
                app.UBsigmaEditField.Value = 10*app.sigmaEditField.Value;
                app.LBsigmaEditField.Value = 0*app.sigmaEditField.Value;
            end
            if round(pos_max) > 1
                val = mean(curve(1:round(pos_max*0.2)));
                val(val<1e-7) = 0;
                app.ConstantEditField.Value = val;
            else
                app.ConstantEditField.Value = 0;
            end
            curve = curve(pos_max:end);
            [~, b] = min(abs(curve - curve(1)*(1-exp(-1))));
            app.tau1EditField.Value = t_est(b);
            app.UBA1EditField.Value = round(curve(1)*1.5);
            app.UBtau1EditField.Value = app.t(end);
            app.LBConstEditField.Value = 0.5*app.ConstantEditField.Value;
            app.UBConstEditField.Value = 1.5*app.ConstantEditField.Value;
            switch app.DecaysSpinner.Value
                case 2
                    app.A2EditField.Value = app.A1EditField.Value;
                    app.tau2EditField.Value = app.tau1EditField.Value;
                    app.UBA2EditField.Value = app.UBA1EditField.Value;
                    app.UBtau2EditField.Value = app.t(end);
                case 3
                    app.A2EditField.Value = app.A1EditField.Value;
                    app.A3EditField.Value = app.A1EditField.Value;
                    app.tau2EditField.Value = app.tau1EditField.Value;
                    app.tau3EditField.Value = app.tau1EditField.Value;
                    app.UBA2EditField.Value = app.UBA1EditField.Value;
                    app.UBA3EditField.Value = app.UBA1EditField.Value;
                    app.UBtau2EditField.Value = app.t(end);
                    app.UBtau3EditField.Value = app.t(end);
            end
            app.FitthecurveButton.Enable = 1;
        end

        % Button pushed function: ExcludepointsButton
        function ExcludepointsButtonPushed(app, event)
            h = drawrectangle(app.UIAxes3);
            
            exclude = [h.Position(1) h.Position(1)+h.Position(3)];
            if h.Position(1)+h.Position(3) < app.TimeROInsEditField.Value || h.Position(1) > app.TimeROI2EditField.Value
                delete(h)
                return;
            end
            if exclude(1) < app.TimeROInsEditField.Value
                exclude(1) = app.TimeROInsEditField.Value;
                h.Position(3) = h.Position(1)+h.Position(3)-exclude(1);
                h.Position(1) = exclude(1);
            end
            if exclude(2)>app.TimeROI2EditField.Value
                exclude(2) = app.TimeROI2EditField.Value;
                h.Position(3) = exclude(2) - h.Position(1);
            end
            [~, t_exclude(1)] = min(abs(app.t-exclude(1)));
            [~, t_exclude(2)] = min(abs(app.t-exclude(2)));
            
            if size(app.excl,1)==0
                app.excl = [];
            end
            app.hexclude = [app.hexclude h];
            app.excl = [app.excl t_exclude(1):t_exclude(2)];
            app.ResetExceptionsButton.Enable = 1;
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            t = app.t;
            pos = app.pos;
            start_data = app.data;
            for xi = 1:length(app.pos(:,1))
                c(:,:,xi) = app.data(app.t_start:app.t_stop,app.l_start:app.l_stop,app.pos(xi,1),app.pos(xi,2));
            end            
            
            data = c;
            l_start = app.l_start;
            l_stop = app.l_stop;
            t_start = app.t_start;
            t_stop = app.t_stop;
            fitresult = app.fitresult;
            %curve = app.curve;
            
            lifetimes = [app.Fittau1EditField.Value app.Fittau2EditField.Value app.Fittau3EditField.Value];
            lifetimes = lifetimes(1:app.DecaysSpinner.Value);
            
            filename = app.FilePathEditField.Value;
            filename = filename(1:end-4);
            uisave(["data","t","pos","l_start","l_stop","t_start","t_stop","fitresult","start_data","lifetimes"],[filename,'_fit.mat'])
        end

        % Button pushed function: ResetExceptionsButton
        function ResetExceptionsButtonPushed(app, event)
            app.excl = [];
            delete(app.hexclude);
            app.hexclude = [];
            app.ResetExceptionsButton.Enable = 0;
            app.ExcludenegativepointsCheckBox.Value = 0;
        end

        % Button pushed function: FittheareaButton
        function FittheareaButtonPushed(app, event)
            assignin('base','path',app.FilePathEditField.Value);
            addpath './'
            APP_FitArea;
        end

        % Button pushed function: SenddatatoCftoolButton
        function SenddatatoCftoolButtonPushed(app, event)
            for xi = 1:length(app.pos(:,1))
                c(:,:,xi) = app.data(:,app.l_start:app.l_stop,app.pos(xi,1),app.pos(xi,2));
            end      
            curve = squeeze(sum(c,[2 3]));
            [~, pos_max] = max(curve);
            %if strcmp(app.FitModelDropDown.Value,'Exponential Decay')
                t_fit = app.t - app.t(pos_max);
            %else
            %    t_fit = app.t - app.t(pos_max);
            %end
            cftool(t_fit(app.t_start:app.t_stop)',curve(app.t_start:app.t_stop));
        end

        % Value changed function: TemporalbinnsEditField
        function TemporalbinnsEditFieldValueChanged(app, event)
            app.DefaultButton.Enable = 1;
            
            [app.t,app.data,app.TemporalbinnsEditField.Value] = ...
                BinData(app.data_nobin,app.t_nobin', app.TemporalbinnsEditField.Value,app.BintypeSwitch.Value);
            app.t = app.t';
            
            refresh_plots(app)
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            app.TemporalbinnsEditField.Value = app.t_nobin(2)-app.t_nobin(1);
            TemporalbinnsEditFieldValueChanged(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: ExcludenegativepointsCheckBox
        function ExcludenegativepointsCheckBoxValueChanged(app, event)
            value = app.ExcludenegativepointsCheckBox.Value;
            for xi = 1:length(app.pos(:,1))
                c(:,:,xi) = app.data(:,app.l_start:app.l_stop,app.pos(xi,1),app.pos(xi,2));
            end
            curve = squeeze(sum(c,[2 3]));
            [a,~] = find(curve<0);
            a = a';
            [~, t_exclude(1)] = min(abs(app.t-app.TimeROInsEditField.Value));
            [~, t_exclude(2)] = min(abs(app.t-app.TimeROI2EditField.Value));
            a(a<t_exclude(1)) = [];
            a(a>t_exclude(2)) = [];
            if value
                app.excl = [app.excl a];
            else
                [~,b] = ismember(a,app.excl);
                app.excl(b) = [];
            end
            
        end

        % Button pushed function: SendfittoworkspaceButton
        function SendfittoworkspaceButtonPushed(app, event)
            fit_name = ['data_fit',num2str(app.fitnumber)];
            assignin('base',fit_name,app.fit_result_structure);
            app.fitnumber = app.fitnumber + 1;
        end

        % Value changed function: ColormapLimitsLower
        function ColormapLimitsLowerValueChanged(app, event)
            app.UIAxes.CLim(1) = round(app.ColormapLimitsLower.Value*100)/100; 
            app.ColormapLimitsLower.Value = round(app.ColormapLimitsLower.Value*100)/100;
        end

        % Value changed function: ColormapLimitsUpper
        function ColormapLimitsUpperValueChanged(app, event)
            app.UIAxes.CLim(2) = round(app.ColormapLimitsUpper.Value*100)/100;
            app.ColormapLimitsUpper.Value = round(app.ColormapLimitsUpper.Value*100)/100;
        end

        % Button pushed function: DownloadSpectrum
        function DownloadSpectrumPushed(app, event)
            for xi = 1:length(app.pos(:,1))
                c(:,:,xi) = app.data(:,app.l_start:app.l_stop,app.pos(xi,1),app.pos(xi,2));
            end
            spectrum.Y = sum(c(app.t_start:app.t_stop,:,:),[1 3]);
            spectrum.X = app.lambda(app.l_start:app.l_stop); 
            decay.Y = sum(c(app.t_start:app.t_stop,:,:),[2 3]);
            [~, pos_max] = max(sum(c,[2 3]));
            decay.X = app.t(app.t_start:app.t_stop);
            decay.Xfit = app.t(app.t_start:app.t_stop) - app.t(pos_max);
            assignin('base','spectrum',spectrum);
            assignin('base','decay',decay);
            app.UploadSpectrum.Enable = 1;
            if size(app.ImageROI,1)>0
            assignin('base','ROI',app.ImageROI.Position);
            end
        end

        % Button pushed function: UploadSpectrum
        function UploadSpectrumPushed(app, event)
            try
                spectrum = evalin('base','spectrum');
                decay = evalin('base','decay');
            catch ME
                return;
            end
            try
                ROI = evalin('base','ROI');
            catch ME
                return
            end
            app.RescaleSpectrum.Enable = 1;
            app.overlaySpectrum = plot(app.UIAxes2,spectrum.X,spectrum.Y,'o-','Color','r');
            app.overlayDecay = plot(app.UIAxes3,decay.X,decay.Y,'.','Color','r');
            if exist('ROI')
            app.overlayROI = drawfreehand(app.UIAxes,'Position',ROI,'Color','r');
            end
            app.DeleteLoadedSpectrum.Enable = 1;
            app.RescaleSpectrum.Enable = 1;
        end

        % Button pushed function: DeleteLoadedSpectrum
        function DeleteLoadedSpectrumButtonPushed(app, event)
            delete(app.overlaySpectrum);
            delete(app.overlayDecay);
            delete(app.overlayROI);
            app.DeleteLoadedSpectrum.Enable = 0;
            app.RescaleSpectrum.Enable = 0;
            app.RescaleSpectrum.Value = 0;
        end

        % Value changed function: RescaleSpectrum
        function RescaleSpectrumValueChanged(app, event)
            value = app.RescaleSpectrum.Value;
            if value
                app.overlaySpectrum.YData = max(app.spectrum.YData).*app.overlaySpectrum.YData./max(app.overlaySpectrum.YData);
                app.overlayDecay.YData = max(app.decay.YData).*app.overlayDecay.YData./max(app.overlayDecay.YData);
            else
                spectrum = evalin('base','spectrum');
                decay = evalin('base','decay');
                app.overlaySpectrum.YData = max(spectrum.Y).*app.overlaySpectrum.YData./max(app.overlaySpectrum.YData);
                app.overlayDecay.YData = max(decay.Y).*app.overlayDecay.YData./max(app.overlayDecay.YData);
            end
        end

        % Value changed function: SpectralbinnmEditField
        function SpectralbinnmEditFieldValueChanged(app, event)
            app.DefaultButtonLambda.Enable = 1;
            [~,app.l_start] = min(abs(app.lambda_nobin - app.WLROInmEditField.Value));
            [~,app.l_stop] = min(abs(app.lambda_nobin - app.WLROIendEditField.Value));
            if length(size(app.data_nobin)) == 4
                a = [3 4];
            else
                a = 3;
            end

            [app.lambda,app.data,app.SpectralbinnmEditField.Value] = ...
                BinData(permute(app.data_nobin(:,app.l_start:app.l_stop,:,:),[2 1 a]),app.lambda_nobin(app.l_start:app.l_stop)', app.SpectralbinnmEditField.Value,app.BintypeSwitch.Value);
            app.lambda = app.lambda';
            app.data = permute(app.data,[2 1 a]);
            app.WLROIendEditField.Value = app.lambda(end);
            app.WLROInmEditField.Value = app.lambda(1);
            refresh_plots(app)
        end

        % Button pushed function: DefaultButtonLambda
        function DefaultButtonLambdaPushed(app, event)
            app.SpectralbinnmEditField.Value = app.lambda_nobin(2)-app.lambda_nobin(1);
            app.WLROIendEditField.Value = app.lambda_nobin(end);
            app.WLROInmEditField.Value = app.lambda_nobin(1);
            SpectralbinnmEditFieldValueChanged(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Spectrum')
            xlabel(app.UIAxes2, 'Wavelength [nm]')
            ylabel(app.UIAxes2, 'Counts')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Time resolved signal')
            xlabel(app.UIAxes3, 'Time [ns]')
            ylabel(app.UIAxes3, 'Counts')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Residuals')
            xlabel(app.UIAxes4, 'Time [ns]')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            app.NonlinearLeastSquareSettingsPanel = uipanel(app.UIFigure);
            app.NonlinearLeastSquareSettingsPanel.Title = 'NonlinearLeastSquare Settings';
            app.WeightsCheckBox = uicheckbox(app.NonlinearLeastSquareSettingsPanel);
            app.WeightsCheckBox.Text = 'Weights';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel = uipanel(app.UIFigure);
            app.FittheareaButton = uibutton(app.Panel, 'push');
            app.FittheareaButton.ButtonPushedFcn = createCallbackFcn(app, @FittheareaButtonPushed, true);
            app.FittheareaButton.Enable = 'off';
            app.FittheareaButton.Text = 'Fit the area';
            app.InitialguessButton = uibutton(app.Panel, 'push');
            app.InitialguessButton.ButtonPushedFcn = createCallbackFcn(app, @InitialguessButtonPushed, true);
            app.InitialguessButton.Text = 'Initial guess';
            app.LoaddataButton = uibutton(app.UIFigure, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.FitModelDropDownLabel = uilabel(app.UIFigure);
            app.FitModelDropDownLabel.Text = 'Fit Model';
            app.FitModelDropDown = uidropdown(app.UIFigure);
            app.FitModelDropDown.Items = {'Exponential Decay', 'Exp. Decay + IRF'};
            app.FitModelDropDown.ValueChangedFcn = createCallbackFcn(app, @FitModelDropDownValueChanged, true);
            app.FitModelDropDown.Value = 'Exponential Decay';
            app.DrawaROIButton = uibutton(app.UIFigure, 'push');
            app.DrawaROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawaROIButtonPushed, true);
            app.DrawaROIButton.Text = 'Draw a ROI';
            app.DecaysSpinnerLabel = uilabel(app.UIFigure);
            app.DecaysSpinnerLabel.Text = '# Decays';
            app.DecaysSpinner = uispinner(app.UIFigure);
            app.DecaysSpinner.Limits = [1 3];
            app.DecaysSpinner.ValueChangedFcn = createCallbackFcn(app, @DecaysSpinnerValueChanged, true);
            app.DecaysSpinner.Value = 1;
            app.ReleasefileButton = uibutton(app.UIFigure, 'push');
            app.ReleasefileButton.ButtonPushedFcn = createCallbackFcn(app, @ReleasefileButtonPushed, true);
            app.ReleasefileButton.Text = 'Release file';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.TimeROInsEditFieldLabel = uilabel(app.UIFigure);
            app.TimeROInsEditFieldLabel.Text = 'Time ROI [ns]';
            app.TimeROInsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeROInsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeROInsEditFieldValueChanged, true);
            app.TimeROI2EditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeROI2EditField.ValueChangedFcn = createCallbackFcn(app, @TimeROI2EditFieldValueChanged, true);
            app.WLROInmEditFieldLabel = uilabel(app.UIFigure);
            app.WLROInmEditFieldLabel.Text = 'WL ROI [nm]';
            app.WLROInmEditField = uieditfield(app.UIFigure, 'numeric');
            app.WLROInmEditField.ValueChangedFcn = createCallbackFcn(app, @WLROInmEditFieldValueChanged, true);
            app.WLROIendEditField = uieditfield(app.UIFigure, 'numeric');
            app.WLROIendEditField.ValueChangedFcn = createCallbackFcn(app, @WLROIendEditFieldValueChanged, true);
            app.LogCheckBox = uicheckbox(app.UIFigure);
            app.LogCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogCheckBoxValueChanged, true);
            app.LogCheckBox.Text = ' Log';
            app.FitthecurveButton = uibutton(app.UIFigure, 'push');
            app.FitthecurveButton.ButtonPushedFcn = createCallbackFcn(app, @FitthecurveButtonPushed, true);
            app.FitthecurveButton.Enable = 'off';
            app.FitthecurveButton.Text = 'Fit the curve';
            app.StartingPointsLabel = uilabel(app.UIFigure);
            app.StartingPointsLabel.Text = 'Starting Points';
            app.LowerLabel = uilabel(app.UIFigure);
            app.LowerLabel.Text = 'Lower ';
            app.UpperLabel = uilabel(app.UIFigure);
            app.UpperLabel.Text = 'Upper';
            app.LBA1EditField = uieditfield(app.UIFigure, 'numeric');
            app.UBA1EditField = uieditfield(app.UIFigure, 'numeric');
            app.LBtau1EditField = uieditfield(app.UIFigure, 'numeric');
            app.UBtau1EditField = uieditfield(app.UIFigure, 'numeric');
            app.LBt0EditField = uieditfield(app.UIFigure, 'numeric');
            app.LBt0EditField.Enable = 'off';
            app.UBt0EditField = uieditfield(app.UIFigure, 'numeric');
            app.UBt0EditField.Enable = 'off';
            app.LBsigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.LBsigmaEditField.Enable = 'off';
            app.UBsigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.UBsigmaEditField.Enable = 'off';
            app.A1EditFieldLabel = uilabel(app.UIFigure);
            app.A1EditFieldLabel.Text = 'A1';
            app.A1EditField = uieditfield(app.UIFigure, 'numeric');
            app.A2EditFieldLabel = uilabel(app.UIFigure);
            app.A2EditFieldLabel.Enable = 'off';
            app.A2EditFieldLabel.Text = 'A2';
            app.A2EditField = uieditfield(app.UIFigure, 'numeric');
            app.A2EditField.Enable = 'off';
            app.A3EditFieldLabel = uilabel(app.UIFigure);
            app.A3EditFieldLabel.Enable = 'off';
            app.A3EditFieldLabel.Text = 'A3';
            app.A3EditField = uieditfield(app.UIFigure, 'numeric');
            app.A3EditField.Enable = 'off';
            app.tau1EditFieldLabel = uilabel(app.UIFigure);
            app.tau1EditFieldLabel.Text = 'tau1';
            app.tau1EditField = uieditfield(app.UIFigure, 'numeric');
            app.tau2EditFieldLabel = uilabel(app.UIFigure);
            app.tau2EditFieldLabel.Enable = 'off';
            app.tau2EditFieldLabel.Text = 'tau2';
            app.tau2EditField = uieditfield(app.UIFigure, 'numeric');
            app.tau2EditField.Enable = 'off';
            app.tau3EditFieldLabel = uilabel(app.UIFigure);
            app.tau3EditFieldLabel.Enable = 'off';
            app.tau3EditFieldLabel.Text = 'tau3';
            app.tau3EditField = uieditfield(app.UIFigure, 'numeric');
            app.tau3EditField.Enable = 'off';
            app.t0EditFieldLabel = uilabel(app.UIFigure);
            app.t0EditFieldLabel.Enable = 'off';
            app.t0EditFieldLabel.Text = 't0';
            app.t0EditField = uieditfield(app.UIFigure, 'numeric');
            app.t0EditField.Enable = 'off';
            app.sigmaEditFieldLabel = uilabel(app.UIFigure);
            app.sigmaEditFieldLabel.Enable = 'off';
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.sigmaEditField.Enable = 'off';
            app.TolXEditFieldLabel = uilabel(app.UIFigure);
            app.TolXEditFieldLabel.Text = 'TolX';
            app.TolXEditField = uieditfield(app.UIFigure, 'numeric');
            app.TolXEditField.Value = 1e-08;
            app.ConstantEditFieldLabel = uilabel(app.UIFigure);
            app.ConstantEditFieldLabel.Text = 'Constant';
            app.ConstantEditField = uieditfield(app.UIFigure, 'numeric');
            app.FitLabel = uilabel(app.UIFigure);
            app.FitLabel.Text = 'Fit';
            app.FitA1EditField = uieditfield(app.UIFigure, 'numeric');
            app.FitA1EditField.Editable = 'off';
            app.FitA2EditField = uieditfield(app.UIFigure, 'numeric');
            app.FitA2EditField.Editable = 'off';
            app.FitA2EditField.Enable = 'off';
            app.FitA3EditField = uieditfield(app.UIFigure, 'numeric');
            app.FitA3EditField.Editable = 'off';
            app.FitA3EditField.Enable = 'off';
            app.Fittau1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Fittau1EditField.Editable = 'off';
            app.Fittau2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Fittau2EditField.Editable = 'off';
            app.Fittau2EditField.Enable = 'off';
            app.Fittau3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Fittau3EditField.Editable = 'off';
            app.Fittau3EditField.Enable = 'off';
            app.FitConstEditField = uieditfield(app.UIFigure, 'numeric');
            app.FitConstEditField.Editable = 'off';
            app.Fitt0EditField = uieditfield(app.UIFigure, 'numeric');
            app.Fitt0EditField.Editable = 'off';
            app.Fitt0EditField.Enable = 'off';
            app.FitsigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.FitsigmaEditField.Editable = 'off';
            app.FitsigmaEditField.Enable = 'off';
            app.LBA2EditField = uieditfield(app.UIFigure, 'numeric');
            app.LBA2EditField.Enable = 'off';
            app.LBA3EditField = uieditfield(app.UIFigure, 'numeric');
            app.LBA3EditField.Enable = 'off';
            app.UBA2EditField = uieditfield(app.UIFigure, 'numeric');
            app.UBA2EditField.Enable = 'off';
            app.UBA3EditField = uieditfield(app.UIFigure, 'numeric');
            app.UBA3EditField.Enable = 'off';
            app.LBtau2EditField = uieditfield(app.UIFigure, 'numeric');
            app.LBtau2EditField.Enable = 'off';
            app.LBtau3EditField = uieditfield(app.UIFigure, 'numeric');
            app.LBtau3EditField.Enable = 'off';
            app.UBtau2EditField = uieditfield(app.UIFigure, 'numeric');
            app.UBtau2EditField.Enable = 'off';
            app.UBtau3EditField = uieditfield(app.UIFigure, 'numeric');
            app.UBtau3EditField.Enable = 'off';
            app.LBConstEditField = uieditfield(app.UIFigure, 'numeric');
            app.UBConstEditField = uieditfield(app.UIFigure, 'numeric');
            app.FitresultTextArea = uitextarea(app.UIFigure);
            app.FitresultTextArea.Editable = 'off';
            app.AlgorithmDropDownLabel = uilabel(app.UIFigure);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.UIFigure);
            app.AlgorithmDropDown.Items = {'Trust-Region', 'Levenberg-Marquardt'};
            app.AlgorithmDropDown.Value = 'Trust-Region';
            app.DiffMaxChangeLabel = uilabel(app.UIFigure);
            app.DiffMaxChangeLabel.Text = 'DiffMaxChange';
            app.DiffMaxChangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.DiffMaxChangeEditField.Value = 0.1;
            app.MaxFunEvalsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxFunEvalsEditFieldLabel.Text = 'MaxFunEvals';
            app.MaxFunEvalsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxFunEvalsEditField.Value = 600;
            app.MaxIterEditFieldLabel = uilabel(app.UIFigure);
            app.MaxIterEditFieldLabel.Text = 'MaxIter';
            app.MaxIterEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxIterEditField.Value = 400;
            app.TolFunEditFieldLabel = uilabel(app.UIFigure);
            app.TolFunEditFieldLabel.Text = 'TolFun';
            app.TolFunEditField = uieditfield(app.UIFigure, 'numeric');
            app.TolFunEditField.Value = 1e-08;
            app.RobustDropDownLabel = uilabel(app.UIFigure);
            app.RobustDropDownLabel.Text = 'Robust';
            app.RobustDropDown = uidropdown(app.UIFigure);
            app.RobustDropDown.Items = {'Off', 'LAR', 'Bisquare'};
            app.RobustDropDown.Value = 'Off';
            app.DiffMinChangeLabel = uilabel(app.UIFigure);
            app.DiffMinChangeLabel.Text = 'DiffMinChange';
            app.DiffMinChangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.DiffMinChangeEditField.Value = 1e-08;
            app.ExcludepointsButton = uibutton(app.UIFigure, 'push');
            app.ExcludepointsButton.ButtonPushedFcn = createCallbackFcn(app, @ExcludepointsButtonPushed, true);
            app.ExcludepointsButton.Text = 'Exclude points';
            app.ResetExceptionsButton = uibutton(app.UIFigure, 'push');
            app.ResetExceptionsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetExceptionsButtonPushed, true);
            app.ResetExceptionsButton.Enable = 'off';
            app.ResetExceptionsButton.Text = 'Reset Exceptions';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.SenddatatoCftoolButton = uibutton(app.UIFigure, 'push');
            app.SenddatatoCftoolButton.ButtonPushedFcn = createCallbackFcn(app, @SenddatatoCftoolButtonPushed, true);
            app.SenddatatoCftoolButton.Text = 'Send data to Cftool';
            app.TemporalbinnsEditFieldLabel = uilabel(app.UIFigure);
            app.TemporalbinnsEditFieldLabel.Text = 'Temporal bin [ns]';
            app.TemporalbinnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemporalbinnsEditField.ValueChangedFcn = createCallbackFcn(app, @TemporalbinnsEditFieldValueChanged, true);
            app.DefaultButton = uibutton(app.UIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Enable = 'off';
            app.DefaultButton.Text = 'Default';
            app.BintypeSwitch = uiswitch(app.UIFigure, 'slider');
            app.BintypeSwitch.Items = {'Bin', 'Mov. Ave'};
            app.BintypeSwitch.Value = 'Bin';
            app.ExcludenegativepointsCheckBox = uicheckbox(app.UIFigure);
            app.ExcludenegativepointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExcludenegativepointsCheckBoxValueChanged, true);
            app.ExcludenegativepointsCheckBox.Text = 'Exclude negative points';
            app.SendfittoworkspaceButton = uibutton(app.UIFigure, 'push');
            app.SendfittoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SendfittoworkspaceButtonPushed, true);
            app.SendfittoworkspaceButton.Text = 'Send fit to workspace';
            app.ColormapLimitsUpper = uieditfield(app.UIFigure, 'numeric');
            app.ColormapLimitsUpper.ValueChangedFcn = createCallbackFcn(app, @ColormapLimitsUpperValueChanged, true);
            app.ColormapLimitsEditFieldLabel = uilabel(app.UIFigure);
            app.ColormapLimitsEditFieldLabel.Text = 'Colormap Limits';
            app.ColormapLimitsLower = uieditfield(app.UIFigure, 'numeric');
            app.ColormapLimitsLower.ValueChangedFcn = createCallbackFcn(app, @ColormapLimitsLowerValueChanged, true);
            app.DownloadSpectrum = uibutton(app.UIFigure, 'push');
            app.DownloadSpectrum.ButtonPushedFcn = createCallbackFcn(app, @DownloadSpectrumPushed, true);
            app.DownloadSpectrum.Text = '↓';
            app.UploadSpectrum = uibutton(app.UIFigure, 'push');
            app.UploadSpectrum.ButtonPushedFcn = createCallbackFcn(app, @UploadSpectrumPushed, true);
            app.UploadSpectrum.Enable = 'off';
            app.UploadSpectrum.Text = '↑';
            app.DeleteLoadedSpectrum = uibutton(app.UIFigure, 'push');
            app.DeleteLoadedSpectrum.ButtonPushedFcn = createCallbackFcn(app, @DeleteLoadedSpectrumButtonPushed, true);
            app.DeleteLoadedSpectrum.Enable = 'off';
            app.DeleteLoadedSpectrum.Text = 'X';
            app.RescaleSpectrum = uibutton(app.UIFigure, 'state');
            app.RescaleSpectrum.ValueChangedFcn = createCallbackFcn(app, @RescaleSpectrumValueChanged, true);
            app.RescaleSpectrum.Enable = 'off';
            app.RescaleSpectrum.Text = '≡';
            app.SpectralbinnmEditFieldLabel = uilabel(app.UIFigure);
            app.SpectralbinnmEditFieldLabel.Text = 'Spectral bin [nm]';
            app.SpectralbinnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpectralbinnmEditField.ValueChangedFcn = createCallbackFcn(app, @SpectralbinnmEditFieldValueChanged, true);
            app.DefaultButtonLambda = uibutton(app.UIFigure, 'push');
            app.DefaultButtonLambda.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonLambdaPushed, true);
            app.DefaultButtonLambda.Enable = 'off';
            app.DefaultButtonLambda.Text = 'Default';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_FitCfTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end