classdef APP_ImageReconstruction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_2                         matlab.ui.container.Panel
        FlipCheckBox                    matlab.ui.control.CheckBox
        BleachingcorrectionButton       matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        NegEditFieldLabel               matlab.ui.control.Label
        NegEditField                    matlab.ui.control.NumericEditField
        MethodDropDownLabel             matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        PatterntypeDropDown_2Label_2    matlab.ui.control.Label
        CWDropDown                      matlab.ui.control.DropDown
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel_2               matlab.ui.control.Label
        XEditField_2                    matlab.ui.control.NumericEditField
        TVALmu                          matlab.ui.control.EditField
        TVALbeta                        matlab.ui.control.EditField
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        BasisDropDownLabel              matlab.ui.control.Label
        BasisDropDown                   matlab.ui.control.DropDown
        LoadfileButton                  matlab.ui.control.StateButton
        EditField                       matlab.ui.control.EditField
        PatterntypeDropDown_2Label      matlab.ui.control.Label
        PatterntypeDropDown_2           matlab.ui.control.DropDown
        CompressivesensingSwitchLabel   matlab.ui.control.Label
        CompressivesensingSwitch        matlab.ui.control.Switch
        RatioEditFieldLabel             matlab.ui.control.Label
        RatioEditField                  matlab.ui.control.NumericEditField
        CompressionTypeDropDownLabel    matlab.ui.control.Label
        CompressionTypeDropDown         matlab.ui.control.DropDown
        ReconstructButton               matlab.ui.control.StateButton
        SaveButton                      matlab.ui.control.Button
        ColormapLimEditFieldLabel       matlab.ui.control.Label
        ColormapLimEditField            matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        IntegralovertimeCheckBox        matlab.ui.control.CheckBox
        IntegraloverwavelengthCheckBox  matlab.ui.control.CheckBox
        ReleasefileButton               matlab.ui.control.Button
        FuseSPCandCameraButton          matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        TimeROInsEditFieldLabel         matlab.ui.control.Label
        TimeROInsEditField              matlab.ui.control.NumericEditField
        TimeROI2EditField               matlab.ui.control.NumericEditField
        WLROInmEditFieldLabel           matlab.ui.control.Label
        WLROInmEditField                matlab.ui.control.NumericEditField
        WLROIendEditField               matlab.ui.control.NumericEditField
        TemporalbinnsEditFieldLabel     matlab.ui.control.Label
        TemporalbinnsEditField          matlab.ui.control.NumericEditField
        DefaultButton                   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        spc
        t
        lambda
        im
        loaded
        camera
        M
        had_spectrum
        had
        im_fused
        t_start
        t_stop
        l_start
        l_stop
        spc_nobin
        t_nobin
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Single Pixel Camera Image Reconstruction';
            path = mfilename('fullpath');
            cd(path(1:(end-length(mfilename()))));            
        end

        % Value changed function: LoadfileButton
        function LoadfileButtonValueChanged(app, event)
            addpath './common/'
            [file, path] = uigetfile('*.mat');
            app.LoadfileButton.Value = 0;
            figure(app.UIFigure)
            if file == 0
                return;
            end
            app.EditField.Value = [path file];
            a = load([path file]);
            app.LoadfileButton.Value = 0;
            app.spc = a.spc;
            if isfield(a,'lambda') ~= 0
                app.lambda = a.lambda;
            else
                app.lambda = 1:size(app.spc,2);
            end
            app.t = a.t;
            app.camera = a.camera;
            app.had_spectrum = plot(app.UIAxes,squeeze(sum(app.spc,1:2)));
            xlim(app.UIAxes, [1 size(app.spc,3)])
            grid(app.UIAxes,"minor")
            box(app.UIAxes,"on")
            app.LoadfileButton.Enable = 0;
            
            app.TimeROInsEditField.Value = app.t(1);
            app.TimeROI2EditField.Value = app.t(end);
            app.WLROInmEditField.Value = app.lambda(1);
            app.WLROIendEditField.Value = app.lambda(end);
            app.TemporalbinnsEditField.Value = app.t(2)-app.t(1);
            app.spc_nobin = app.spc;
            app.t_nobin = app.t;
            while sum(app.camera,1:2)==0
                [file,path] = uigetfile({'*.mat'},...
                          'Camera is empty. Select a file containing a valid camera:');
                figure(app.UIFigure)
                if file == 0
                    return;
                end
                a = load([path file]);
                app.camera = a.camera;
            end
        end

        % Value changed function: ReconstructButton
        function ReconstructButtonValueChanged(app, event)
            app.DropDown.Value = 'K';
            DropDownValueChanged(app);
            spc_raw = app.spc;
            app.spc = app.spc(app.t_start:app.t_stop,app.l_start:app.l_stop,:);
            
            if app.IntegralovertimeCheckBox.Value == 1
                app.spc = sum(app.spc,1);
            end
            
            if app.IntegraloverwavelengthCheckBox.Value == 1
                app.spc = sum(app.spc,2);
            end
            
            if not(strcmp(app.BasisDropDown.Value,'Raster Scan'))
            switch app.PatterntypeDropDown_2.Value
                case 'Pos-Neg'
                    spc = CWHandling(app.spc,app.CWDropDown.Value,'Pos-Neg');
                    had = PosNeg2Had(spc);
                case 'Shifted Simulation'
                    spc = CWHandling(app.spc,app.CWDropDown.Value,'Pos-Neg'); %for CW handl. Pos simulation implies a pos-neg meas.
                    had = PosNeg2Pos(spc,app.NegEditField.Value,app.MethodDropDown.Value);
                case 'Shifted'
                    K = sqrt(2^floor(log2(size(app.spc,3)-app.NegEditField.Value)));
                    had = Shifted2Had(app.spc(:,:,1:(K^2+app.NegEditField.Value)),K,app.NegEditField.Value,app.MethodDropDown.Value,app.CWDropDown.Value);
                case 'Pos'
                    if mod(log2(sqrt(size(app.spc,3))),1)==0
                       % 1024 measurements, with CW
                       had = spc;
                    elseif mod(log2(sqrt(size(app.spc,3)/2)),1)==0
                       % Original posneg measurement
                       spc = CWHandling(app.spc,app.CWDropDown.Value,'Pos-Neg');
                       had = spc(:,:,1:2:end);
                    else
                       % Original Pos+some negs measurement
                       prompt = {'Detected Pos+someNeg. Enter number of negs:'};
                       dlgtitle = 'Neg:';
                       dims = [1 35];
                       definput = {'40'};
                       nNeg = inputdlg(prompt,dlgtitle,dims,definput);
                       app.NegEditField.Value = str2double(cell2mat(nNeg));
                       K = sqrt(2^floor(log2(size(app.spc,3)-app.NegEditField.Value)));
                       [~,had] = Shifted2Had(app.spc(:,:,1:(K^2+app.NegEditField.Value)),K,app.NegEditField.Value,app.MethodDropDown.Value,app.CWDropDown.Value);
                    end
            end
            else
                had = [];
            end
            app.had_spectrum.YData = squeeze(sum(app.spc,1:2));
            
            if strcmp(app.CompressivesensingSwitch.Value,'On') && strcmp(app.CompressionTypeDropDown.Value,'Algebric Inversion')
                had = PutCofficients2Zero(had,app.RatioEditField.Value);    
            end
            
            app.had = had;
            
            if size(app.loaded,1) == 0 && not(strcmp(app.BasisDropDown.Value,'Raster Scan'))
                try
                    load(['../../Calibrations/FLIM_',app.BasisDropDown.Value,'_',num2str(size(had,3))],'M','Pc','Pr')
                catch
                    [file, path] = uigetfile(['FLIM_',app.BasisDropDown.Value,'_',num2str(size(had,3))]);
                    figure(app.UIFigure)
                    if file == 0
                        return;
                    end
                    load([path file],'M');
                    app.loaded = 1;
                    app.M = M;
                end
            elseif size(app.loaded,1) == 1 && not(strcmp(app.BasisDropDown.Value,'Raster Scan'))
                    M = app.M;
            end
            
            if not(strcmp(app.BasisDropDown.Value,'Raster Scan')) && not(strcmp(app.PatterntypeDropDown_2.Value,'Pos'))
                M = M(1:2:end,:) - M(2:2:end,:);
            elseif strcmp(app.PatterntypeDropDown_2.Value,'Pos')
                M = M(1:2:end,:);
            end
            
            flag_flip = app.FlipCheckBox.Value;
            if strcmp(app.CompressivesensingSwitch.Value,'Off') || (strcmp(app.CompressivesensingSwitch.Value,'On')&&strcmp(app.CompressionTypeDropDown.Value,'Algebric Inversion'))
                switch app.BasisDropDown.Value
                    case 'Walsh-Hadamard'
                        im = WHReconstruction(had,flag_flip);
                    case 'Scrambled-Hadamard'
                        im = SHReconstruction(had,M,flag_flip);
                    case 'Raster Scan'
                        im = reshape(app.spc(:,:,1:app.XEditField_2.Value*app.XEditField.Value),[size(app.spc,1) size(app.spc,2) app.XEditField_2.Value app.XEditField.Value]);
                end
            else
                run('./TVAL/warm_up.m')
                ratio = app.RatioEditField.Value/100;
                K = size(app.had,3);
                m = round((1-ratio)*K);
                [im,opts] = InvertwTVAL3(app.had,m,M,app.camera,sqrt(K),eval(app.TVALmu.Value),eval(app.TVALbeta.Value));
                assignin('base', 'tval_opts', opts);
            end
            app.im = im;
            imagesc(app.UIAxes2,squeeze(sum(app.im,1:2)));
            axis(app.UIAxes2,"image")
            c = colorbar(app.UIAxes2);
            app.ReconstructButton.Value = 0;
            app.ColormapLimEditField.Value = round(app.UIAxes2.CLim(1));
            app.EditField_2.Value = round(app.UIAxes2.CLim(2));
            app.ColormapLimEditField.Visible = 'on';
            app.ColormapLimEditFieldLabel.Visible = 'on';
            app.EditField_2.Visible = 'on';
            app.spc = spc_raw;
            
            if sum(app.camera(:))>0 && (size(app.im,1)>1 || size(app.im,2)>1)
            app.FuseSPCandCameraButton.Enable = 1;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.SaveButton.Enable = 0;
            app.ReconstructButton.Enable = 0;
            app.ReleasefileButton.Enable = 0;
            im = app.im;
            t = app.t(app.t_start:app.t_stop);
            lambda = app.lambda(app.l_start:app.l_stop);
            camera = app.camera;
            had = app.had;
            im_fused = app.im_fused;
            assignin('base', 'lambda', lambda);
            assignin('base', 't', t);
            assignin('base', 'im', im);
            assignin('base', 'camera', camera);
            assignin('base', 'had', had);
            assignin('base', 'im_fused', im_fused);
            if not(strcmp(app.EditField.Value,'Loaded file from workspace.'))
            filename = app.EditField.Value;
            filename = filename(1:end-4);
            else
                filename = [];
            end
            %save([filename,'_tlxy.mat'],"im");
            uisave(["im","t","lambda","camera","had","im_fused"],[filename,'_tlxy.mat'])
            app.SaveButton.Enable = 1;
            app.ReconstructButton.Enable = 1;
            app.ReleasefileButton.Enable = 1;
        end

        % Value changed function: PatterntypeDropDown_2
        function PatterntypeDropDown_2ValueChanged(app, event)
            if strcmp(app.PatterntypeDropDown_2.Value,'Shifted Simulation')
                app.NegEditField.Visible = 'on';
                app.NegEditFieldLabel.Visible = 'on';
                app.MethodDropDown.Visible = 'on';
                app.MethodDropDownLabel.Visible = 'on';
            elseif strcmp(app.PatterntypeDropDown_2.Value,'Shifted')
                app.NegEditField.Visible = 'on';
                app.NegEditFieldLabel.Visible = 'on';
                app.MethodDropDown.Visible = 'on';
                app.MethodDropDownLabel.Visible = 'on';
            else
                app.NegEditField.Visible = 'off';
                app.NegEditFieldLabel.Visible = 'off';
                app.MethodDropDown.Visible = 'off';
                app.MethodDropDownLabel.Visible = 'off';
            end
        end

        % Value changed function: CompressivesensingSwitch
        function CompressivesensingSwitchValueChanged(app, event)
            value = app.CompressivesensingSwitch.Value;
            if strcmp(app.BasisDropDown.Value,'Raster Scan')
                app.CompressivesensingSwitch.Value = 'Off';
                app.CompressionTypeDropDown.Enable = 'off';
                app.RatioEditField.Enable = 'off';
                app.CompressionTypeDropDownLabel.Enable = 'off';
                app.RatioEditFieldLabel.Enable = 'off';
                app.RatioEditField.Value = 0;
                return;
            end
            if strcmp(value,'On')
                app.CompressionTypeDropDown.Enable = 'on';
                app.RatioEditField.Enable = 'on';
                app.CompressionTypeDropDownLabel.Enable = 'on';
                app.RatioEditFieldLabel.Enable = 'on';
            else
                app.CompressionTypeDropDown.Enable = 'off';
                app.RatioEditField.Enable = 'off';
                app.CompressionTypeDropDownLabel.Enable = 'off';
                app.RatioEditFieldLabel.Enable = 'off';
                app.RatioEditField.Value = 0;
            end
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.UIAxes2.CLim(2) = app.EditField_2.Value;
        end

        % Value changed function: ColormapLimEditField
        function ColormapLimEditFieldValueChanged(app, event)
            app.UIAxes2.CLim(1) = app.ColormapLimEditField.Value;
            
        end

        % Callback function
        function ExitButtonPushed(app, event)
            app.delete;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: ReleasefileButton
        function ReleasefileButtonPushed(app, event)
            app.UIFigure.Visible = 'off';
            im = [];
            t = [];
            lambda = [];
            camera = [];
            im_fused = [];
            assignin('base', 'lambda', lambda);
            assignin('base', 't', t);
            assignin('base', 'im', im);
            assignin('base', 'camera', camera);
            assignin('base', 'im_fused', im_fused);
            APP_ImageReconstruction();  
            % Delete old instance
            close(app.UIFigure)
        end

        % Value changed function: BasisDropDown
        function BasisDropDownValueChanged(app, event)
            if strcmp(app.BasisDropDown.Value,'Raster Scan')
                app.CWDropDown.Visible = 0;
                app.PatterntypeDropDown_2.Visible = 0;
                app.XEditField.Visible = 1;
                app.XEditField_2.Visible = 1;
                app.XEditFieldLabel.Visible = 1;
                app.XEditFieldLabel_2.Visible = 1;
                app.CompressivesensingSwitch.Value = 'Off';
                app.CompressionTypeDropDown.Enable = 'off';
                app.RatioEditField.Enable = 'off';
                app.CompressionTypeDropDownLabel.Enable = 'off';
                app.RatioEditFieldLabel.Enable = 'off';
                app.RatioEditField.Value = 0;
                app.BleachingcorrectionButton.Enable = 0;
            else
                app.CWDropDown.Visible = 1;
                app.PatterntypeDropDown_2.Visible = 1;
                app.XEditField.Visible = 0;
                app.XEditField_2.Visible = 0;
                app.XEditFieldLabel.Visible = 0;
                app.XEditFieldLabel_2.Visible = 0;
                app.BleachingcorrectionButton.Enable = 1;
            end
            
        end

        % Button pushed function: FuseSPCandCameraButton
        function FuseSPCandCameraButtonPushed(app, event)
            addpath './data_fusion'
            
            Data = PrepareDataFusion(app.im,app.camera,app.lambda(app.l_start:app.l_stop),app.t(app.t_start:app.t_stop),0.0009);
            
            app.im_fused = data_fusion(Data);
            imagesc(app.UIAxes2,squeeze(sum(app.im_fused,1:2)));
            app.ColormapLimEditField.Value = round(app.UIAxes2.CLim(1));
            app.EditField_2.Value = round(app.UIAxes2.CLim(2));
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            [~,app.t_start] = min(abs(app.t - app.TimeROInsEditField.Value));
            [~,app.t_stop] = min(abs(app.t - app.TimeROI2EditField.Value));
            [~,app.l_start] = min(abs(app.lambda - app.WLROInmEditField.Value));
            [~,app.l_stop] = min(abs(app.lambda - app.WLROIendEditField.Value));
            switch value
                case 'K'
                    app.had_spectrum = plot(app.UIAxes,squeeze(sum(app.spc(app.t_start:app.t_stop,app.l_start:app.l_stop,:),1:2)));
                    xlim(app.UIAxes, [1 size(app.spc,3)])
                    app.UIAxes.XLabel.String = 'Patterns';
                case 'T'
                    app.had_spectrum = plot(app.UIAxes,app.t(app.t_start:app.t_stop),squeeze(sum(app.spc(app.t_start:app.t_stop,app.l_start:app.l_stop,:),2:3)));
                    xlim(app.UIAxes, [app.t(app.t_start) app.t(app.t_stop)])
                    app.UIAxes.XLabel.String = 'Time';
                case 'W'
                    app.had_spectrum = plot(app.UIAxes,app.lambda(app.l_start:app.l_stop),squeeze(sum(app.spc(app.t_start:app.t_stop,app.l_start:app.l_stop,:),[1 3])));
                    xlim(app.UIAxes, [app.lambda(app.l_start) app.lambda(app.l_stop)])
                    app.UIAxes.XLabel.String = 'Wavelength';
            end
        end

        % Value changed function: TimeROInsEditField
        function TimeROInsEditFieldValueChanged(app, event)
            [~,b] = min(abs(app.t - app.TimeROInsEditField.Value));
            app.TimeROInsEditField.Value = app.t(b);
            DropDownValueChanged(app);
        end

        % Value changed function: TimeROI2EditField
        function TimeROI2EditFieldValueChanged(app, event)
            [~,b] = min(abs(app.t - app.TimeROI2EditField.Value));
            app.TimeROI2EditField.Value = app.t(b);
            DropDownValueChanged(app);
        end

        % Value changed function: WLROInmEditField
        function WLROInmEditFieldValueChanged(app, event)
            if app.WLROInmEditField.Value > app.WLROIendEditField.Value
                app.WLROInmEditField.Value = app.WLROIendEditField.Value;
            end
            [~,b] = min(abs(app.lambda - app.WLROInmEditField.Value));
            app.WLROInmEditField.Value = app.lambda(b);
            DropDownValueChanged(app);
        end

        % Value changed function: WLROIendEditField
        function WLROIendEditFieldValueChanged(app, event)
            if app.WLROIendEditField.Value < app.WLROInmEditField.Value
                app.WLROIendEditField.Value = app.WLROInmEditField.Value;
            end
            [~,b] = min(abs(app.lambda - app.WLROIendEditField.Value));
            app.WLROIendEditField.Value = app.lambda(b);
            DropDownValueChanged(app);
        end

        % Value changed function: TemporalbinnsEditField
        function TemporalbinnsEditFieldValueChanged(app, event)
            app.DefaultButton.Enable = 1;
            
            [app.t,app.spc,app.TemporalbinnsEditField.Value] = ...
                BinData(app.spc_nobin,app.t_nobin', app.TemporalbinnsEditField.Value,'Bin');
            app.t = app.t';
            
            DropDownValueChanged(app);
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            app.TemporalbinnsEditField.Value = app.t_nobin(2)-app.t_nobin(1);
            TemporalbinnsEditFieldValueChanged(app);
        end

        % Button pushed function: BleachingcorrectionButton
        function BleachingcorrectionButtonPushed(app, event)
            if strcmp(app.PatterntypeDropDown_2.Value,'Shifted')
                K = sqrt(2^floor(log2(size(app.spc,3)-app.NegEditField.Value)));
                spc = app.spc(:,:,1:(K^2+app.NegEditField.Value));
            else
                %K = sqrt(size(app.spc,3)*(0.5+0.5*(mod(log2(sqrt(size(app.spc,3))),1)==0)));
                spc = app.spc;
            end
            app.spc = BleachingCorrection(spc,app.spc);
            DropDownValueChanged(app);
            app.BleachingcorrectionButton.Enable = 0;
        end

        % Value changed function: CompressionTypeDropDown
        function CompressionTypeDropDownValueChanged(app, event)
            value = app.CompressionTypeDropDown.Value;
            switch value
                case 'TVAL3'
                    app.TVALbeta.Enable = 1;
                    app.TVALbeta.Visible = 1;
                    app.TVALmu.Enable = 1;
                    app.TVALmu.Visible = 1;
                    app.Label_2.Visible = 1;
                    app.Label_2.Enable = 1;
                    app.Label_3.Visible = 1;
                    app.Label_3.Enable = 1;
                case 'Algebric Inversion'
                    app.TVALbeta.Enable = 0;
                    app.TVALbeta.Visible = 0;
                    app.TVALmu.Enable = 0;
                    app.TVALmu.Visible = 0;
                    app.Label_2.Visible = 0;
                    app.Label_2.Enable = 0;
                    app.Label_3.Visible = 0;
                    app.Label_3.Enable = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel_2 = uipanel(app.UIFigure);
            app.FlipCheckBox = uicheckbox(app.Panel_2);
            app.FlipCheckBox.Text = 'Flip';
            app.BleachingcorrectionButton = uibutton(app.Panel_2, 'push');
            app.BleachingcorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @BleachingcorrectionButtonPushed, true);
            app.BleachingcorrectionButton.Text = 'Bleaching correction';
            app.Panel = uipanel(app.UIFigure);
            app.NegEditFieldLabel = uilabel(app.Panel);
            app.NegEditFieldLabel.Visible = 'off';
            app.NegEditFieldLabel.Text = '#Neg';
            app.NegEditField = uieditfield(app.Panel, 'numeric');
            app.NegEditField.Visible = 'off';
            app.NegEditField.Value = 40;
            app.MethodDropDownLabel = uilabel(app.Panel);
            app.MethodDropDownLabel.Visible = 'off';
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.Panel);
            app.MethodDropDown.Items = {'Mean', 'Distributed'};
            app.MethodDropDown.Visible = 'off';
            app.MethodDropDown.Value = 'Mean';
            app.PatterntypeDropDown_2Label_2 = uilabel(app.Panel);
            app.PatterntypeDropDown_2Label_2.Text = 'CW type';
            app.CWDropDown = uidropdown(app.Panel);
            app.CWDropDown.Items = {'Measured', 'Not measured', 'Measured, but exclude'};
            app.CWDropDown.Value = 'Not measured';
            app.XEditFieldLabel = uilabel(app.Panel);
            app.XEditFieldLabel.Visible = 'off';
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.Panel, 'numeric');
            app.XEditField.Visible = 'off';
            app.XEditField.Value = 32;
            app.XEditFieldLabel_2 = uilabel(app.Panel);
            app.XEditFieldLabel_2.Visible = 'off';
            app.XEditFieldLabel_2.Text = 'Y';
            app.XEditField_2 = uieditfield(app.Panel, 'numeric');
            app.XEditField_2.Visible = 'off';
            app.XEditField_2.Value = 32;
            app.TVALmu = uieditfield(app.Panel, 'text');
            app.TVALmu.Enable = 'off';
            app.TVALmu.Visible = 'off';
            app.TVALmu.Value = '2^8';
            app.TVALbeta = uieditfield(app.Panel, 'text');
            app.TVALbeta.Enable = 'off';
            app.TVALbeta.Visible = 'off';
            app.TVALbeta.Value = '2^5';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Enable = 'off';
            app.Label_2.Visible = 'off';
            app.Label_2.Text = 'β';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Enable = 'off';
            app.Label_3.Visible = 'off';
            app.Label_3.Text = 'μ';
            app.BasisDropDownLabel = uilabel(app.UIFigure);
            app.BasisDropDownLabel.Text = 'Basis';
            app.BasisDropDown = uidropdown(app.UIFigure);
            app.BasisDropDown.Items = {'Walsh-Hadamard', 'Scrambled-Hadamard', 'Raster Scan'};
            app.BasisDropDown.ValueChangedFcn = createCallbackFcn(app, @BasisDropDownValueChanged, true);
            app.BasisDropDown.Value = 'Scrambled-Hadamard';
            app.LoadfileButton = uibutton(app.UIFigure, 'state');
            app.LoadfileButton.ValueChangedFcn = createCallbackFcn(app, @LoadfileButtonValueChanged, true);
            app.LoadfileButton.Text = 'Load file';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'file.mat';
            app.PatterntypeDropDown_2Label = uilabel(app.UIFigure);
            app.PatterntypeDropDown_2Label.Text = 'Pattern type';
            app.PatterntypeDropDown_2 = uidropdown(app.UIFigure);
            app.PatterntypeDropDown_2.Items = {'Pos-Neg', 'Shifted Simulation', 'Shifted', 'Pos'};
            app.PatterntypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @PatterntypeDropDown_2ValueChanged, true);
            app.PatterntypeDropDown_2.Value = 'Pos-Neg';
            app.CompressivesensingSwitchLabel = uilabel(app.UIFigure);
            app.CompressivesensingSwitchLabel.Text = 'Compressive sensing';
            app.CompressivesensingSwitch = uiswitch(app.UIFigure, 'slider');
            app.CompressivesensingSwitch.ValueChangedFcn = createCallbackFcn(app, @CompressivesensingSwitchValueChanged, true);
            app.RatioEditFieldLabel = uilabel(app.UIFigure);
            app.RatioEditFieldLabel.Enable = 'off';
            app.RatioEditFieldLabel.Text = 'Ratio [%]';
            app.RatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.RatioEditField.Enable = 'off';
            app.CompressionTypeDropDownLabel = uilabel(app.UIFigure);
            app.CompressionTypeDropDownLabel.Enable = 'off';
            app.CompressionTypeDropDownLabel.Text = 'Compression Type';
            app.CompressionTypeDropDown = uidropdown(app.UIFigure);
            app.CompressionTypeDropDown.Items = {'Algebric Inversion', 'TVAL3'};
            app.CompressionTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @CompressionTypeDropDownValueChanged, true);
            app.CompressionTypeDropDown.Enable = 'off';
            app.CompressionTypeDropDown.Value = 'Algebric Inversion';
            app.ReconstructButton = uibutton(app.UIFigure, 'state');
            app.ReconstructButton.ValueChangedFcn = createCallbackFcn(app, @ReconstructButtonValueChanged, true);
            app.ReconstructButton.Text = 'Reconstruct';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ColormapLimEditFieldLabel = uilabel(app.UIFigure);
            app.ColormapLimEditFieldLabel.Visible = 'off';
            app.ColormapLimEditFieldLabel.Text = 'ColormapLim';
            app.ColormapLimEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColormapLimEditField.ValueChangedFcn = createCallbackFcn(app, @ColormapLimEditFieldValueChanged, true);
            app.ColormapLimEditField.Visible = 'off';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Visible = 'off';
            app.IntegralovertimeCheckBox = uicheckbox(app.UIFigure);
            app.IntegralovertimeCheckBox.Text = 'Integral over time';
            app.IntegraloverwavelengthCheckBox = uicheckbox(app.UIFigure);
            app.IntegraloverwavelengthCheckBox.Text = 'Integral over wavelength';
            app.ReleasefileButton = uibutton(app.UIFigure, 'push');
            app.ReleasefileButton.ButtonPushedFcn = createCallbackFcn(app, @ReleasefileButtonPushed, true);
            app.ReleasefileButton.Text = 'Release file';
            app.FuseSPCandCameraButton = uibutton(app.UIFigure, 'push');
            app.FuseSPCandCameraButton.ButtonPushedFcn = createCallbackFcn(app, @FuseSPCandCameraButtonPushed, true);
            app.FuseSPCandCameraButton.Enable = 'off';
            app.FuseSPCandCameraButton.Text = 'Fuse SPC and Camera';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'K', 'W', 'T'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'K';
            app.TimeROInsEditFieldLabel = uilabel(app.UIFigure);
            app.TimeROInsEditFieldLabel.Text = 'Time ROI [ns]';
            app.TimeROInsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeROInsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeROInsEditFieldValueChanged, true);
            app.TimeROI2EditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeROI2EditField.ValueChangedFcn = createCallbackFcn(app, @TimeROI2EditFieldValueChanged, true);
            app.WLROInmEditFieldLabel = uilabel(app.UIFigure);
            app.WLROInmEditFieldLabel.Text = 'WL ROI [nm]';
            app.WLROInmEditField = uieditfield(app.UIFigure, 'numeric');
            app.WLROInmEditField.ValueChangedFcn = createCallbackFcn(app, @WLROInmEditFieldValueChanged, true);
            app.WLROIendEditField = uieditfield(app.UIFigure, 'numeric');
            app.WLROIendEditField.ValueChangedFcn = createCallbackFcn(app, @WLROIendEditFieldValueChanged, true);
            app.TemporalbinnsEditFieldLabel = uilabel(app.UIFigure);
            app.TemporalbinnsEditFieldLabel.Text = 'Temporal bin [ns]';
            app.TemporalbinnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemporalbinnsEditField.ValueChangedFcn = createCallbackFcn(app, @TemporalbinnsEditFieldValueChanged, true);
            app.DefaultButton = uibutton(app.UIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Enable = 'off';
            app.DefaultButton.Text = 'Default';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Acquired data')
            xlabel(app.UIAxes, 'Patterns')
            ylabel(app.UIAxes, 'Counts [a.u.]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_ImageReconstruction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end