classdef APP_PreProc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Switch                       matlab.ui.control.Switch
        CameraImageLamp              matlab.ui.control.Lamp
        ImfeelingluckyButton         matlab.ui.control.Button
        ReleasefileButton            matlab.ui.control.Button
        LoadcamerafileButton         matlab.ui.control.Button
        Panel_5                      matlab.ui.container.Panel
        SubtractButton               matlab.ui.control.Button
        EditField_2                  matlab.ui.control.NumericEditField
        BkgROIEditField              matlab.ui.control.NumericEditField
        BkgROIEditFieldLabel         matlab.ui.control.Label
        SubtractBackgroundDropDown   matlab.ui.control.DropDown
        SubtractBackgroundDropDownLabel  matlab.ui.control.Label
        ImageReconstructionButton    matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        TemporalROInsStopEditField   matlab.ui.control.NumericEditField
        TemporalROInsEditField       matlab.ui.control.NumericEditField
        TemporalROInsEditFieldLabel  matlab.ui.control.Label
        TemporalbinnsEditField       matlab.ui.control.NumericEditField
        TemporalbinnsEditFieldLabel  matlab.ui.control.Label
        DetectorDropDown             matlab.ui.control.DropDown
        DetectorDropDownLabel        matlab.ui.control.Label
        LoaddataButton               matlab.ui.control.Button
        EditField                    matlab.ui.control.EditField
        Panel                        matlab.ui.container.Panel
        Panel_3                      matlab.ui.container.Panel
        Panel_4                      matlab.ui.container.Panel
        DefaultButton                matlab.ui.control.Button
        Panel_2                      matlab.ui.container.Panel
        TOffsetLamp                  matlab.ui.control.Lamp
        ToffsetButton                matlab.ui.control.Button
        CalibrationLamp              matlab.ui.control.Lamp
        EfficiencyLamp               matlab.ui.control.Lamp
        CalibrationButton            matlab.ui.control.Button
        EfficiencyButton             matlab.ui.control.Button
        ApplycalibrationsButton      matlab.ui.control.Button
        DropDown                     matlab.ui.control.DropDown
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        lambda %wavelength
        t
        spc    % 3d dataset: time, lambda, pattern
        t_nobin % auxiliary variable
        LTMap  % time wavelength map
        decay
        TLMapTROIstart
        DecayTROIstart
        TLMapTROIstop
        DecayTROIstop
        spc_nobin % auxiliary variable
        efficiency
        temporal_offset
        eff_applied
        toff_applied
        camera
    end
    
    methods (Access = private)
        %CallingApp;
        
        
        function RefreshPlot(app)
            
            if size(app.lambda,2) == 0 || size(app.t,2) == 0
                return
            end
            
            
            hold(app.UIAxes,'off');
            [~,b] = max(squeeze(sum(app.spc,1:2)));
            if size(app.spc,2) > 1
                hold(app.UIAxes_2,'off');
                app.LTMap.CData = squeeze(app.spc(:,:,b));
                app.LTMap.XData = app.lambda;
                app.LTMap.YData = app.t;
                xlim(app.UIAxes,[app.lambda(1) app.lambda(end)])
                ylim(app.UIAxes,[app.t(1) app.t(end)])
                app.decay.YData = squeeze(sum(app.spc(:,:,b),2));
                app.UIAxes_2.YLim = [0 1.2*max(squeeze(sum(app.spc(:,:,b),2)))];
                if strcmp(app.Switch.Value,'chs')
                 app.decay.XData = 1:length(app.decay.YData);
                 xlabel(app.UIAxes_2,'Channel')
                else
                 app.decay.XData = app.t;
                 xlabel(app.UIAxes_2,'Time [ns]')
                end
                ylabel(app.UIAxes_2,'Counts [a.u.]')
                
            else
                app.decay.YData = squeeze(app.spc(:,1,b));
                if strcmp(app.Switch.Value,'chs')
                 app.decay.XData = 1:length(app.decay.YData);
                 xlabel(app.UIAxes_2,'Channel')
                else
                 app.decay.XData = app.t;
                 xlabel(app.UIAxes_2,'Time [ns]')
                end
                
                app.UIAxes.YLim = [0 1.2*max(squeeze(sum(app.spc(:,:,b),2)))];
                ylabel(app.UIAxes,'Counts [a.u.]')
                
                app.UIAxes_2.Visible = 'off';
                app.UIAxes.XMinorGrid = 'on';
                app.UIAxes.YMinorGrid = 'on';
            end
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'λTR Compressive SPC Data Pre Processing';
        end

        % Callback function
        function ExitButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            addpath './common/'
            app.LoaddataButton.Enable = 'off';
            app.ApplycalibrationsButton.Enable = 'off';
            app.SubtractButton.Enable = 'off';
            
            app.DefaultButton.Enable = 'off';
            app.SaveButton.Enable = 'off';
            if strcmp(app.EditField.Value,'')==1
            [file, path] = uigetfile('*.mat');
            figure(app.UIFigure)
            if file == 0
                return;
            end
            app.EditField.Value = [path file];
            a = load([path file]);
            else
               a = load(app.EditField.Value); 
            end
            
            camera = zeros(256,256);
            assignin('base','camera',camera);
            assignin('base', 'CCDThreshold',0);
            try
                app.spc = a.spc;
            catch
                app.LoaddataButton.Enable = 'on';
                warndlg('File not compatible.','Warning');
                return
            end
            
            app.lambda = 1:size(app.spc,2);
            app.t = TimeVectorTCSPC(size(app.spc,1));
            app.TemporalbinnsEditField.Value = app.t(2)-app.t(1);
            app.TemporalROInsEditField.Value = app.t(1);
            app.TemporalROInsStopEditField.Value = app.t(end);
            [~,b] = max(squeeze(sum(app.spc,1:2)));
            
            if size(app.spc,2) > 1
                hold(app.UIAxes_2,'off');
                app.LTMap = imagesc(app.UIAxes,squeeze(app.spc(:,:,b)));
                app.decay = plot(app.UIAxes_2,app.t,squeeze(sum(app.spc(:,:,b),2)));
                ylabel(app.UIAxes_2,'Counts [a.u.]')
                xlabel(app.UIAxes_2,'Time [ns]')
                xlim(app.UIAxes,[1 size(app.spc,2)])
                %ylim(app.UIAxes,[t(1) t(end)])
            else
                app.decay = plot(app.UIAxes,app.t,squeeze(app.spc(:,1,b)));
                ylabel(app.UIAxes,'Counts [a.u.]')
                xlabel(app.UIAxes,'Time [ns]')
                app.UIAxes_2.Visible = 'off';
                app.UIAxes.XMinorGrid = 'on';
                app.UIAxes.YMinorGrid = 'on';
            end
            if size(app.spc,2) > 1
                xlabel(app.UIAxes_2,'ADC level')
            else
                xlabel(app.UIAxes,'ADC level')
            end
            
            
            app.ApplycalibrationsButton.Enable = 'on';
            app.SubtractButton.Enable = 'on';
            
            app.DefaultButton.Enable = 'on';
            app.SaveButton.Enable = 'on';
            app.LoadcamerafileButton.Enable = 'on';
        end

        % Button pushed function: ApplycalibrationsButton
        function ApplycalibrationsButtonPushed(app, event)
            app.LoaddataButton.Enable = 'off';
            app.SubtractButton.Enable = 'off';
            app.DefaultButton.Enable = 'off';
            app.SaveButton.Enable = 'off';
            
            pause(0.1);
            if size(app.efficiency,2)==0
                warndlg('Efficiency not loaded, considered as 1.','Warning');
                app.efficiency = ones(1,size(app.spc,2))';
            end
            if size(app.lambda,2)==0
                warndlg('Calibration not loaded, considered as channels.','Warning');
                app.lambda = 1:size(app.spc,2);
            end
            if size(app.temporal_offset,2)==0 && strcmp(app.DetectorDropDown.Value,'L16')
                warndlg('Temporal offset not loaded, considered as zero.','Warning');
                app.temporal_offset = zeros(size(app.spc,2));
            end
            
            if size(app.spc,2) == 32 && strcmp(app.DetectorDropDown.Value,'Manta')
                app.spc = app.spc(:,5:22,:);
                app.efficiency = app.efficiency(1:size(app.spc,2))';
                app.CalibrationButton.Enable = 0;
                app.ToffsetButton.Enable = 0;
            end
            
            if size(app.temporal_offset,2)==0 && strcmp(app.DetectorDropDown.Value,'Manta')
                warndlg('Temporal offset not loaded, considered as zero.','Warning');
            elseif strcmp(app.DetectorDropDown.Value,'Manta') && isempty(app.toff_applied)
                for ki = 1:size(app.spc,3)
                        for li = 1:size(app.spc,2)
                            app.spc(:,li,ki) = circshift(app.spc(:,li,ki),app.temporal_offset(li));
                        end
                end
                app.toff_applied = 2;
            end


            if isempty(app.eff_applied) && not(sum(app.efficiency)==16)
                app.spc = app.spc.*app.efficiency';
                if strcmp(app.DetectorDropDown.Value,'L16')
                    for ki = 1:size(app.spc,3)
                        for li = 1:size(app.spc,2)
                            app.spc(:,li,ki) = circshift(app.spc(:,li,ki),app.temporal_offset(li));
                        end
                    end
                end
                app.eff_applied = 2;
                app.EfficiencyButton.Enable = 0;
                app.ToffsetButton.Enable = 0;
            end
            
            RefreshPlot(app)
            app.ApplycalibrationsButton.Enable = 'off';
            app.TemporalROInsEditField.Value = app.t(1);
            app.TemporalROInsStopEditField.Value = app.t(end);
            
            
            app.ApplycalibrationsButton.Enable = 'off';
            app.SubtractButton.Enable = 'on';
            
            app.DefaultButton.Enable = 'on';
            app.SaveButton.Enable = 'on';
            
        end

        % Value changed function: SubtractBackgroundDropDown
        function SubtractBackgroundDropDownValueChanged(app, event)
            
            value = app.SubtractBackgroundDropDown.Value;
            if strcmp(value,'Select ROI')
                app.BkgROIEditField.Visible = 'on';
                app.BkgROIEditFieldLabel.Visible = 'on';
                app.EditField_2.Visible = 'on';
            elseif strcmp(value,'Use Dark Meas')
                app.BkgROIEditField.Visible = 'off';
                app.BkgROIEditFieldLabel.Visible = 'off';
                app.EditField_2.Visible = 'off';
            end
        end

        % Button pushed function: SubtractButton
        function SubtractButtonPushed(app, event)
            app.LoaddataButton.Enable = 'off';
            app.ApplycalibrationsButton.Enable = 'off';
            app.SubtractButton.Enable = 'off';
            
            app.DefaultButton.Enable = 'off';
            app.SaveButton.Enable = 'off';
            pause(0.1);
            
            if strcmp(app.SubtractBackgroundDropDown.Value,'Select ROI')
                app.spc = app.spc-mean(app.spc(app.BkgROIEditField.Value:app.EditField_2.Value,:,:),1);
            elseif strcmp(app.SubtractBackgroundDropDown.Value,'Use Dark Meas')
                bkg = (app.spc(:,:,1)+min(app.spc(:,:,2:end),[],3))./2;
                app.spc = app.spc - bkg;
            end
            app.spc = app.spc(:,:,2:end);
            app.SubtractButton.Enable = 'off';
            
            app.TemporalROInsEditField.Value = app.t(1);
            app.TemporalROInsStopEditField.Value = app.t(end);
            
            RefreshPlot(app)
            
            
            app.ApplycalibrationsButton.Enable = 'on';
            %app.SubtractButton.Enable = 'on';
            
            app.DefaultButton.Enable = 'on';
            app.SaveButton.Enable = 'on';
            
            app.spc_nobin = app.spc;
            app.t_nobin = app.t;
        end

        % Value changed function: TemporalbinnsEditField
        function TemporalbinnsEditFieldValueChanged(app, event)
            if size(app.spc_nobin,1) == 0
                warndlg('Subtract Background before.','Warning')
                return;
            end
            [app.t,app.spc,app.TemporalbinnsEditField.Value] = ...
                BinData(app.spc_nobin,app.t_nobin, app.TemporalbinnsEditField.Value);
            
            RefreshPlot(app)
            TemporalROInsEditFieldValueChanged(app);
            TemporalROInsStopEditFieldValueChanged(app);
        end

        % Value changed function: TemporalROInsEditField
        function TemporalROInsEditFieldValueChanged(app, event)
            
            [~,t0] = min(abs(app.t-app.TemporalROInsEditField.Value));
            if size(app.spc,2) > 1
                hold(app.UIAxes,'on')
                hold(app.UIAxes_2,'on')
                L = app.UIAxes_2.YLim;
                if size(app.TLMapTROIstart,1)>0
                    app.TLMapTROIstart.YData = [app.t(t0) app.t(t0)];
                    app.DecayTROIstart.YData = [0 L(end)];
                    app.DecayTROIstart.XData = [app.t(t0) app.t(t0)];
                else
                    app.TLMapTROIstart = line(app.UIAxes,[app.lambda(1),app.lambda(end)], [app.t(t0) app.t(t0)], 'Color', 'k');
                    app.DecayTROIstart = line(app.UIAxes_2,[app.t(t0) app.t(t0)],[0 L(end)], 'Color', 'k');
                end
            else
                hold(app.UIAxes,'on')
                L = app.UIAxes.YLim;
                if size(app.DecayTROIstart,1)>0
                    app.DecayTROIstart.XData = [app.t(t0) app.t(t0)];
                    app.DecayTROIstart.YData = [0 L(end)];
                else
                    app.DecayTROIstart = line(app.UIAxes,[app.t(t0) app.t(t0)],[0 L(end)], 'Color', 'k');
                end
            end
            drawnow;
        end

        % Value changed function: TemporalROInsStopEditField
        function TemporalROInsStopEditFieldValueChanged(app, event)
            [~,t0] = min(abs(app.t-app.TemporalROInsStopEditField.Value));
            
            if size(app.spc,2) > 1
                hold(app.UIAxes,'on')
                hold(app.UIAxes_2,'on')
                L = app.UIAxes_2.YLim;
                if size(app.TLMapTROIstop,1)>0
                    app.TLMapTROIstop.YData = [app.t(t0) app.t(t0)];
                    app.DecayTROIstop.XData = [app.t(t0) app.t(t0)];
                    app.DecayTROIstop.YData = [0 L(end)];
                else
                    app.TLMapTROIstop = line(app.UIAxes,[app.lambda(1),app.lambda(end)], [app.t(t0) app.t(t0)], 'Color', 'k');
                    app.DecayTROIstop = line(app.UIAxes_2,[app.t(t0) app.t(t0)],[0 L(end)], 'Color', 'k');
                end
            else
                hold(app.UIAxes,'on')
                L = app.UIAxes.YLim;
                if size(app.DecayTROIstop,1)>0
                    app.DecayTROIstop.XData = [app.t(t0) app.t(t0)];
                    app.DecayTROIstop.YData = [0 L(end)];
                else
                    app.DecayTROIstop = line(app.UIAxes,[app.t(t0) app.t(t0)],[0 L(end)], 'Color', 'k');
                end
            end
            drawnow;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            filename = app.EditField.Value;
            filename = filename(1:end-4);
            
            [~,t1] = min(abs(app.t-app.TemporalROInsStopEditField.Value));
            [~,t0] = min(abs(app.t-app.TemporalROInsEditField.Value));
            spc = app.spc(t0:t1,:,:);
            t = app.t(t0:t1)-app.t(t0)+(app.t(2)-app.t(1));
            assignin('base', 't', t);
            assignin('base', 'spc', spc);
            camera = evalin('base','camera');
            lambda = app.lambda;
            assignin('base', 'lambda', lambda);
            CCDThreshold = evalin('base','CCDThreshold');
            uisave(["spc","t","lambda","camera","CCDThreshold"],[filename,'_proc.mat'])

            app.ImageReconstructionButton.Enable = 'on';
        end

        % Button pushed function: ImageReconstructionButton
        function ImageReconstructionButtonPushed(app, event)
            %addpath '.\'
            APP_ImageReconstruction;
        end

        % Value changed function: DetectorDropDown
        function DetectorDropDownValueChanged(app, event)
            switch app.DetectorDropDown.Value
                case 'L16'
                    app.DropDown.Visible = 'off';
                    app.EfficiencyButton.Visible = 'on';
                    app.EfficiencyButton.Enable = 'on';
                    app.EfficiencyLamp.Visible = 'on';
                    app.CalibrationButton.Visible = 'on';
                    app.CalibrationLamp.Visible = 'on';
                    app.ToffsetButton.Visible = 'on';
                    app.TOffsetLamp.Visible = 'on';
                    app.ApplycalibrationsButton.Visible = 'on';
                    
                case 'Manta'
                    app.EfficiencyButton.Visible = 'on';
                    app.EfficiencyButton.Enable = 'on';
                    app.EfficiencyLamp.Visible = 'on';
                    app.CalibrationButton.Visible = 'on';
                    app.CalibrationLamp.Visible = 'on';
                    app.ToffsetButton.Visible = 'on';
                    app.TOffsetLamp.Visible = 'on';
                    app.ApplycalibrationsButton.Visible = 'on';
                    %app.EfficiencyButton.Enable = 0;
                    app.BkgROIEditField.Value = 1060;
                    app.EditField_2.Value = 1365;
                case 'Hybrid PMT'
                    app.EfficiencyButton.Visible = 'off';
                    app.EfficiencyLamp.Visible = 'off';
                    app.CalibrationButton.Visible = 'off';
                    app.CalibrationLamp.Visible = 'off';
                    app.ToffsetButton.Visible = 'off';
                    app.TOffsetLamp.Visible = 'off';
                    app.ApplycalibrationsButton.Visible = 'off';
            end
            
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            app.TemporalbinnsEditField.Value = app.t_nobin(2)-app.t_nobin(1);
            TemporalbinnsEditFieldValueChanged(app);
            %             [~,t0] = min(abs(app.t-app.TemporalROInsEditField.Value));
            %             [~,t1] = min(abs(app.t-app.TemporalROInsStopEditField.Value));
            %             app.spc = app.spc(t0:t1,:,:);
            %             app.t = app.t_test(t0:t1);
            %             app.DefaultButton.Enable = 'off';
        end

        % Button pushed function: LoadcamerafileButton
        function LoadcamerafileButtonPushed(app, event)
            %addpath pwd
            APP_CCDCalib;
            app.CameraImageLamp.Enable = 1;
        end

        % Button pushed function: EfficiencyButton
        function EfficiencyButtonPushed(app, event)
            switch app.DetectorDropDown.Value
                case 'L16'
                    
                    [file, path] = uigetfile(['Efficiency_L16_lambda.mat']);
                    figure(app.UIFigure)
                    if file == 0
                        return;
                    end
                    load([path file]);
                    
                    app.EfficiencyLamp.Enable = 1;
                    app.efficiency = efficiency_L16;
                    
                case 'Manta'
                    
                    [file, path] = uigetfile('S11GA09_DAE6.csv');
                    figure(app.UIFigure)
                    if file == 0
                        return;
                    end
                    efficiency_data = readmatrix([path file]);
                    
                    lambda_eff = efficiency_data(1,2:end);
                    spad_num = efficiency_data(2:end,1);
                    efficiency_data = efficiency_data(2:end, 2:end);
                    
                    app.lambda = [450 475 480 490 app.lambda];
                    
                    efficiency = ones(length(spad_num),length(app.lambda));
                    for li = 1:length(spad_num)
                        efficiency(li,:) = interp1(lambda_eff,efficiency_data(li,:),app.lambda);
                    end
                    efficiency = efficiency./mean(efficiency(:));
                    good_chs = [5 22];
                    app.lambda = app.lambda(good_chs(1):end);
                    efficiency = efficiency(good_chs(1):end,good_chs(1):good_chs(2));
                    app.efficiency = 1./diag(efficiency(1:size(efficiency,2),1:size(efficiency,2)).*eye(size(efficiency,2)))';
                    app.EfficiencyLamp.Enable = 1;
            end
            app.ApplycalibrationsButton.Enable = 1;
        end

        % Button pushed function: CalibrationButton
        function CalibrationButtonPushed(app, event)
            switch app.DetectorDropDown.Value
                case 'L16'
                    [file, path] = uigetfile(['lambda_Lambda_L16.mat']);
                    figure(app.UIFigure)
                    if file == 0
                        return;
                    end
                    load([path file]);
                    
                    app.lambda = lambda;
                    app.CalibrationLamp.Enable = 1;
                case 'Manta'
                    [file, path] = uigetfile(['calib_Manta_',num2str(app.DropDown.Value),'.mat']);
                    figure(app.UIFigure)
                    if file == 0
                        return;
                    end
                    load([path file]);
                    
                    app.lambda = lambda;
                    app.CalibrationLamp.Enable = 1;
                    app.EfficiencyButton.Enable = 1;
            end
            app.ApplycalibrationsButton.Enable = 1;
        end

        % Button pushed function: ToffsetButton
        function ToffsetButtonPushed(app, event)
            if strcmp(app.DetectorDropDown.Value,'Manta')
            [file, path] = uigetfile('manta_temporal_offsets.mat');
            figure(app.UIFigure)
            if file == 0
                return;
            end
            a = load([path file]);
            app.TOffsetLamp.Enable = 1;
            %good_chs = [5 22];
            %OS = a.spc;
            %[~,pos_max] = max(OS(:,:,2),[],1);
            %for li = good_chs(1):good_chs(2)
            %    curves(li,:) = polyval(polyfit([(pos_max-30):(pos_max+30)],OS((pos_max(li)-30):(pos_max(li)+30),li,2),2),[(pos_max-30):(pos_max+30)]);
            %end
            %[~,pos_max_fit] = max(curves,[],2);
            %temporal_offsets = [pos_max_fit' 0 0 0 0 0 0 0 0 0 0] + pos_max - 30;
            %temporal_offsets = round(temporal_offsets - mean(temporal_offsets));
            
            %app.temporal_offset = temporal_offsets(good_chs(1):good_chs(2));
            app.temporal_offset = a.temporal_offset;
            else
                [file, path] = uigetfile(['L16_temporal_offsets.mat']);
                figure(app.UIFigure)
                if file == 0
                    return;
                end
                a = load([path file]);
                app.TOffsetLamp.Enable = 1;
                app.temporal_offset = a.time_delay_shift;
            end
            app.ApplycalibrationsButton.Enable = 1;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: ReleasefileButton
        function ReleasefileButtonPushed(app, event)
            app.UIFigure.Visible = 'off';
            camera = [];
            lambda = [];
            spc = [];
            t = [];
            assignin('base', 'camera', camera);
            assignin('base', 'lambda', lambda);
            assignin('base', 'spc', spc);
            assignin('base', 't', t);
            APP_PreProc();  
            % Delete old instance
            close(app.UIFigure)
        end

        % Button pushed function: ImfeelingluckyButton
        function ImfeelingluckyButtonPushed(app, event)
            if strcmp(app.DetectorDropDown.Value,'L16')
            [a] = find(app.EditField.Value == '_');
            wl = app.EditField.Value((a(4)+1):(a(5)-1));
            load(['../../Calibrations/Efficiency_L16_',wl,'.mat'])
            app.EfficiencyLamp.Enable = 1;
            app.efficiency = efficiency_L16; 
            app.ApplycalibrationsButton.Enable = 1;
            load(['../../Calibrations/',wl,'_Lambda_L16.mat'])
            app.lambda = lambda;
            app.CalibrationLamp.Enable = 1;
            a = load(['../../Calibrations/L16_temporal_offsets_20220520.mat']);
            app.TOffsetLamp.Enable = 1;
            app.temporal_offset = a.time_delay_shift;
            app.ApplycalibrationsButton.Enable = 1;
            app.ApplycalibrationsButtonPushed;
            
            %app.SubtractBackgroundDropDown.Value = 'Use Dark Meas';
            %app.BkgROIEditField.Visible = 'off';
            %app.BkgROIEditFieldLabel.Visible = 'off';
            %app.EditField_2.Visible = 'off';
            app.SubtractButtonPushed;
            
            %app.TemporalbinnsEditField.Value = 0.06;
            %app.TemporalbinnsEditFieldValueChanged;
            
            app.ImfeelingluckyButton.Enable = 0;
            
            filename = app.EditField.Value;
            filename = filename(1:end-4);
            
            [~,t1] = min(abs(app.t-app.TemporalROInsStopEditField.Value));
            [~,t0] = min(abs(app.t-app.TemporalROInsEditField.Value));
            spc = app.spc(t0:t1,:,1:21);
            t = app.t(t0:t1)-app.t(t0)+(app.t(2)-app.t(1));
            assignin('base', 't', t);
            assignin('base', 'spc', spc);
            camera = evalin('base','camera');
            
            CCDThreshold = evalin('base','CCDThreshold');
            lambda = app.lambda;
            assignin('base', 'lambda', lambda);
            save([filename,'_proc.mat'],"spc","t","lambda","camera","CCDThreshold")

            app.ImageReconstructionButton.Enable = 'on';
            
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            RefreshPlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Acquired signal')
            xlabel(app.UIAxes, 'Spectral channel')
            ylabel(app.UIAxes, 'Time [ns]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'TR Signal')
            xlabel(app.UIAxes_2, 'Time [ns]')
            ylabel(app.UIAxes_2, 'Counts [a.u.]')
            zlabel(app.UIAxes_2, 'Z')
            app.Panel_2 = uipanel(app.UIFigure);
            app.DropDown = uidropdown(app.Panel_2);
            app.DropDown.Items = {'550'};
            app.DropDown.Visible = 'off';
            app.DropDown.Value = '550';
            app.ApplycalibrationsButton = uibutton(app.Panel_2, 'push');
            app.ApplycalibrationsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplycalibrationsButtonPushed, true);
            app.ApplycalibrationsButton.Enable = 'off';
            app.ApplycalibrationsButton.Text = 'Apply calibrations';
            app.EfficiencyButton = uibutton(app.Panel_2, 'push');
            app.EfficiencyButton.ButtonPushedFcn = createCallbackFcn(app, @EfficiencyButtonPushed, true);
            app.EfficiencyButton.Text = 'Efficiency';
            app.CalibrationButton = uibutton(app.Panel_2, 'push');
            app.CalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrationButtonPushed, true);
            app.CalibrationButton.Text = 'Calibration';
            app.EfficiencyLamp = uilamp(app.Panel_2);
            app.EfficiencyLamp.Enable = 'off';
            app.CalibrationLamp = uilamp(app.Panel_2);
            app.CalibrationLamp.Enable = 'off';
            app.ToffsetButton = uibutton(app.Panel_2, 'push');
            app.ToffsetButton.ButtonPushedFcn = createCallbackFcn(app, @ToffsetButtonPushed, true);
            app.ToffsetButton.Text = 'T offset';
            app.TOffsetLamp = uilamp(app.Panel_2);
            app.TOffsetLamp.Enable = 'off';
            app.Panel_4 = uipanel(app.UIFigure);
            app.DefaultButton = uibutton(app.Panel_4, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Enable = 'off';
            app.DefaultButton.Text = 'Default';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel = uipanel(app.UIFigure);
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.LoaddataButton = uibutton(app.UIFigure, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.DetectorDropDownLabel = uilabel(app.UIFigure);
            app.DetectorDropDownLabel.Text = 'Detector';
            app.DetectorDropDown = uidropdown(app.UIFigure);
            app.DetectorDropDown.Items = {'L16', 'Manta', 'Hybrid PMT'};
            app.DetectorDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectorDropDownValueChanged, true);
            app.DetectorDropDown.Value = 'L16';
            app.TemporalbinnsEditFieldLabel = uilabel(app.UIFigure);
            app.TemporalbinnsEditFieldLabel.Text = 'Temporal bin [ns]';
            app.TemporalbinnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemporalbinnsEditField.ValueChangedFcn = createCallbackFcn(app, @TemporalbinnsEditFieldValueChanged, true);
            app.TemporalROInsEditFieldLabel = uilabel(app.UIFigure);
            app.TemporalROInsEditFieldLabel.Text = 'Temporal ROI [ns]';
            app.TemporalROInsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemporalROInsEditField.ValueChangedFcn = createCallbackFcn(app, @TemporalROInsEditFieldValueChanged, true);
            app.TemporalROInsStopEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemporalROInsStopEditField.ValueChangedFcn = createCallbackFcn(app, @TemporalROInsStopEditFieldValueChanged, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.ImageReconstructionButton = uibutton(app.UIFigure, 'push');
            app.ImageReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @ImageReconstructionButtonPushed, true);
            app.ImageReconstructionButton.Enable = 'off';
            app.ImageReconstructionButton.Text = 'Image Reconstruction';
            app.SubtractBackgroundDropDownLabel = uilabel(app.UIFigure);
            app.SubtractBackgroundDropDownLabel.Text = 'Subtract Background';
            app.SubtractBackgroundDropDown = uidropdown(app.UIFigure);
            app.SubtractBackgroundDropDown.Items = {'Select ROI', 'Use Dark Meas'};
            app.SubtractBackgroundDropDown.ValueChangedFcn = createCallbackFcn(app, @SubtractBackgroundDropDownValueChanged, true);
            app.SubtractBackgroundDropDown.Value = 'Select ROI';
            app.BkgROIEditFieldLabel = uilabel(app.UIFigure);
            app.BkgROIEditFieldLabel.Text = 'Bkg ROI';
            app.BkgROIEditField = uieditfield(app.UIFigure, 'numeric');
            app.BkgROIEditField.Value = 150;
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Value = 350;
            app.SubtractButton = uibutton(app.UIFigure, 'push');
            app.SubtractButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractButtonPushed, true);
            app.SubtractButton.Enable = 'off';
            app.SubtractButton.Text = 'Subtract';
            app.Panel_5 = uipanel(app.UIFigure);
            app.LoadcamerafileButton = uibutton(app.UIFigure, 'push');
            app.LoadcamerafileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcamerafileButtonPushed, true);
            app.LoadcamerafileButton.Text = 'Load camera file';
            app.ReleasefileButton = uibutton(app.UIFigure, 'push');
            app.ReleasefileButton.ButtonPushedFcn = createCallbackFcn(app, @ReleasefileButtonPushed, true);
            app.ReleasefileButton.Text = 'Release file';
            app.ImfeelingluckyButton = uibutton(app.UIFigure, 'push');
            app.ImfeelingluckyButton.ButtonPushedFcn = createCallbackFcn(app, @ImfeelingluckyButtonPushed, true);
            app.ImfeelingluckyButton.Text = 'I''m feeling lucky';
            app.CameraImageLamp = uilamp(app.UIFigure);
            app.CameraImageLamp.Enable = 'off';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'ns', 'chs'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'ns';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_PreProc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end