classdef APP_RuntimeProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StopButton                      matlab.ui.control.Button
        ColormapLimitsLower_2           matlab.ui.control.NumericEditField
        ColormapLimitsEditFieldLabel_2  matlab.ui.control.Label
        ColormapLimitsUpper_2           matlab.ui.control.NumericEditField
        ColormapLimitsLower             matlab.ui.control.NumericEditField
        ColormapLimitsEditFieldLabel    matlab.ui.control.Label
        ColormapLimitsUpper             matlab.ui.control.NumericEditField
        NpixelsEditField_2              matlab.ui.control.NumericEditField
        FinishedLamp                    matlab.ui.control.Lamp
        FinishedLampLabel               matlab.ui.control.Label
        RunningLamp                     matlab.ui.control.Lamp
        RunningLampLabel                matlab.ui.control.Label
        TVALParametersLabel             matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        Label_2                         matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        NNegEditField                   matlab.ui.control.NumericEditField
        NNegEditFieldLabel              matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        TChannelsEditField              matlab.ui.control.EditField
        TChannelsEditFieldLabel         matlab.ui.control.Label
        WLChannelsEditField             matlab.ui.control.EditField
        WLChannelsEditFieldLabel        matlab.ui.control.Label
        RestartButton                   matlab.ui.control.Button
        ContFlowSwitch                  matlab.ui.control.Switch
        ContFlowSwitchLabel             matlab.ui.control.Label
        NpixelsEditField                matlab.ui.control.NumericEditField
        NpixelsEditFieldLabel           matlab.ui.control.Label
        DayEditField                    matlab.ui.control.EditField
        DayEditFieldLabel               matlab.ui.control.Label
        CWMeasuredTypeSwitch            matlab.ui.control.Switch
        CWMeasuredTypeSwitchLabel       matlab.ui.control.Label
        PatternTypeButtonGroup          matlab.ui.container.ButtonGroup
        ShiftedButton                   matlab.ui.control.ToggleButton
        PosButton                       matlab.ui.control.ToggleButton
        PosNegButton                    matlab.ui.control.ToggleButton
        BasisButtonGroup                matlab.ui.container.ButtonGroup
        RasterScanButton                matlab.ui.control.ToggleButton
        ScrambledHadamardButton         matlab.ui.control.ToggleButton
        InPathEditField                 matlab.ui.control.EditField
        InPathEditFieldLabel            matlab.ui.control.Label
        GratingEditField                matlab.ui.control.EditField
        GratingEditFieldLabel           matlab.ui.control.Label
        FluoFiltEditField               matlab.ui.control.EditField
        FluoFiltEditFieldLabel          matlab.ui.control.Label
        DichroicEditField               matlab.ui.control.EditField
        DichroicEditFieldLabel          matlab.ui.control.Label
        PrefixEditField                 matlab.ui.control.EditField
        PrefixEditFieldLabel            matlab.ui.control.Label
        LaserEditField                  matlab.ui.control.EditField
        LaserEditFieldLabel             matlab.ui.control.Label
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes5_2                       matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        MyTimer = 0; % Description
        filename
        fullpath
        fig
        Kfig
        Tfig
        Wfig
        spc
        im
        imfig
        prevIt = 1;
        RGBfig
        PSNR
    end
    
    
    methods (Access = public)
        
        function TimerCallback(app,obj,event)
            
            LoadSPC(app);
            a = 0;
        end
        
        function camera = LoadCCD(app)
            camera = zeros(512,512,1,1,2);
            ccd_ext='SPE';
            output_fileCCD = [app.fullpath app.filename];
            i = 1; j = 1;
            for k = 1:size(camera,5)
                try
                    camera(:,:,i,j,k) = loadSPE([output_fileCCD '_' num2str(0) '_' num2str(j-1) '_' num2str(k-1) '.',ccd_ext],[512 512]);
                catch ME
                    camera(:,:,i,j,k) = zeros(512,512);
                end
            end
            camera = squeeze(camera(:,:,1,2)-camera(:,:,1,1));
            
            app.fig.CData = camera;
            % search for Camera Calibration
            files = dir('../Calibrations/520_microscope_*.mat');
            for i = 1:size(files,1)
                date(i) = str2double(files(i).name(16:23));
            end
            [~,b] = min(abs(date-str2double(app.DayEditField.Value)));
            a = load(['../Calibrations/',files(b).name]);
            camera_new = fliplr(imwarp(camera,a.tform,'OutputView',imref2d(a.pixels)));
            
            app.fig.CData = camera_new;
            app.ColormapLimitsUpper.Value = app.UIAxes4.CLim(2);
            app.ColormapLimitsLower.Value = app.UIAxes4.CLim(1);
            drawnow;
            
        end
        
        function LoadSPC(app)
                
                files = dir([app.fullpath app.filename '*.sdt']);
                if strcmp(app.ContFlowSwitch.Value,'Off')
                files(1) = [];
                end
                if size(files,1)>0
                for i = app.prevIt:size(files,1)
                    clear d
                    try 
                    d = f_read_sdt_01([app.fullpath files(i).name]);
                    catch ME
                            pause(1);
                            d = f_read_sdt_01([app.fullpath files(i).name]);
                    end
                    d = reshape(d,str2double(app.TChannelsEditField.Value),str2double(app.WLChannelsEditField.Value),[]);
                    app.spc(:,:,(1:size(d,3)) + (i-1)*size(d,3)) = double(d);
                end
                app.prevIt = size(files,1);
                else
                    app.spc = zeros(str2double(app.TChannelsEditField.Value),str2double(app.WLChannelsEditField.Value),3);
                end            
          
            SPC_PreProc(app);
            SPC_Imrecon(app);
        end
        
        function SPC_PreProc(app)
            normalize = @(X)X./max(X(:));
            %load(['../Calibrations/Efficiency_L16_',app.GratingEditField.Value,'.mat'])
            load(['../Calibrations/',app.GratingEditField.Value,'_Lambda_L16.mat'])
            app.Wfig.XData = lambda;
            %a = load(['../../Calibrations/L16_temporal_offsets_20220520.mat']);
            %app.spc = app.spc.*efficiency_L16';
%             for ki = 1:size(app.spc,3)               
%                     app.spc(:,:,ki) = circshift(app.spc(:,:,ki),a.time_delay_shift); 
%             end
            %app.spc = app.spc-mean(app.spc(150:350,:,:),1);
            
            t = TimeVectorTCSPC(str2double(app.TChannelsEditField.Value));
            [~,b] = max(squeeze(sum(app.spc,2:3)));
            app.Kfig.YData = squeeze(sum(app.spc,1:2));
            app.Tfig.YData = normalize(squeeze(sum(app.spc,2:3))); 
            app.Tfig.XData = t-t(b)+(t(2)-t(1));
            app.Wfig.YData = squeeze(sum(app.spc,[1 3]));        
        end
        
        function SPC_Imrecon(app)
            q = strcmp(app.PatternTypeButtonGroup.SelectedObject.Text,'Pos-Neg');
            l = strcmp(app.PatternTypeButtonGroup.SelectedObject.Text,'Shifted')|| strcmp(app.PatternTypeButtonGroup.SelectedObject.Text,'Pos');
            
            if strcmp(app.BasisButtonGroup.SelectedObject.Text,'Raster Scan')
                q = 0; l = 0;
            end
            
            spc = zeros([size(app.spc,1:2)  (1+q).*app.NpixelsEditField.Value*app.NpixelsEditField_2.Value + l*app.NNegEditField.Value]);
            spc(:,:,1:size(app.spc,3)) = app.spc;
            
            if not(strcmp(app.BasisButtonGroup.SelectedObject.Text,'Raster Scan'))
            S.spc = spc;
            if l == 1
            indexCW = zeros(1,2*app.NpixelsEditField.Value*app.NpixelsEditField_2.Value);
            indexCW(1:2:end) = 1;
            if app.NNegEditField.Value == 1
                neg2save = 2;
            else
                [~,d] = find((indexCW == 0));
                b = round(linspace(1,numel(d),app.NNegEditField.Value));
                neg2save = d(b)./2+1;
                neg2save = neg2save + (1:length(neg2save))-1;
            end
            neg2save = neg2save(neg2save>size(app.spc,3));
            S.spc(:,:,neg2save) = repmat(app.spc(:,:,2),[1 1 length(neg2save)]);
            S.spc(:,:,neg2save-1) = repmat(app.spc(:,:,1),[1 1 length(neg2save)]);
            end

            S.t_start = 1; S.t_stop = size(app.spc,1); S.l_start = 1; S.l_stop = size(app.spc,2);
            S.IntegralovertimeCheckBox.Value = 1;
            S.IntegraloverwavelengthCheckBox.Value = 0;
            S.BasisDropDown.Value = app.BasisButtonGroup.SelectedObject.Text;
            S.PatterntypeDropDown_2.Value = app.PatternTypeButtonGroup.SelectedObject.Text;
            S.NegEditField.Value = app.NNegEditField.Value;
            S.FlipCheckBox.Value = 0;
            S.CompressivesensingSwitch.Value = 'On';
            S.CompressionTypeDropDown.Value = 'TVAL3';
            S.loaded = [];
            S.camera = app.fig.CData;
            S.TVALmu.Value = app.EditField_2.Value;
            S.TVALbeta.Value = app.EditField.Value;
            S.RatioEditField.Value = ceil((1-size(app.spc,3)/size(spc,3))*100);
            S.MethodDropDown.Value = 'Distributed';
            if strcmp(app.CWMeasuredTypeSwitch.Value,'Yes')
            S.CWDropDown.Value = 'Measured';
            else
            S.CWDropDown.Value = 'Not Measured';
            end
            S.visplot = 0;
            
            S = ImageReconstruction(S);
            app.im = S.im;
            else % Raster Scan Case
               app.im = reshape(spc(:,:,1:app.NpixelsEditField.Value*app.NpixelsEditField_2.Value),[size(app.spc,1) size(app.spc,2) app.NpixelsEditField.Value app.NpixelsEditField_2.Value]);
               S.RatioEditField.Value = ceil((1-size(app.spc,3)/size(spc,3))*100);
            end
            
            app.imfig.CData = squeeze(sum(app.im,1:2));
            A = S.camera;
            I = imresize(squeeze(sum(app.im,1:2)),size(A),'box');
            A = A./sum(A(:));
            app.PSNR.YData = [app.PSNR.YData Compute_PSNR(A,I./sum(I(:)))];
            app.PSNR.XData = [app.PSNR.XData S.RatioEditField.Value];
            app.ColormapLimitsUpper_2.Value = app.UIAxes5.CLim(2);
            app.ColormapLimitsLower_2.Value = app.UIAxes5.CLim(1);
            app.RGBfig.CData = Hyperspectral2RGB(app.Wfig.XData,app.im);
            if S.RatioEditField.Value == 0
                stop(app.MyTimer);
                app.RunningLamp.Color = [0.65,0.65,0.65];
                app.FinishedLamp.Color = [0 1 0];
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Position = [100 50 1031 750];
            path = mfilename('fullpath');
            cd(path(1:(end-length(mfilename()))));
            addpath ./common/
            
            [y,m,d] = ymd(datetime('now'));
            y = num2str(y); m = num2str(m);d = num2str(d);
            if length(m)==1
                m = ['0',m];
            end
            if length(d)==1
                d = ['0',d];
            end
            app.DayEditField.Value = [y m d];
            app.UIFigure.Name = 'λFLIM Runtime Visualizer';
            app.MyTimer = timer('Period',0.5,'ExecutionMode','fixedSpacing','TasksToExecute', Inf);
            app.MyTimer.TimerFcn = @app.TimerCallback; % Your Timer Callback
            spc = zeros(str2double(app.TChannelsEditField.Value),str2double(app.WLChannelsEditField.Value),2);
            app.fig = imagesc(app.UIAxes4,zeros(512,512)); axis(app.UIAxes4,'image');colorbar(app.UIAxes4);colormap(app.UIAxes4,'gray');
            app.Kfig = plot(app.UIAxes,squeeze(sum(spc,1:2))); grid(app.UIAxes,'minor');
            app.Tfig = plot(app.UIAxes3,squeeze(sum(spc,2:3))); grid(app.UIAxes3,'minor');
            app.Wfig = plot(app.UIAxes2,squeeze(sum(spc,[1 3])),'-o'); grid(app.UIAxes2,'minor');
            app.imfig = imagesc(app.UIAxes5,zeros(app.NpixelsEditField.Value,app.NpixelsEditField_2.Value)); axis(app.UIAxes5,'image');colorbar(app.UIAxes5);colormap(app.UIAxes5,'gray');
            app.RGBfig = imagesc(app.UIAxes5_2,zeros(app.NpixelsEditField.Value,app.NpixelsEditField_2.Value)); axis(app.UIAxes5_2,'image'); axis(app.UIAxes5_2,'off');
            app.PSNR = plot(app.UIAxes6,100,0,'-o'); axis(app.UIAxes6,'square');
            % Activate parallel toolbox
            parfor i = 1:2
                a = 0;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.MyTimer);
            delete(app)
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            stop(app.MyTimer);
            APP_RuntimeProcessing();
            % Delete old instance
            close(app.UIFigure)
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            app.filename = [num2str(app.LaserEditField.Value) '_' app.PrefixEditField.Value '_' num2str(app.DichroicEditField.Value) '_' num2str(app.FluoFiltEditField.Value) '_' num2str(app.GratingEditField.Value)];
            app.fullpath = [app.InPathEditField.Value filesep app.DayEditField.Value filesep];
            
            % Load CCD
            addpath ../Shared/
            LoadCCD(app);
            app.RunningLamp.Color = [0.00,1.00,0.00];
            %% RUNTIME
            start(app.MyTimer);
        end

        % Value changed function: ColormapLimitsLower
        function ColormapLimitsLowerValueChanged(app, event)
            app.UIAxes4.CLim(1) = round(app.ColormapLimitsLower.Value*100)/100; 
            app.ColormapLimitsLower.Value = round(app.ColormapLimitsLower.Value*100)/100;

        end

        % Value changed function: ColormapLimitsUpper
        function ColormapLimitsUpperValueChanged(app, event)
            app.UIAxes4.CLim(2) = round(app.ColormapLimitsUpper.Value*100)/100;
            app.ColormapLimitsUpper.Value = round(app.ColormapLimitsUpper.Value*100)/100;
        end

        % Value changed function: ColormapLimitsLower_2
        function ColormapLimitsLower_2ValueChanged(app, event)
            app.UIAxes5.CLim(1) = round(app.ColormapLimitsLower_2.Value*100)/100; 
            app.ColormapLimitsLower_2.Value = round(app.ColormapLimitsLower_2.Value*100)/100;
        end

        % Value changed function: ColormapLimitsUpper_2
        function ColormapLimitsUpper_2ValueChanged(app, event)
            app.UIAxes5.CLim(1) = round(app.ColormapLimitsUpper_2.Value*100)/100; 
            app.ColormapLimitsUpper_2.Value = round(app.ColormapLimitsUpper_2.Value*100)/100;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.MyTimer);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Patterns')
            xlabel(app.UIAxes, 'Number')
            ylabel(app.UIAxes, 'Counts')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Spectrum')
            xlabel(app.UIAxes2, 'Wavelength')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Decay')
            xlabel(app.UIAxes3, 'Time')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'CCD Image')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'SPC')
            app.UIAxes5_2 = uiaxes(app.UIFigure);
            app.UIAxes6 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes6, 'CR [%]')
            ylabel(app.UIAxes6, 'PSNR vs CCD [dB]')
            zlabel(app.UIAxes6, 'Z')
            app.LaserEditFieldLabel = uilabel(app.UIFigure);
            app.LaserEditFieldLabel.Text = 'Laser';
            app.LaserEditField = uieditfield(app.UIFigure, 'text');
            app.LaserEditField.Value = '520';
            app.PrefixEditFieldLabel = uilabel(app.UIFigure);
            app.PrefixEditFieldLabel.Text = 'Prefix';
            app.PrefixEditField = uieditfield(app.UIFigure, 'text');
            app.PrefixEditField.Value = 'BEADS1um20100msSH';
            app.DichroicEditFieldLabel = uilabel(app.UIFigure);
            app.DichroicEditFieldLabel.Text = 'Dichroic';
            app.DichroicEditField = uieditfield(app.UIFigure, 'text');
            app.DichroicEditField.Value = '550';
            app.FluoFiltEditFieldLabel = uilabel(app.UIFigure);
            app.FluoFiltEditFieldLabel.Text = 'FluoFilt';
            app.FluoFiltEditField = uieditfield(app.UIFigure, 'text');
            app.FluoFiltEditField.Value = '550';
            app.GratingEditFieldLabel = uilabel(app.UIFigure);
            app.GratingEditFieldLabel.Text = 'Grating';
            app.GratingEditField = uieditfield(app.UIFigure, 'text');
            app.GratingEditField.Value = '610';
            app.InPathEditFieldLabel = uilabel(app.UIFigure);
            app.InPathEditFieldLabel.Text = 'InPath';
            app.InPathEditField = uieditfield(app.UIFigure, 'text');
            app.InPathEditField.Value = '\\nas-dfis.fisica.polimi.it\work\DATA\CS_FLIM\Microscope';
            app.BasisButtonGroup = uibuttongroup(app.UIFigure);
            app.BasisButtonGroup.Title = 'Basis';
            app.ScrambledHadamardButton = uitogglebutton(app.BasisButtonGroup);
            app.ScrambledHadamardButton.Text = 'Scrambled-Hadamard';
            app.ScrambledHadamardButton.Value = true;
            app.RasterScanButton = uitogglebutton(app.BasisButtonGroup);
            app.RasterScanButton.Text = 'Raster Scan';
            app.PatternTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.PatternTypeButtonGroup.Title = 'Pattern Type';
            app.PosNegButton = uitogglebutton(app.PatternTypeButtonGroup);
            app.PosNegButton.Text = 'Pos-Neg';
            app.PosButton = uitogglebutton(app.PatternTypeButtonGroup);
            app.PosButton.Text = 'Pos';
            app.PosButton.Value = true;
            app.ShiftedButton = uitogglebutton(app.PatternTypeButtonGroup);
            app.ShiftedButton.Text = 'Shifted';
            app.CWMeasuredTypeSwitchLabel = uilabel(app.UIFigure);
            app.CWMeasuredTypeSwitchLabel.Text = 'CW Measured Type';
            app.CWMeasuredTypeSwitch = uiswitch(app.UIFigure, 'slider');
            app.CWMeasuredTypeSwitch.Items = {'No', 'Yes'};
            app.CWMeasuredTypeSwitch.Value = 'No';
            app.DayEditFieldLabel = uilabel(app.UIFigure);
            app.DayEditFieldLabel.Text = 'Day';
            app.DayEditField = uieditfield(app.UIFigure, 'text');
            app.NpixelsEditFieldLabel = uilabel(app.UIFigure);
            app.NpixelsEditFieldLabel.Text = 'Npixels';
            app.NpixelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NpixelsEditField.Value = 32;
            app.ContFlowSwitchLabel = uilabel(app.UIFigure);
            app.ContFlowSwitchLabel.Text = 'Cont. Flow';
            app.ContFlowSwitch = uiswitch(app.UIFigure, 'slider');
            app.ContFlowSwitch.Value = 'On';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.WLChannelsEditFieldLabel = uilabel(app.UIFigure);
            app.WLChannelsEditFieldLabel.Text = 'WL Channels';
            app.WLChannelsEditField = uieditfield(app.UIFigure, 'text');
            app.WLChannelsEditField.Value = '16';
            app.TChannelsEditFieldLabel = uilabel(app.UIFigure);
            app.TChannelsEditFieldLabel.Text = 'TChannels';
            app.TChannelsEditField = uieditfield(app.UIFigure, 'text');
            app.TChannelsEditField.Value = '4096';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.NNegEditFieldLabel = uilabel(app.UIFigure);
            app.NNegEditFieldLabel.Text = 'NNeg';
            app.NNegEditField = uieditfield(app.UIFigure, 'numeric');
            app.NNegEditField.Value = 40;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'β';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = '2^5';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'μ';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Value = '2^8';
            app.TVALParametersLabel = uilabel(app.UIFigure);
            app.TVALParametersLabel.Text = 'TVAL Parameters';
            app.RunningLampLabel = uilabel(app.UIFigure);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.UIFigure);
            app.FinishedLampLabel = uilabel(app.UIFigure);
            app.FinishedLampLabel.Text = 'Finished';
            app.FinishedLamp = uilamp(app.UIFigure);
            app.NpixelsEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.NpixelsEditField_2.Value = 32;
            app.ColormapLimitsUpper = uieditfield(app.UIFigure, 'numeric');
            app.ColormapLimitsUpper.ValueChangedFcn = createCallbackFcn(app, @ColormapLimitsUpperValueChanged, true);
            app.ColormapLimitsEditFieldLabel = uilabel(app.UIFigure);
            app.ColormapLimitsEditFieldLabel.Text = 'Colormap Limits';
            app.ColormapLimitsLower = uieditfield(app.UIFigure, 'numeric');
            app.ColormapLimitsLower.ValueChangedFcn = createCallbackFcn(app, @ColormapLimitsLowerValueChanged, true);
            app.ColormapLimitsUpper_2 = uieditfield(app.UIFigure, 'numeric');
            app.ColormapLimitsUpper_2.ValueChangedFcn = createCallbackFcn(app, @ColormapLimitsUpper_2ValueChanged, true);
            app.ColormapLimitsEditFieldLabel_2 = uilabel(app.UIFigure);
            app.ColormapLimitsEditFieldLabel_2.Text = 'Colormap Limits';
            app.ColormapLimitsLower_2 = uieditfield(app.UIFigure, 'numeric');
            app.ColormapLimitsLower_2.ValueChangedFcn = createCallbackFcn(app, @ColormapLimitsLower_2ValueChanged, true);
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_RuntimeProcessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end