classdef APP_Simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SeedEditField              matlab.ui.control.NumericEditField
        SeedEditFieldLabel         matlab.ui.control.Label
        TypeDropDown               matlab.ui.control.DropDown
        PreviewSettingsButton      matlab.ui.control.Button
        NPixEditField              matlab.ui.control.NumericEditField
        NPixEditFieldLabel         matlab.ui.control.Label
        WLEditField_2              matlab.ui.control.NumericEditField
        WLEditField                matlab.ui.control.NumericEditField
        TimesEditField             matlab.ui.control.NumericEditField
        TimesEditFieldLabel        matlab.ui.control.Label
        NlambdaEditField           matlab.ui.control.NumericEditField
        NlambdaEditFieldLabel      matlab.ui.control.Label
        EditField                  matlab.ui.control.NumericEditField
        ConfirmSettingsButton      matlab.ui.control.Button
        GenerateButton             matlab.ui.control.Button
        PoissonNoiseCheckBox       matlab.ui.control.CheckBox
        TotalCountsEditField       matlab.ui.control.NumericEditField
        TotalCountsEditFieldLabel  matlab.ui.control.Label
        LoadBasisButton            matlab.ui.control.Button
        AmplitudeKnob              matlab.ui.control.Knob
        AmplitudeKnobLabel         matlab.ui.control.Label
        MuEditField                matlab.ui.control.NumericEditField
        ListBox_3                  matlab.ui.control.ListBox
        MuEditFieldLabel           matlab.ui.control.Label
        SigmaEditField             matlab.ui.control.NumericEditField
        SigmaEditFieldLabel        matlab.ui.control.Label
        IRFCheckBox                matlab.ui.control.CheckBox
        AddButton                  matlab.ui.control.Button
        AddspectrumButton          matlab.ui.control.Button
        ListBox_2                  matlab.ui.control.ListBox
        ListBox                    matlab.ui.control.ListBox
        AddelementButton           matlab.ui.control.Button
        UIAxes_4                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        i = 1;
        k = 1;
        l = 1;
        masksPlot
        SpectPlot
        Preview
        TimePlot
        j = 1;
        data
        patterns
        wl
    end
    
    properties (Access = public)
        spectra % Description
        lambda
        masks
        spc
        t
        dataConfirmed
    end

    methods (Access = private)

        function results = decay(app,tau)
            results = exp(-app.t./tau);
        end

        function results = decayIRF(app,tau)
            mu = app.MuEditField.Value;
            sigma = app.SigmaEditField.Value;
            results = (app.t(2)-app.t(1)).*conv(normpdf(app.t,mu+app.t(length(app.t))/2,sigma),exp(-app.t./tau),'same');
        end

        function y = lifetimeSelect(app,item)
            app.TimePlot.XData = app.t;
            if app.IRFCheckBox.Value == 1
                y = decayIRF(app,item);
            else
                y = decay(app,item);
            end
            app.TimePlot.YData = y;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            path = mfilename('fullpath');
            cd(path(1:(end-length(mfilename())))); 
            addpath './common/'
            app.masksPlot = imagesc(app.UIAxes,zeros(32,32));axis(app.UIAxes,'image'),disableDefaultInteractivity(app.UIAxes)
            app.UIFigure.Name = 'Simulator for SPC Dataset';
            app.SpectPlot = plot(app.UIAxes_2,zeros(1,16),zeros(1,16));axis(app.UIAxes_2,'square'),disableDefaultInteractivity(app.UIAxes)
            app.TimePlot = plot(app.UIAxes_3,zeros(1,256),zeros(1,256));axis(app.UIAxes_3,'square'),disableDefaultInteractivity(app.UIAxes)
            app.t = TimeVectorTCSPC(app.TimesEditField.Value);
            app.Preview = imagesc(app.UIAxes_4,zeros(32,32));axis(app.UIAxes_4,'image'),disableDefaultInteractivity(app.UIAxes)
            app.spectra = [];
            app.lambda = [];
        end

        % Button pushed function: AddelementButton
        function AddelementButtonPushed(app, event)
            [file, path] = uigetfile('*.mat');
            figure(app.UIFigure)
            if file == 0
                return;
            end
            app.ListBox.Items{app.i} = file(1:end-4);
            a = load([path file]);
            app.masks(app.i,:,:) = a.mask;
            app.i = app.i + 1;
        end

        % Clicked callback: ListBox
        function ListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            app.masksPlot.CData = squeeze(app.masks(item,:,:));

        end

        % Button pushed function: AddspectrumButton
        function AddspectrumButtonPushed(app, event)
            [file, path] = uigetfile('*.mat');
            figure(app.UIFigure)
            if file == 0
                return;
            end
            app.ListBox_2.Items{app.j} = file(1:end-4);
            a = load([path file]);
            app.spectra(app.j,:) = a.spectrum;
            app.lambda(app.j,:) = a.lambda;
            app.j = app.j + 1;
        end

        % Clicked callback: ListBox_2
        function ListBox_2Clicked(app, event)
            item = event.InteractionInformation.Item;
            app.SpectPlot.YData = squeeze(app.spectra(item,:));
            app.SpectPlot.XData = squeeze(app.lambda(item,:));
            if app.WLEditField.Value == 0
                app.WLEditField.Value = app.lambda(item,1);
                app.WLEditField_2.Value = app.lambda(item,end);
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.ListBox_3.Items{app.k} = num2str(app.EditField.Value);
            app.k = app.k + 1;
        end

        % Value changed function: IRFCheckBox
        function IRFCheckBoxValueChanged(app, event)
            value = app.IRFCheckBox.Value;
            if value
                app.SigmaEditField.Enable = 1;
                app.MuEditField.Enable = 1;
                lifetimeSelect(app,str2double(app.ListBox_3.Value));
            else
                app.SigmaEditField.Enable = 0;
                app.MuEditField.Enable = 0;
                lifetimeSelect(app,str2double(app.ListBox_3.Value));
            end
        end

        % Clicked callback: ListBox_3
        function ListBox_3Clicked(app, event)
            item = event.InteractionInformation.Item;
            if size(item,1) == 0
                return;
            end
            lifetimeSelect(app,str2double(app.ListBox_3.Value));
        end

        % Value changed function: TimesEditField
        function TimesEditFieldValueChanged(app, event)
            value = app.TimesEditField.Value;
            app.t = TimeVectorTCSPC(value);
        end

        % Value changed function: SigmaEditField
        function SigmaEditFieldValueChanged(app, event)
            value = app.SigmaEditField.Value;
            lifetimeSelect(app,str2double(app.ListBox_3.Value));
        end

        % Value changed function: MuEditField
        function MuEditFieldValueChanged(app, event)
            value = app.MuEditField.Value;
            lifetimeSelect(app,str2double(app.ListBox_3.Value));
        end

        % Value changed function: WLEditField
        function WLEditFieldValueChanged(app, event)
            xlim(app.UIAxes_2,[app.WLEditField.Value app.WLEditField_2.Value]);
        end

        % Value changed function: WLEditField_2
        function WLEditField_2ValueChanged(app, event)
            xlim(app.UIAxes_2,[app.WLEditField.Value app.WLEditField_2.Value]);
        end

        % Button pushed function: ConfirmSettingsButton
        function ConfirmSettingsButtonPushed(app, event)
            app.dataConfirmed = squeeze(sum(app.data,1));
            app.LoadBasisButton.Enable = 1;
        end

        % Button pushed function: PreviewSettingsButton
        function PreviewSettingsButtonPushed(app, event)
            app.NlambdaEditField.Enable = 0;
            app.TimesEditField.Enable = 0;
            app.WLEditField.Enable = 0;
            app.WLEditField_2.Enable = 0;
            app.SigmaEditField.Enable = 0;
            app.MuEditField.Enable = 0;
            app.IRFCheckBox.Enable = 0;
            app.NPixEditField.Enable = 0;
            dl = (app.WLEditField_2.Value - app.WLEditField.Value)/(2*app.NlambdaEditField.Value);
            lambda_start = linspace(app.WLEditField.Value+dl,app.WLEditField_2.Value-dl,app.NlambdaEditField.Value);
            item = sum((1:numel(app.ListBox_2.Items)).*strcmp(app.ListBox_2.Items,app.ListBox_2.Value));
            spectra = squeeze(app.spectra(item,:));
            wl = app.lambda(item,:);
            [~,a] = min(abs(wl-floor(lambda_start(1))));
            [~,b] = min(abs(wl-ceil(lambda_start(end))));
            spectra = interp1(wl(a:b),spectra(a:b),lambda_start);
            spectra(isnan(spectra)) = 0;
            lambda_start(isnan(lambda_start)) = 0;
            app.wl = lambda_start;
            item = sum((1:numel(app.ListBox_3.Items)).*strcmp(app.ListBox_3.Items,app.ListBox_3.Value));
            c = (spectra'.*lifetimeSelect(app,str2double(app.ListBox_3.Items{item})))';
            item = sum((1:numel(app.ListBox.Items)).*strcmp(app.ListBox.Items,app.ListBox.Value));
            im = imresize(squeeze(app.masks(item,:,:)),[app.NPixEditField.Value app.NPixEditField.Value],'box');
            for ii = 1:size(c,1)
                for jj = 1:size(c,2)
                    app.data(item,ii,jj,:,:) = repmat(c(ii,jj),[app.NPixEditField.Value app.NPixEditField.Value]).*im;
                end
            end
            app.data(item,:,:,:,:) = app.AmplitudeKnob.Value.*app.data(item,:,:,:,:)./sum(app.data(item,:,:,:,:),2:5);
            %app.l = app.l + 1;
            app.Preview.CData = squeeze(sum(app.data,1:3));
        end

        % Button pushed function: LoadBasisButton
        function LoadBasisButtonPushed(app, event)
            P = CSpattern(app.TypeDropDown.Value,app.NPixEditField.Value,app.SeedEditField.Value);

            if not(strcmp(app.TypeDropDown.Value,'raster_scan'))
                P = P.ConvertToPosNeg;
            end
            app.patterns = P.Mmatrix;
            app.GenerateButton.Enable = 1;
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            if strcmp(value,'scrambledh')
                app.SeedEditField.Enable = 1;
            else
                app.SeedEditField.Enable = 0;
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.spc = zeros(size(app.dataConfirmed,1),size(app.dataConfirmed,2),size(app.patterns,1));
            M = app.dataConfirmed;
            for i = 1:size(app.spc,1)
                for j = 1:size(app.spc,2)
                    app.spc(i,j,:) = sum(reshape(squeeze(repmat(M(i,j,:,:),[size(app.patterns,1) 1])),size(app.patterns,1),[]).*app.patterns,2);
                end
            end
            [~,b] = max(squeeze(sum(app.spc,1:2)));
            %% Noise
            app.spc = app.spc.*(app.TotalCountsEditField.Value./sum(app.spc(:,:,b),[1 2])); % Normalize on count rate
            if app.PoissonNoiseCheckBox.Value == 1
            app.spc = poissrnd(app.spc); % With this function I can add noise
            end
            assignin('base','spc',app.spc)
            assignin('base','t',app.t);
            assignin('base','lambda',app.wl);
            assignin('base','CCDThreshold',0);
            assignin('base','camera',zeros(256,256));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.AddelementButton = uibutton(app.UIFigure, 'push');
            app.AddelementButton.ButtonPushedFcn = createCallbackFcn(app, @AddelementButtonPushed, true);
            app.AddelementButton.Text = 'Add element';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.ClickedFcn = createCallbackFcn(app, @ListBoxClicked, true);
            app.ListBox.Value = {};
            app.ListBox_2 = uilistbox(app.UIFigure);
            app.ListBox_2.Items = {};
            app.ListBox_2.ClickedFcn = createCallbackFcn(app, @ListBox_2Clicked, true);
            app.ListBox_2.Value = {};
            app.AddspectrumButton = uibutton(app.UIFigure, 'push');
            app.AddspectrumButton.ButtonPushedFcn = createCallbackFcn(app, @AddspectrumButtonPushed, true);
            app.AddspectrumButton.Text = 'Add spectrum';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add τ';
            app.IRFCheckBox = uicheckbox(app.UIFigure);
            app.IRFCheckBox.ValueChangedFcn = createCallbackFcn(app, @IRFCheckBoxValueChanged, true);
            app.IRFCheckBox.Text = 'IRF';
            app.SigmaEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaEditFieldLabel.Enable = 'off';
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigmaEditField.ValueChangedFcn = createCallbackFcn(app, @SigmaEditFieldValueChanged, true);
            app.SigmaEditField.Enable = 'off';
            app.MuEditFieldLabel = uilabel(app.UIFigure);
            app.MuEditFieldLabel.Enable = 'off';
            app.MuEditFieldLabel.Text = 'Mu';
            app.ListBox_3 = uilistbox(app.UIFigure);
            app.ListBox_3.Items = {};
            app.ListBox_3.ClickedFcn = createCallbackFcn(app, @ListBox_3Clicked, true);
            app.ListBox_3.Value = {};
            app.MuEditField = uieditfield(app.UIFigure, 'numeric');
            app.MuEditField.ValueChangedFcn = createCallbackFcn(app, @MuEditFieldValueChanged, true);
            app.MuEditField.Enable = 'off';
            app.AmplitudeKnobLabel = uilabel(app.UIFigure);
            app.AmplitudeKnobLabel.Text = 'Amplitude';
            app.AmplitudeKnob = uiknob(app.UIFigure, 'continuous');
            app.AmplitudeKnob.Value = 40;
            app.LoadBasisButton = uibutton(app.UIFigure, 'push');
            app.LoadBasisButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBasisButtonPushed, true);
            app.LoadBasisButton.Enable = 'off';
            app.LoadBasisButton.Text = 'Load Basis';
            app.TotalCountsEditFieldLabel = uilabel(app.UIFigure);
            app.TotalCountsEditFieldLabel.Text = 'TotalCounts';
            app.TotalCountsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalCountsEditField.Value = 1000000;
            app.PoissonNoiseCheckBox = uicheckbox(app.UIFigure);
            app.PoissonNoiseCheckBox.Text = 'Poisson Noise';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Enable = 'off';
            app.GenerateButton.Text = 'Generate';
            app.ConfirmSettingsButton = uibutton(app.UIFigure, 'push');
            app.ConfirmSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmSettingsButtonPushed, true);
            app.ConfirmSettingsButton.Text = 'Confirm Settings';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.NlambdaEditFieldLabel = uilabel(app.UIFigure);
            app.NlambdaEditFieldLabel.Text = 'Nlambda';
            app.NlambdaEditField = uieditfield(app.UIFigure, 'numeric');
            app.NlambdaEditField.Value = 16;
            app.TimesEditFieldLabel = uilabel(app.UIFigure);
            app.TimesEditFieldLabel.Text = 'Times';
            app.TimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimesEditField.ValueChangedFcn = createCallbackFcn(app, @TimesEditFieldValueChanged, true);
            app.TimesEditField.Value = 256;
            app.WLEditField = uieditfield(app.UIFigure, 'numeric');
            app.WLEditField.ValueChangedFcn = createCallbackFcn(app, @WLEditFieldValueChanged, true);
            app.WLEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.WLEditField_2.ValueChangedFcn = createCallbackFcn(app, @WLEditField_2ValueChanged, true);
            app.NPixEditFieldLabel = uilabel(app.UIFigure);
            app.NPixEditFieldLabel.Text = 'NPix';
            app.NPixEditField = uieditfield(app.UIFigure, 'numeric');
            app.NPixEditField.Value = 32;
            app.PreviewSettingsButton = uibutton(app.UIFigure, 'push');
            app.PreviewSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewSettingsButtonPushed, true);
            app.PreviewSettingsButton.Text = 'Preview Settings';
            app.TypeDropDown = uidropdown(app.UIFigure);
            app.TypeDropDown.Items = {'wh', 'scrambledh'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'scrambledh';
            app.SeedEditFieldLabel = uilabel(app.UIFigure);
            app.SeedEditFieldLabel.Text = 'Seed';
            app.SeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.SeedEditField.Value = 222;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_Simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end