classdef Efficiency_Calibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EfficiencyCalibrationUIFigure  matlab.ui.Figure
        UITable                        matlab.ui.control.Table
        SelectSpectraFilesButton       matlab.ui.control.Button
        Time_chanEditFieldLabel        matlab.ui.control.Label
        Time_chanEditField             matlab.ui.control.NumericEditField
        Num_lambdaEditFieldLabel       matlab.ui.control.Label
        Num_lambdaEditField            matlab.ui.control.NumericEditField
        SavetofileButton               matlab.ui.control.Button
        SavetoworkspaceButton          matlab.ui.control.Button
        SpectrometerDropDownLabel      matlab.ui.control.Label
        SpectrometerDropDown           matlab.ui.control.DropDown
        SelectSPCfileButton            matlab.ui.control.Button
        DarkCheckBox                   matlab.ui.control.CheckBox
        LoadCalibrationButton          matlab.ui.control.Button
        UpdatePlotButton               matlab.ui.control.Button
        LambdaCalibLampLabel           matlab.ui.control.Label
        LambdaCalibLamp                matlab.ui.control.Lamp
        Lambda_chanListBox             matlab.ui.control.ListBox
        CalculateEfficiencyButton      matlab.ui.control.Button
        ClearTableButton               matlab.ui.control.Button
        XaxisDropDown                  matlab.ui.control.DropDown
        EqualizeefficiencyButton       matlab.ui.control.Button
        DarkCheckBox_2                 matlab.ui.control.CheckBox
        Amplitude_plot                 matlab.ui.control.UIAxes
        spc_plot                       matlab.ui.control.UIAxes
        Efficiency_plot                matlab.ui.control.UIAxes
        TableContextMenu               matlab.ui.container.ContextMenu
        DeleteCellMenu                 matlab.ui.container.Menu
        DeleteRowMenu                  matlab.ui.container.Menu
        ReplaceFileMenu                matlab.ui.container.Menu
    end

    
    properties (Access = private)
        spc_path % Path of the folder containing B&H files
        N_meas = 1;  % number of reference measurements
        fileTab
        SPC_time = 4096;    % default spc data
        SPC_Nchan = 16;
        spc_ampl                 % spc data
        spectra_ampl
        spectra_ampl_interp
        current_file
        current_tab_indices
        lambda_calib    %lambda calibration of L16 channels
        lambda_spe
        efficiency
        SPETYPE     % spectrometer model
        
    end
    
    methods (Access = private)
        
        function spc = ReadSPCData(app,filespc)
            dd = reshape(f_read_sdt_01(...
                filespc),[app.SPC_time,...
                app.SPC_Nchan]);
            spc = sum(dd,1);
            %spc = spc./max(spc);
            
        end
        
        function [y,filespc] = GetSPCData(app,darkflag)
            app.EfficiencyCalibrationUIFigure.Visible = 'off';
            [filespc,app.spc_path] = uigetfile('*.sdt',...
                'Select SPC file');
            if filespc == 0
                filespc = 0;y = 0;
                figure(app.EfficiencyCalibrationUIFigure);
                return;
            end
            if darkflag == true
                [filespc_dark,spcdark_path] = uigetfile('*.*',...
                'Select SPC Dark file');
                if filespc_dark == 0
                    figure(app.EfficiencyCalibrationUIFigure);
                    return;
                end
            end
            figure(app.EfficiencyCalibrationUIFigure);
            y = app.ReadSPCData(fullfile(app.spc_path,filespc));
            if darkflag == true
                drk = app.ReadSPCData(fullfile(spcdark_path,filespc_dark));
                y = (y - drk);
            end
            y = app.NormalizeData(y);
                
        end
        
        function [lambda_spe,spectra] = ReadSpectraData(app,filespe)
            switch lower(app.SpectrometerDropDown.Value)
                case 'hammamatsu'
                    [spectra,lambda_spe] = ReadSpectrum_HAM(filespe);
                    %spectra = spectra./max(spectra);
            end
            
        end
        
        function [y,lambdaSPE,filespe] = GetSpectraData(app,darkflag)
            app.EfficiencyCalibrationUIFigure.Visible = 'off';
            [filespe,spe_path] = uigetfile('*.*',...
                'Select Spectrum file');
            if filespe == 0
                y = 0;lambdaSPE = 0; filespe = 0;
                figure(app.EfficiencyCalibrationUIFigure);
                return;
            end
            if darkflag == true
                [filespe_dark,spedark_path] = uigetfile('*.*',...
                'Select Spectrum Dark file');
                if filespe_dark == 0
                    figure(app.EfficiencyCalibrationUIFigure);
                    return;
                end
            end
            figure(app.EfficiencyCalibrationUIFigure);
            
            %% read and plot spectra data
            [lambdaSPE,y] = app.ReadSpectraData(fullfile(spe_path,filespe));
            if darkflag == true
                [~,drk] = app.ReadSpectraData(fullfile(spedark_path,filespe_dark));
                y = (y - drk);
            end
            y = app.NormalizeData(y);
            
        end
        function y = NormalizeData(app,data) %#ok<INUSL> 
            y = data(:)./max(data(:));
        end
        
        function [] = PlotSPCData(app)
            semilogy(app.spc_plot,reshape(f_read_sdt_01(...
                fullfile(app.spc_path,app.current_file{1,1}{1})),...
                [app.SPC_time,...
                app.SPC_Nchan]))
        end
        
        function [] = PlotAmplitudeData(app)
            cla(app.Amplitude_plot),hold(app.Amplitude_plot,'on');
            str_color = ['b';'r';'k';'y';'g';'c';'p'];
            if ~isempty(app.spc_ampl)
                for i = 1:size(app.spc_ampl,2)
                plot(app.Amplitude_plot ,app.lambda_calib,app.spc_ampl(:,i),...
                    '.-','Color',str_color(i)),
                end
            end
            if ~isempty(app.spectra_ampl)
                for i = 1:size(app.spectra_ampl,2)
                plot(app.Amplitude_plot,app.lambda_spe,app.spectra_ampl(:,i),...
                    '-','Color',str_color(i));
                end
            end
            xlim(app.Amplitude_plot,[min(app.lambda_calib)*0.9 max(app.lambda_calib)*1.1]);
            legend(app.Amplitude_plot,app.UITable.Data{:,1});
        end
        
        function [] = PlotEfficiencyData(app,xaxis)
            if nargin<2
                xaxis = 'wavelength (nm)';
            end
            yyaxis left 
            cla(app.Efficiency_plot),hold(app.Efficiency_plot,'on');
            yyaxis right
            cla(app.Efficiency_plot),hold(app.Efficiency_plot,'on');
            str_color = ['b';'r';'k';'y';'g';'c';'p'];
            switch lower(xaxis)
                case 'channels'
                    xaxis = 1:app.SPC_Nchan;
                case 'wavelength (nm)'
                    xaxis = app.lambda_calib;
            end
            if ~isempty(app.spc_ampl)
                for i = 1:size(app.spc_ampl,2)
                yyaxis left 
                plot(app.Efficiency_plot ,xaxis,app.spc_ampl(:,i),...
                    '.-','Color',str_color(i)),
                end
            end
            if ~isempty(app.spectra_ampl_interp)
                for i = 1:size(app.spectra_ampl_interp,2)
                yyaxis left
                plot(app.Efficiency_plot,xaxis,app.spectra_ampl_interp(:,i),...
                    '-','Color',str_color(i));
                end
            end
            legend(app.Efficiency_plot,app.UITable.Data{:,1});
            yyaxis right
            for i = 1:size(app.efficiency,2)
                yyaxis right
                plot(app.Efficiency_plot,xaxis,app.efficiency(:,i),...
                    '-','Color',str_color(i),'LineWidth',1.5);
            end
            %plot(app.Efficiency_plot,app.lambda_calib,app.efficiency,'k-'),
            ylabel(app.Efficiency_plot,'Efficiency');
            %legend(app.Efficiency_plot,[app.UITable.Data{:,1},'efficiency']);
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            sz = [1,5];
            varType = {'string','string','string','double','double'};
            varNames = {'Label','File Spectrum','File SPC','ChnMin','ChnMax'};
            app.fileTab = table('Size',sz,'VariableTypes',varType,...
                'VariableNames',varNames);
            app.lambda_calib = 1:app.SPC_Nchan;
            app.LambdaCalibLamp.Enable = false;
        end

        % Button pushed function: SelectSpectraFilesButton
        function SelectSpectraFilesButtonPushed(app, event)
            [spe,app.lambda_spe,filespe] = app.GetSpectraData(app.DarkCheckBox.Value);
            %% populate the table
            N = app.N_meas;
            app.fileTab(N,2) = {filespe};
            if ismissing(app.fileTab(N,1))
                app.fileTab(N,1) = {['SAMPLE ',num2str(N)]};
            end
            if ~ismissing(app.fileTab(N,3))
                app.N_meas = N + 1; % if both file are present increase the counter
            end
            app.fileTab(:,4) = table(1);
            app.fileTab(:,5) = table(app.SPC_Nchan);
            app.UITable.Data = app.fileTab;
            %% read and plot spectra data
            app.spectra_ampl(:,N) = spe;
            
            app.PlotAmplitudeData;
            
            
        end

        % Value changed function: Time_chanEditField
        function Time_chanEditFieldValueChanged(app, event)
            app.SPC_time = app.Time_chanEditField.Value;
        end

        % Value changed function: Num_lambdaEditField
        function Num_lambdaEditFieldValueChanged(app, event)
            app.SPC_Nchan = app.Num_lambdaEditField.Value;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.current_tab_indices = indices;
            if (indices(2)==3)&&(~ismissing(app.UITable.Data(indices(1),indices(2))))
                app.current_file = app.UITable.Data(indices(1),indices(2));
                app.PlotSPCData;
            end
        end

        % Callback function
        function PlotButtonPushed(app, event)
            PlotSPCData(app);
        end

        % Button pushed function: SavetofileButton
        function SavetofileButtonPushed(app, event)
            [filesave,pathsave] = uiputfile('*.mat',...
                'Save to mat file');
            efficiency_L16 = app.efficiency;
            info = 'multiply L16 data for the vector';
            save(fullfile(pathsave,filesave),'efficiency_L16','info');
            
        end

        % Button pushed function: SavetoworkspaceButton
        function SavetoworkspaceButtonPushed(app, event)
            efficiency_L16 = app.lambda_calib;
            prompt = {'Enter variable name:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'efficiency_L16'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            assignin('base',answer{1},efficiency_L16)
        end

        % Value changed function: SpectrometerDropDown
        function SpectrometerDropDownValueChanged(app, event)
            app.SPETYPE = app.SpectrometerDropDown.Value;
            
        end

        % Button pushed function: SelectSPCfileButton
        function SelectSPCfileButtonPushed(app, event)
            [spc,filespc] = GetSPCData(app,app.DarkCheckBox_2.Value);
            
            %% populate the table
            N = app.N_meas;
            app.fileTab(N,3) = {filespc};
            if ismissing(app.fileTab(N,1))
                app.fileTab(N,1) = {['SAMPLE ',num2str(N)]};
            end
          
            if ~ismissing(app.fileTab(N,2))
                app.N_meas = N + 1; % if both file are present increase the counter
            end
                
            app.fileTab(:,4) = table(1);
            app.fileTab(:,5) = table(app.SPC_Nchan);
            app.UITable.Data = app.fileTab;
            app.spc_ampl(:,N) = spc;
            
            app.PlotAmplitudeData;
        end

        % Button pushed function: LoadCalibrationButton
        function LoadCalibrationButtonPushed(app, event)
            app.EfficiencyCalibrationUIFigure.Visible = 'off';
            [filespc_calib,filespc_path] = uigetfile('*.mat',...
                'Select Lambda calibration file');
            if filespc_calib == 0
                figure(app.EfficiencyCalibrationUIFigure);
                return;
            end
            figure(app.EfficiencyCalibrationUIFigure);
            load(fullfile(filespc_path,filespc_calib),'lambda');
            if (lambda(1) > 1)&&(numel(lambda)==app.SPC_Nchan)
                app.LambdaCalibLamp.Enable = true;
                app.Lambda_chanListBox.Items = cellstr(num2str(lambda','%.1f'));
                app.lambda_calib = lambda;
            else
                msgbox('File not correct');
            end
            
        end

        % Button pushed function: UpdatePlotButton
        function UpdatePlotButtonPushed(app, event)
            app.PlotAmplitudeData;
        end

        % Button pushed function: CalculateEfficiencyButton
        function CalculateEfficiencyButtonPushed(app, event)
            d = interp1(app.lambda_spe,app.spectra_ampl,app.lambda_calib);
            if size(d,1)==1
                d = d';
            end
            app.spectra_ampl_interp = d;
            app.efficiency = app.spectra_ampl_interp./app.spc_ampl;
            app.PlotEfficiencyData(app.XaxisDropDown.Value);

        end

        % Button pushed function: ClearTableButton
        function ClearTableButtonPushed(app, event)
            sz = [1,6];
            varType = {'string','string','string','double','double','double'};
            varNames = {'Label','File Spectrum','File SPC','RangeMin','RangeMax','valid'};
            app.fileTab = table('Size',sz,'VariableTypes',varType,...
                'VariableNames',varNames);
            %app.fileTab = [];
            app.UITable.Data = [];
            app.N_meas = 1;
            app.spc_ampl = [];
            app.spectra_ampl = [];
            app.spectra_ampl_interp = [];
            app.efficiency = [];
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.fileTab(indices(1),indices(2)) = table(newData);
        end

        % Value changed function: XaxisDropDown
        function XaxisDropDownValueChanged(app, event)
            value = app.XaxisDropDown.Value;
            app.PlotEfficiencyData(value);
        end

        % Button pushed function: EqualizeefficiencyButton
        function EqualizeefficiencyButtonPushed(app, event)
            range = app.UITable.Data{:,4:5};
            app.efficiency = EqualizeEfficiency(app.efficiency,range);
            app.PlotEfficiencyData;
        end

        % Menu selected function: DeleteCellMenu
        function DeleteCellMenuSelected(app, event)
            indices = app.current_tab_indices;
            app.fileTab(indices(1),indices(2)) = table(missing);
            app.UITable.Data = app.fileTab;
%             if app.N_meas > 1
%                 app.N_meas = app.N_meas - 1;
%             end
            if indices(2)==2
                app.spectra_ampl(:,indices(1)) = [];
                app.spectra_ampl_interp(:,indices(1)) = [];
            elseif indices(2)==3
                app.spc_ampl(:,indices(1)) = [];
            end
        end

        % Menu selected function: DeleteRowMenu
        function DeleteRowMenuSelected(app, event)
            indices = app.current_tab_indices;
            app.fileTab(indices(1),:) = [];
            app.UITable.Data = app.fileTab;
            if app.N_meas > 1
                app.N_meas = app.N_meas - 1;
            end
            app.spc_ampl(:,indices(1)) = [];
            app.spectra_ampl(:,indices(1)) = [];
            app.spectra_ampl_interp(:,indices(1)) = [];
            
            
        end

        % Menu selected function: ReplaceFileMenu
        function ReplaceFileMenuSelected(app, event)
            indices = app.current_tab_indices;
            if indices(2)==2 % spectra file
                app.EfficiencyCalibrationUIFigure.Visible = 'off';
                [filespe,spe_path] = uigetfile('*.*',...
                    'Select Spectrum file');
                if filespe == 0
                    figure(app.EfficiencyCalibrationUIFigure);
                    return;
                end
                if app.DarkCheckBox.Value == true
                    [filespe_dark,spedark_path] = uigetfile('*.*',...
                        'Select Spectrum Dark file');
                    if filespe_dark == 0
                        figure(app.EfficiencyCalibrationUIFigure);
                        return;
                    end
                end
                figure(app.EfficiencyCalibrationUIFigure);
                app.fileTab(indices(1),2) = {filespe};
                app.UITable.Data = app.fileTab;
                %% read and plot spectra data
                [app.lambda_spe,spe] = app.ReadSpectraData(fullfile(spe_path,filespe));
                app.spectra_ampl(:,indices(1)) = spe;
                if app.DarkCheckBox.Value == true
                    [~,drk] = app.ReadSpectraData(fullfile(spedark_path,filespe_dark));
                    app.spectra_ampl(:,indices(1)) = app.NormalizeData(spe - drk);
                end
                app.PlotAmplitudeData;
            elseif indices(2) == 3 %%spc file
                [spc,filespc] = GetSPCData(app,app.DarkCheckBox_2.Value);
                
                app.fileTab(indices(1),3) = {filespc};
                app.UITable.Data = app.fileTab;
                app.spc_ampl(:,indices(1)) = spc;
                
                app.PlotAmplitudeData;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EfficiencyCalibrationUIFigure = uifigure('Visible', 'off');
            app.EfficiencyCalibrationUIFigure.Name = 'Efficiency Calibration';
            app.UITable = uitable(app.EfficiencyCalibrationUIFigure);
            app.UITable.ColumnName = {'Label'; 'File Spectrum'; 'File SPC'; 'ChnMin'; 'ChnMax'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false false true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SelectSpectraFilesButton = uibutton(app.EfficiencyCalibrationUIFigure, 'push');
            app.SelectSpectraFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSpectraFilesButtonPushed, true);
            app.SelectSpectraFilesButton.Text = 'Select Spectra Files';
            app.Time_chanEditFieldLabel = uilabel(app.EfficiencyCalibrationUIFigure);
            app.Time_chanEditFieldLabel.Text = 'Time_chan';
            app.Time_chanEditField = uieditfield(app.EfficiencyCalibrationUIFigure, 'numeric');
            app.Time_chanEditField.Limits = [0 Inf];
            app.Time_chanEditField.ValueChangedFcn = createCallbackFcn(app, @Time_chanEditFieldValueChanged, true);
            app.Time_chanEditField.Value = 4096;
            app.Num_lambdaEditFieldLabel = uilabel(app.EfficiencyCalibrationUIFigure);
            app.Num_lambdaEditFieldLabel.Text = 'Num_lambda';
            app.Num_lambdaEditField = uieditfield(app.EfficiencyCalibrationUIFigure, 'numeric');
            app.Num_lambdaEditField.Limits = [0 16];
            app.Num_lambdaEditField.ValueChangedFcn = createCallbackFcn(app, @Num_lambdaEditFieldValueChanged, true);
            app.Num_lambdaEditField.Value = 16;
            app.SavetofileButton = uibutton(app.EfficiencyCalibrationUIFigure, 'push');
            app.SavetofileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetofileButtonPushed, true);
            app.SavetofileButton.Text = 'Save to file';
            app.SavetoworkspaceButton = uibutton(app.EfficiencyCalibrationUIFigure, 'push');
            app.SavetoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoworkspaceButtonPushed, true);
            app.SavetoworkspaceButton.Text = 'Save to workspace';
            app.SpectrometerDropDownLabel = uilabel(app.EfficiencyCalibrationUIFigure);
            app.SpectrometerDropDownLabel.Text = 'Spectrometer';
            app.SpectrometerDropDown = uidropdown(app.EfficiencyCalibrationUIFigure);
            app.SpectrometerDropDown.Items = {'Hammamatsu', 'OceanOptics'};
            app.SpectrometerDropDown.ValueChangedFcn = createCallbackFcn(app, @SpectrometerDropDownValueChanged, true);
            app.SpectrometerDropDown.Value = 'Hammamatsu';
            app.SelectSPCfileButton = uibutton(app.EfficiencyCalibrationUIFigure, 'push');
            app.SelectSPCfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSPCfileButtonPushed, true);
            app.SelectSPCfileButton.Text = 'Select SPC file';
            app.DarkCheckBox = uicheckbox(app.EfficiencyCalibrationUIFigure);
            app.DarkCheckBox.Text = 'Dark';
            app.LoadCalibrationButton = uibutton(app.EfficiencyCalibrationUIFigure, 'push');
            app.LoadCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCalibrationButtonPushed, true);
            app.LoadCalibrationButton.Text = 'Load Calibration';
            app.UpdatePlotButton = uibutton(app.EfficiencyCalibrationUIFigure, 'push');
            app.UpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotButtonPushed, true);
            app.UpdatePlotButton.Text = 'Update Plot';
            app.LambdaCalibLampLabel = uilabel(app.EfficiencyCalibrationUIFigure);
            app.LambdaCalibLampLabel.Text = 'LambdaCalib';
            app.LambdaCalibLamp = uilamp(app.EfficiencyCalibrationUIFigure);
            app.Lambda_chanListBox = uilistbox(app.EfficiencyCalibrationUIFigure);
            app.Lambda_chanListBox.Items = {'515', '525', '535', '545'};
            app.Lambda_chanListBox.Value = '515';
            app.CalculateEfficiencyButton = uibutton(app.EfficiencyCalibrationUIFigure, 'push');
            app.CalculateEfficiencyButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateEfficiencyButtonPushed, true);
            app.CalculateEfficiencyButton.Text = 'Calculate Efficiency';
            app.ClearTableButton = uibutton(app.EfficiencyCalibrationUIFigure, 'push');
            app.ClearTableButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTableButtonPushed, true);
            app.ClearTableButton.Text = 'Clear Table';
            app.XaxisDropDown = uidropdown(app.EfficiencyCalibrationUIFigure);
            app.XaxisDropDown.Items = {'Wavelength (nm)', 'Channels'};
            app.XaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XaxisDropDownValueChanged, true);
            app.XaxisDropDown.Value = 'Wavelength (nm)';
            app.EqualizeefficiencyButton = uibutton(app.EfficiencyCalibrationUIFigure, 'push');
            app.EqualizeefficiencyButton.ButtonPushedFcn = createCallbackFcn(app, @EqualizeefficiencyButtonPushed, true);
            app.EqualizeefficiencyButton.Text = 'Equalize efficiency';
            app.DarkCheckBox_2 = uicheckbox(app.EfficiencyCalibrationUIFigure);
            app.DarkCheckBox_2.Text = 'Dark';
            app.Amplitude_plot = uiaxes(app.EfficiencyCalibrationUIFigure);
            title(app.Amplitude_plot, 'Normalized spectra')
            xlabel(app.Amplitude_plot, 'lambda')
            ylabel(app.Amplitude_plot, 'counts')
            zlabel(app.Amplitude_plot, 'Z')
            app.spc_plot = uiaxes(app.EfficiencyCalibrationUIFigure);
            title(app.spc_plot, 'TCSPC plot')
            xlabel(app.spc_plot, 'channel')
            ylabel(app.spc_plot, 'photon counts')
            zlabel(app.spc_plot, 'Z')
            app.spc_plot.YScale = 'log';
            app.Efficiency_plot = uiaxes(app.EfficiencyCalibrationUIFigure);
            title(app.Efficiency_plot, 'Efficiency')
            ylabel(app.Efficiency_plot, 'counts')
            zlabel(app.Efficiency_plot, 'Z')
            app.TableContextMenu = uicontextmenu(app.EfficiencyCalibrationUIFigure);
            % Assign app.TableContextMenu
            app.UITable.ContextMenu = app.TableContextMenu;
            app.DeleteCellMenu = uimenu(app.TableContextMenu);
            app.DeleteCellMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCellMenuSelected, true);
            app.DeleteCellMenu.Text = 'Delete Cell';
            app.DeleteRowMenu = uimenu(app.TableContextMenu);
            app.DeleteRowMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteRowMenuSelected, true);
            app.DeleteRowMenu.Text = 'Delete Row';
            app.ReplaceFileMenu = uimenu(app.TableContextMenu);
            app.ReplaceFileMenu.MenuSelectedFcn = createCallbackFcn(app, @ReplaceFileMenuSelected, true);
            app.ReplaceFileMenu.Text = 'Replace File';
            % Show the figure after all components are created
            app.EfficiencyCalibrationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Efficiency_Calibration

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EfficiencyCalibrationUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.EfficiencyCalibrationUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EfficiencyCalibrationUIFigure)
        end
    end
end