classdef Spectral_Calibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectralCalibrationUIFigure  matlab.ui.Figure
        UITable                      matlab.ui.control.Table
        SelectFilesButton            matlab.ui.control.Button
        Time_chanEditFieldLabel      matlab.ui.control.Label
        Time_chanEditField           matlab.ui.control.NumericEditField
        Num_lambdaEditFieldLabel     matlab.ui.control.Label
        Num_lambdaEditField          matlab.ui.control.NumericEditField
        SortTableButton              matlab.ui.control.Button
        PolyFitButton                matlab.ui.control.Button
        OrderEditFieldLabel          matlab.ui.control.Label
        OrderEditField               matlab.ui.control.NumericEditField
        SavetofileButton             matlab.ui.control.Button
        SavetoworkspaceButton        matlab.ui.control.Button
        LambdafitListBoxLabel        matlab.ui.control.Label
        LambdafitListBox             matlab.ui.control.ListBox
        spc_plot                     matlab.ui.control.UIAxes
        lambda_plot                  matlab.ui.control.UIAxes
        Amplitude_plot               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        path_lambda % Path of the folder containing B&H files for calibration
        file_lambda % Cell containing files B&H for calibration
        N_lambda = 1    % number of lambda measurements
        SPC_time = 4096;    %default spc data
        SPC_lambda = 16;
        spc         % spc data
        current_file
        lambda_calib
    end
    
    methods (Access = private)
        
        function spc = ReadData(app)
            spc = zeros(app.SPC_time,app.SPC_lambda,app.N_lambda);
            for i = 1:app.N_lambda
                file = app.UITable.Data(i,2);
                spc(:,:,i) = reshape(f_read_sdt_01(...
                    fullfile(app.path_lambda,file{1,1}{1})),[app.SPC_time,...
                    app.SPC_lambda]);
            end
        end
        
        function [] = PlotData(app)
            semilogy(app.spc_plot,reshape(f_read_sdt_01(...
                fullfile(app.path_lambda,app.current_file{1,1}{1})),...
                [app.SPC_time,...
                app.SPC_lambda]))
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            app.SpectralCalibrationUIFigure.Visible = 'off';
            [app.file_lambda,app.path_lambda] = uigetfile('*.sdt',...
                'Select One or More Files', ...
                    'MultiSelect', 'on');
            if isnumeric(app.file_lambda)
                figure(app.SpectralCalibrationUIFigure);
                return;
            end
            figure(app.SpectralCalibrationUIFigure);
            if ~iscell(app.file_lambda) % if 1 file is selected
                app.N_lambda = 1;
            else
                app.N_lambda = size(app.file_lambda,2);
            end
            %% populate the table
            t(:,1) = table(linspace(515,625,app.N_lambda)');
            if ~iscell(app.file_lambda)
                t(1,2) = {app.file_lambda};
            else
                t(:,2) = app.file_lambda';
            end
            
            t(:,3) = table(true*ones(app.N_lambda,1));
            app.UITable.Data = t;
            %% read and plot amplitude data
            app.spc = app.ReadData;
            plot(app.Amplitude_plot,squeeze(sum(app.spc)));
        
            
        end

        % Value changed function: Time_chanEditField
        function Time_chanEditFieldValueChanged(app, event)
            app.SPC_time = app.Time_chanEditField.Value;   
        end

        % Value changed function: Num_lambdaEditField
        function Num_lambdaEditFieldValueChanged(app, event)
            app.SPC_lambda = app.Num_lambdaEditField.Value;           
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            if (indices(2)==2)&&(~ismissing(app.UITable.Data(indices(1),indices(2))))
                app.current_file = app.UITable.Data(indices(1),indices(2));
                app.PlotData;
            end
        end

        % Callback function
        function PlotButtonPushed(app, event)
            PlotData(app);
        end

        % Button pushed function: SortTableButton
        function SortTableButtonPushed(app, event)
            app.UITable.Data = sortrows(app.UITable.Data);
            app.spc = app.ReadData;
        end

        % Button pushed function: PolyFitButton
        function PolyFitButtonPushed(app, event)
            lambda0 = app.UITable.Data{:,1};
            mask = logical(app.UITable.Data{:,3});
            lambda0 = lambda0(mask);
            y = squeeze(sum(app.spc));
            y = y(:,mask);
            [app.lambda_calib,p_baric] = PolynomialFit(lambda0,y,app.OrderEditField.Value);
            plot(app.lambda_plot,lambda0,p_baric,'o',app.lambda_calib,1:app.SPC_lambda);
            app.LambdafitListBox.Items = cellstr(num2str(app.lambda_calib','%.1f'));
        end

        % Button pushed function: SavetofileButton
        function SavetofileButtonPushed(app, event)
            [filesave,pathsave] = uiputfile('*.mat',...
                'Save to mat file');
            lambda = app.lambda_calib;
            save(fullfile(pathsave,filesave),'lambda');
            
        end

        % Button pushed function: SavetoworkspaceButton
        function SavetoworkspaceButtonPushed(app, event)
           lambda = app.lambda_calib;
           prompt = {'Enter variable name:'};
           dlgtitle = 'Input';
           dims = [1 35];
           definput = {'lambda'};
           answer = inputdlg(prompt,dlgtitle,dims,definput);
           assignin('base',answer{1},lambda)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpectralCalibrationUIFigure = uifigure('Visible', 'off');
            app.SpectralCalibrationUIFigure.Name = 'Spectral Calibration';
            app.UITable = uitable(app.SpectralCalibrationUIFigure);
            app.UITable.ColumnName = {'Nominal Wavelength (nm)'; 'File'; 'Valid'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false true];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SelectFilesButton = uibutton(app.SpectralCalibrationUIFigure, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.Time_chanEditFieldLabel = uilabel(app.SpectralCalibrationUIFigure);
            app.Time_chanEditFieldLabel.Text = 'Time_chan';
            app.Time_chanEditField = uieditfield(app.SpectralCalibrationUIFigure, 'numeric');
            app.Time_chanEditField.Limits = [0 Inf];
            app.Time_chanEditField.ValueChangedFcn = createCallbackFcn(app, @Time_chanEditFieldValueChanged, true);
            app.Time_chanEditField.Value = 4096;
            app.Num_lambdaEditFieldLabel = uilabel(app.SpectralCalibrationUIFigure);
            app.Num_lambdaEditFieldLabel.Text = 'Num_lambda';
            app.Num_lambdaEditField = uieditfield(app.SpectralCalibrationUIFigure, 'numeric');
            app.Num_lambdaEditField.Limits = [0 16];
            app.Num_lambdaEditField.ValueChangedFcn = createCallbackFcn(app, @Num_lambdaEditFieldValueChanged, true);
            app.Num_lambdaEditField.Value = 16;
            app.SortTableButton = uibutton(app.SpectralCalibrationUIFigure, 'push');
            app.SortTableButton.ButtonPushedFcn = createCallbackFcn(app, @SortTableButtonPushed, true);
            app.SortTableButton.Text = 'SortTable';
            app.PolyFitButton = uibutton(app.SpectralCalibrationUIFigure, 'push');
            app.PolyFitButton.ButtonPushedFcn = createCallbackFcn(app, @PolyFitButtonPushed, true);
            app.PolyFitButton.Text = 'PolyFit';
            app.OrderEditFieldLabel = uilabel(app.SpectralCalibrationUIFigure);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.SpectralCalibrationUIFigure, 'numeric');
            app.OrderEditField.Limits = [0 10];
            app.OrderEditField.Value = 1;
            app.SavetofileButton = uibutton(app.SpectralCalibrationUIFigure, 'push');
            app.SavetofileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetofileButtonPushed, true);
            app.SavetofileButton.Text = 'Save to file';
            app.SavetoworkspaceButton = uibutton(app.SpectralCalibrationUIFigure, 'push');
            app.SavetoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoworkspaceButtonPushed, true);
            app.SavetoworkspaceButton.Text = 'Save to workspace';
            app.LambdafitListBoxLabel = uilabel(app.SpectralCalibrationUIFigure);
            app.LambdafitListBoxLabel.Text = 'Lambda fit';
            app.LambdafitListBox = uilistbox(app.SpectralCalibrationUIFigure);
            app.spc_plot = uiaxes(app.SpectralCalibrationUIFigure);
            title(app.spc_plot, 'TCSPC plot')
            xlabel(app.spc_plot, 'channel')
            ylabel(app.spc_plot, 'photon counts')
            zlabel(app.spc_plot, 'Z')
            app.spc_plot.YScale = 'log';
            app.lambda_plot = uiaxes(app.SpectralCalibrationUIFigure);
            title(app.lambda_plot, 'Lambda calibration')
            xlabel(app.lambda_plot, 'lambda')
            ylabel(app.lambda_plot, 'lambda channel')
            zlabel(app.lambda_plot, 'Z')
            app.Amplitude_plot = uiaxes(app.SpectralCalibrationUIFigure);
            title(app.Amplitude_plot, 'Amplitude spectrum')
            xlabel(app.Amplitude_plot, 'lambda channel')
            ylabel(app.Amplitude_plot, 'photon counts')
            zlabel(app.Amplitude_plot, 'Z')
            % Show the figure after all components are created
            app.SpectralCalibrationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spectral_Calibration

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SpectralCalibrationUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.SpectralCalibrationUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectralCalibrationUIFigure)
        end
    end
end