classdef APP_CCDFOVPattern < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DrawarectangularROIButton    matlab.ui.control.Button
        LoadROIfromWorkspaceButton   matlab.ui.control.Button
        PixelsizemEditField          matlab.ui.control.NumericEditField
        PixelsizemEditFieldLabel     matlab.ui.control.Label
        FOVsizesidemEditField        matlab.ui.control.NumericEditField
        FOVsizesidemEditFieldLabel   matlab.ui.control.Label
        ResolutionDropDown           matlab.ui.control.DropDown
        ResolutionDropDownLabel      matlab.ui.control.Label
        DrawasquaredROIButton        matlab.ui.control.Button
        Lamp                         matlab.ui.control.Lamp
        LoadPatternusedforCCDButton  matlab.ui.control.Button
        SavepatternButton            matlab.ui.control.Button
        DrawafreeROIButton           matlab.ui.control.Button
        EditField_2                  matlab.ui.control.EditField
        EditField                    matlab.ui.control.EditField
        LoadFOVreferenceButton       matlab.ui.control.Button
        LoadcamerafileButton         matlab.ui.control.Button
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        camera
        fig
        camera_new
        fig_2
        ref_pattern
        ImageROI
        ImageROIsquared
        fig_3
        pos
        pos_squared
        ROI
        ROI_squared
        target_pattern
        flag
        mirrors
        offset
        rect_input
      % Description
    end
    
    methods (Access = private)
        
        function ROIProcessingSquared(app)
            app.pos_squared = round(app.ImageROI.Position);
            size_pattern = sqrt(sum(app.ref_pattern(:)./255));
            factor = size(app.camera_new,1)/size_pattern;
            mirrors = max(app.pos_squared(3:4))/factor;
            mirrors = round(mirrors/str2double(app.ResolutionDropDown.Value))*str2double(app.ResolutionDropDown.Value);
            app.mirrors = mirrors;
            app.ImageROI.Position(3:4) = round([mirrors*factor mirrors*factor]);
            diffx = (app.ImageROI.Position(3)+app.ImageROI.Position(1)-1)-size(app.camera_new,1);
            diffy = (app.ImageROI.Position(4)+app.ImageROI.Position(2)-1)-size(app.camera_new,1);
            if diffx>0
                app.ImageROI.Position(1) = app.ImageROI.Position(1) - diffx - 1;
            end
            if diffy>0
                app.ImageROI.Position(2) = app.ImageROI.Position(2) - diffy - 1 ;
            end
            mask = zeros(size(app.camera_new));
            mask(round(app.ImageROI.Position(2):(app.ImageROI.Position(2)+app.ImageROI.Position(4)-1)),...
                round(app.ImageROI.Position(1):(app.ImageROI.Position(1)+app.ImageROI.Position(3)-1))) = 1;
            mask = 255.*uint8(imresize(mask,[size_pattern size_pattern],'box'));
            [a,~] = find(mask(:)>0);
            [row,col] = ind2sub(size(mask),a(1)); 
            
            app.offset = [row col];
            
            app.fig_3 = imagesc(app.UIAxes3,mask); axis(app.UIAxes3,'image'); colormap(app.UIAxes3,'gray');
            app.PixelsizemEditField.Value = app.FOVsizesidemEditField.Value*round(app.ImageROI.Position(3))/(size(app.camera_new,1)*str2double(app.ResolutionDropDown.Value));  
            
            app.flag = 1;
        end
        function ROIProcessingRect(app)
            app.pos_squared = round(app.ImageROI.Position);
            size_pattern = sqrt(sum(app.ref_pattern(:)./255));
            factor = size(app.camera_new,1)/size_pattern;
            mirrors = app.pos_squared(3:4)/factor;
            mirrors = round(mirrors/app.rect_input(1:2)')*app.rect_input(1:2)';
            %[min_val, min_pos] = min(app.pos_squared(3:4)./factor);
            %mirrors(min_pos) = (max(mirrors)/str2double(app.ResolutionDropDown.Value))*round(min_val/(max(mirrors)/str2double(app.ResolutionDropDown.Value)));
            app.mirrors = mirrors;
            app.ImageROI.Position(3:4) = round(mirrors*factor);
            diffx = (app.ImageROI.Position(3)+app.ImageROI.Position(1)-1)-size(app.camera_new,1);
            diffy = (app.ImageROI.Position(4)+app.ImageROI.Position(2)-1)-size(app.camera_new,1);
            if diffx>0
                app.ImageROI.Position(1) = app.ImageROI.Position(1) - diffx - 1;
            end
            if diffy>0
                app.ImageROI.Position(2) = app.ImageROI.Position(2) - diffy - 1 ;
            end
            mask = zeros(size(app.camera_new));
            mask(round(app.ImageROI.Position(2):(app.ImageROI.Position(2)+app.ImageROI.Position(4)-1)),...
                round(app.ImageROI.Position(1):(app.ImageROI.Position(1)+app.ImageROI.Position(3)-1))) = 1;
            mask = 255.*uint8(imresize(mask,[size_pattern size_pattern],'box'));
            [a,~] = find(mask(:)>0);
            [row,col] = ind2sub(size(mask),a(1)); 
            
            app.offset = [row col];
            
            app.fig_3 = imagesc(app.UIAxes3,mask); axis(app.UIAxes3,'image'); colormap(app.UIAxes3,'gray');
            for_pixel_size = max(app.ImageROI.Position(3:4));
            app.PixelsizemEditField.Value = app.FOVsizesidemEditField.Value*round(for_pixel_size)/(size(app.camera_new,1)*str2double(app.ResolutionDropDown.Value));  
            
            app.flag = 2;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadcamerafileButton
        function LoadcamerafileButtonPushed(app, event)
            [file, path] = uigetfile({'*.mat';'*.SPE'});
            figure(app.UIFigure)
            if file == 0
                return;
            end
            app.EditField.Value = [path file];
            if strcmp(file(end-2:end),'SPE')
                addpath '../Shared/'
               a.camera(:,:,1,2) = loadSPE([path file]);
               a.camera(:,:,1,1) = zeros(size(a.camera,1),size(a.camera,2),1,1);
            else
               a = load([path file]); 
            end
            
            if isfield(a,'cmos')
                app.camera = a.cmos;
                app.camera_new = app.camera;
                app.LoadFOVreferenceButton.Enable = 0;
                app.UIAxes2.Visible = 0;
            else
            app.camera = a.camera;
            app.camera = squeeze(app.camera(:,:,1,2)-app.camera(:,:,1,1));
            app.LoadFOVreferenceButton.Enable = 1;
            end
            app.fig = imagesc(app.UIAxes,app.camera); axis(app.UIAxes,'image');colormap(app.UIAxes,'gray');
        end

        % Button pushed function: LoadFOVreferenceButton
        function LoadFOVreferenceButtonPushed(app, event)
            [file, path] = uigetfile('*.mat');
            figure(app.UIFigure)
            if file == 0
                return;
            end
            app.EditField_2.Value = [path file];
            
            a = load([path file]);
            app.camera_new = fliplr(imwarp(app.camera,a.tform,'OutputView',imref2d(a.pixels)));
            app.fig_2 = imagesc(app.UIAxes2,app.camera_new); axis(app.UIAxes2,'image');colormap(app.UIAxes2,'gray');
        end

        % Button pushed function: LoadPatternusedforCCDButton
        function LoadPatternusedforCCDButtonPushed(app, event)
            [file, path] = uigetfile('*.*');
            if file == 0
                return;
            else
               app.Lamp.Enable = 1; 
            end
            app.ref_pattern = imread([path file]);
        end

        % Button pushed function: DrawafreeROIButton
        function DrawafreeROIButtonPushed(app, event)
            if size(app.ImageROI,1) > 0
                delete(app.ImageROI)
            end
            app.ImageROI = drawpolygon(app.UIAxes2);
            bw = poly2mask(round(app.ImageROI.Position(:,1)),round(app.ImageROI.Position(:,2)),size(app.camera_new,1),size(app.camera_new,2));
                        
            [a,~] = find(app.ref_pattern(:)>0);
            [row,col] = ind2sub(size(app.ref_pattern),a(1)); 
            size_pattern = sqrt(sum(app.ref_pattern(:)./255));
            app.ROI = 255.*uint8(imresize(bw,[size_pattern size_pattern],'box'));
            app.fig_3 = imagesc(app.UIAxes3,bw); axis(app.UIAxes3,'image'); colormap(app.UIAxes3,'gray');
            
            
            % Pattern
            app.target_pattern = uint8(zeros(size(app.ref_pattern)));
            app.target_pattern(row:(row+size_pattern-1),col:(col+size_pattern-1)) = app.ROI;
            
            app.flag = 0;
        end

        % Button pushed function: SavepatternButton
        function SavepatternButtonPushed(app, event)
            if app.flag == 0
            s = im2uint8(app.target_pattern);
            imwrite(s,"TargetPattern.png");
            elseif app.flag == 1
            assignin("base",'offset',app.offset);
            assignin("base",'mirrors',app.mirrors);
            assignin("base",'order',str2double(app.ResolutionDropDown.Value));
            assignin("base",'orig_pattern_size',sqrt(sum(app.ref_pattern(:)./255)));
            assignin("base",'generate_custom_roi',app.ImageROI.Position);
            
            APP_CSPatternGenerator; 
            elseif app.flag == 2
                dim = sqrt(sum(app.ref_pattern(:)./255));
                stack = zeros([app.rect_input(1:2)' prod(app.rect_input(1:2))]);
                k = 1;
                for yi = 1:size(stack,1)
                    for xi = 1:size(stack,2)
                        stack(yi,xi,k) = 1;
                        k = k+1;
                    end
                end
                NbanksTCSPC = 32;
                if NbanksTCSPC*ceil((k-1)/NbanksTCSPC)-(k-1)> 0
                stack(:,:,k:NbanksTCSPC*ceil((k-1)/NbanksTCSPC)) = zeros(size(stack,1),size(stack,2),NbanksTCSPC*ceil((k-1)/NbanksTCSPC)-(k-1));
                end
                for i = 1:(k-1)
                    rstack(:,:,i) = imresize(squeeze(stack(:,:,i)),app.mirrors,'box');
                end
                sstack = zeros(dim,dim,k-1);
                sstack(app.offset(2):(app.offset(2)+app.mirrors(1)-1),app.offset(1):(app.offset(1)+app.mirrors(2)-1),:) = rstack;
                P = CSpattern('raster_scan',32);
                P.stack = sstack;
                P.dim = [dim dim];
                P = P.padToFitDim([1024 768]);
                path = P.SaveStack(8,'hdf','smallscan'); %first entry is the bit depth
                assignin("base",'generate_custom_roi',app.ImageROI.Position);
                custom_ROI = app.ImageROI.Position;
                flagFOV = app.flag;
                save([path,'.mat'],'custom_ROI','flagFOV');
            end
        end

        % Button pushed function: DrawasquaredROIButton
        function DrawasquaredROIButtonPushed(app, event)
            if size(app.ImageROI,1) > 0
                delete(app.ImageROI)
            end
            app.ImageROI = drawrectangle(app.UIAxes2);
            
            ROIProcessingSquared(app);
                      
        end

        % Button pushed function: LoadROIfromWorkspaceButton
        function LoadROIfromWorkspaceButtonPushed(app, event)
            if size(app.ImageROI,1) > 0
                delete(app.ImageROI)
            end
            
            try custom_ROI = evalin("base","generate_custom_roi");       
            catch ME
                [file, path] = uigetfile('*.*');
                if file == 0
                    disp('Custom ROI to be load not found.')
                    return;
                else
                   load([path file]);
                end            
            end
            app.ImageROI = drawrectangle(app.UIAxes2,'Position',custom_ROI);
            if custom_ROI(3)==custom_ROI(4)
            ROIProcessingSquared(app);
            else
             ROIProcessingRect(app);
            end
         
            assignin("base",'generate_custom_roi',custom_ROI);

            
        end

        % Button pushed function: DrawarectangularROIButton
        function DrawarectangularROIButtonPushed(app, event)
            if size(app.ImageROI,1) > 0
                delete(app.ImageROI)
            end
            size_pattern = sqrt(sum(app.ref_pattern(:)./255));
            factor = size(app.camera_new,1)/size_pattern;
                        
            point = drawpoint(app.UIAxes2);
            input_choice = {'Num pix X', 'Num pix Y','Pixel size:'};
            definput = {'32' '32' '1.5'};
            data = str2double(inputdlg(input_choice,'',[1 30],definput));
            ImageROI = [point.Position data(1:2)'*data(3)/factor];
            app.rect_input = data;
            app.ImageROI = rectangle(app.UIAxes2,'Position',ImageROI);
            delete(point)
            ROIProcessingRect(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Pattern')
            app.LoadcamerafileButton = uibutton(app.UIFigure, 'push');
            app.LoadcamerafileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcamerafileButtonPushed, true);
            app.LoadcamerafileButton.Text = 'Load camera file';
            app.LoadFOVreferenceButton = uibutton(app.UIFigure, 'push');
            app.LoadFOVreferenceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFOVreferenceButtonPushed, true);
            app.LoadFOVreferenceButton.Text = 'Load FOV reference';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.DrawafreeROIButton = uibutton(app.UIFigure, 'push');
            app.DrawafreeROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawafreeROIButtonPushed, true);
            app.DrawafreeROIButton.Text = 'Draw a free ROI';
            app.SavepatternButton = uibutton(app.UIFigure, 'push');
            app.SavepatternButton.ButtonPushedFcn = createCallbackFcn(app, @SavepatternButtonPushed, true);
            app.SavepatternButton.Text = 'Save pattern';
            app.LoadPatternusedforCCDButton = uibutton(app.UIFigure, 'push');
            app.LoadPatternusedforCCDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPatternusedforCCDButtonPushed, true);
            app.LoadPatternusedforCCDButton.Text = 'Load Pattern used for CCD';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Enable = 'off';
            app.DrawasquaredROIButton = uibutton(app.UIFigure, 'push');
            app.DrawasquaredROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawasquaredROIButtonPushed, true);
            app.DrawasquaredROIButton.Text = 'Draw a squared ROI';
            app.ResolutionDropDownLabel = uilabel(app.UIFigure);
            app.ResolutionDropDownLabel.Text = 'Resolution';
            app.ResolutionDropDown = uidropdown(app.UIFigure);
            app.ResolutionDropDown.Items = {'4', '8', '16', '32', '64'};
            app.ResolutionDropDown.Value = '32';
            app.FOVsizesidemEditFieldLabel = uilabel(app.UIFigure);
            app.FOVsizesidemEditFieldLabel.Text = 'FOV size (side) [μm]';
            app.FOVsizesidemEditField = uieditfield(app.UIFigure, 'numeric');
            app.FOVsizesidemEditField.Value = 160;
            app.PixelsizemEditFieldLabel = uilabel(app.UIFigure);
            app.PixelsizemEditFieldLabel.Text = 'Pixel size [μm]';
            app.PixelsizemEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelsizemEditField.Editable = 'off';
            app.LoadROIfromWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.LoadROIfromWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadROIfromWorkspaceButtonPushed, true);
            app.LoadROIfromWorkspaceButton.Text = 'Load ROI from Workspace';
            app.DrawarectangularROIButton = uibutton(app.UIFigure, 'push');
            app.DrawarectangularROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawarectangularROIButtonPushed, true);
            app.DrawarectangularROIButton.Text = 'Draw a rectangular ROI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_CCDFOVPattern

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end