classdef APP_CSPatternGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        OrderDropDownLabel             matlab.ui.control.Label
        OrderDropDown                  matlab.ui.control.DropDown
        TypeDropDownLabel              matlab.ui.control.Label
        TypeDropDown                   matlab.ui.control.DropDown
        SeedEditFieldLabel             matlab.ui.control.Label
        SeedEditField                  matlab.ui.control.NumericEditField
        CWCheckBox                     matlab.ui.control.CheckBox
        TransposestackCheckBox         matlab.ui.control.CheckBox
        Switch                         matlab.ui.control.Switch
        NumberofnegsEditFieldLabel     matlab.ui.control.Label
        NumberofnegsEditField          matlab.ui.control.NumericEditField
        IncludeoffsetfromCCDFOVCheckBox  matlab.ui.control.CheckBox
        IncludesizefromCCDFOVCheckBox  matlab.ui.control.CheckBox
        OutputfileDropDownLabel        matlab.ui.control.Label
        OutputfileDropDown             matlab.ui.control.DropDown
        GenerateButton                 matlab.ui.control.Button
        NbanksTCSPCEditFieldLabel      matlab.ui.control.Label
        NbanksTCSPCEditField           matlab.ui.control.NumericEditField
        CurvebankTCSPCEditFieldLabel   matlab.ui.control.Label
        CurvebankTCSPCEditField        matlab.ui.control.NumericEditField
        FilenamesuffixEditFieldLabel   matlab.ui.control.Label
        FilenamesuffixEditField        matlab.ui.control.EditField
    end

    
    properties (Access = public)
        mirrors
        order
        orig_pattern_size % Description
        offset
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.mirrors = evalin("base",'mirrors');
            app.order = evalin("base",'order');
            app.orig_pattern_size = evalin("base",'orig_pattern_size');
            app.offset = evalin("base",'offset');
            app.OrderDropDown.Value = num2str(app.order);
            
            app.NbanksTCSPCEditField.Value = ceil((app.order^2+app.NumberofnegsEditField.Value)/app.CurvebankTCSPCEditField.Value);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'PosNeg')
                app.NumberofnegsEditField.Enable = 0;
                app.NbanksTCSPCEditField.Value = ceil((2*app.order^2)/app.CurvebankTCSPCEditField.Value);
            else
                app.NumberofnegsEditField.Enable = 1;
                app.NbanksTCSPCEditField.Value = ceil((app.order^2+app.NumberofnegsEditField.Value)/app.CurvebankTCSPCEditField.Value);
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            P = CSpattern(app.TypeDropDown.Value,app.order,app.SeedEditField.Value);
            
            if not(strcmp(app.TypeDropDown.Value,'raster_scan'))
                P = P.ConvertToPosNeg;
            end
            
            if app.TransposestackCheckBox.Value == 1
                P = P.TransposeStack;
            end
            
            if app.CWCheckBox.Value == 0
                P = P.TurnOffCW;
            end
            
            if strcmp(app.Switch.Value,'CW Shifted')
                P = P.ConvertToCWShifted(app.NumberofnegsEditField.Value);
                P.stack(:,:,(app.order^2+app.NumberofnegsEditField.Value+1):app.NbanksTCSPCEditField.Value*app.CurvebankTCSPCEditField.Value) = zeros(app.order,app.order,app.NbanksTCSPCEditField.Value*app.CurvebankTCSPCEditField.Value - app.order^2 - app.NumberofnegsEditField.Value);
                P.Mmatrix((app.order^2+app.NumberofnegsEditField.Value+1):app.NbanksTCSPCEditField.Value*app.CurvebankTCSPCEditField.Value,:) = zeros(app.NbanksTCSPCEditField.Value*app.CurvebankTCSPCEditField.Value - app.order^2 - app.NumberofnegsEditField.Value,app.order^2);
                P.Npatt = size(P.Mmatrix,1);
            end
            
            if app.IncludesizefromCCDFOVCheckBox.Value == 0
                app.mirrors = app.orig_pattern_size;
            end
            
            P = P.resample([app.mirrors app.mirrors]);
            
            if app.IncludeoffsetfromCCDFOVCheckBox.Value == 1
                % Apply offset
                rstack = zeros(app.orig_pattern_size, app.orig_pattern_size,P.Npatt);
                rstack(app.offset(2):(app.offset(2)+app.mirrors-1),app.offset(1):(app.offset(1)+app.mirrors-1),:) = P.stack;
                P.stack = rstack;
            end
            P.dim = size(P.stack,1:2);
            P = P.padToFitDim([1024 768]);
            
            if isempty(app.FilenamesuffixEditField.Value)
                path = P.SaveStack(8,app.OutputfileDropDown.Value); %first entry is the bit depth
            else
                path = P.SaveStack(8,app.OutputfileDropDown.Value,app.FilenamesuffixEditField.Value); %first entry is the bit depth
            end
            position = squeeze(sum(P.stack,3));
            position(position>0) = 1;
            custom_ROI = evalin('base','generate_custom_roi');
            save([path,'.mat'],'custom_ROI','position');
            
            delete(app);
        end

        % Value changed function: OrderDropDown
        function OrderDropDownValueChanged(app, event)
            value = app.OrderDropDown.Value;
            app.order = str2double(value);
        end

        % Value changed function: NumberofnegsEditField
        function NumberofnegsEditFieldValueChanged(app, event)
            app.NbanksTCSPCEditField.Value = ceil((app.order^2+app.NumberofnegsEditField.Value)/app.CurvebankTCSPCEditField.Value);
        end

        % Value changed function: CurvebankTCSPCEditField
        function CurvebankTCSPCEditFieldValueChanged(app, event)
            app.NbanksTCSPCEditField.Value = ceil((app.order^2+app.NumberofnegsEditField.Value)/app.CurvebankTCSPCEditField.Value);
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            switch value
                case 'raster_scan'
                    app.TransposestackCheckBox.Value = 1;
                    app.CWCheckBox.Value = 1;
                    app.CWCheckBox.Enable = 0;
                    app.NumberofnegsEditField.Value = 0;
                    app.NumberofnegsEditField.Enable = 0;
                    app.Switch.Enable = 0;
                    app.NbanksTCSPCEditField.Value = ceil((app.order^2)/app.CurvebankTCSPCEditField.Value);
                otherwise
                    app.CWCheckBox.Enable = 1;
                    app.CWCheckBox.Value = 0;
                    app.NumberofnegsEditField.Value = 40;
                    app.NumberofnegsEditField.Enable = 1;
                    app.Switch.Enable = 1;
                    app.SwitchValueChanged;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OrderDropDownLabel = uilabel(app.UIFigure);
            app.OrderDropDownLabel.Text = 'Order';
            app.OrderDropDown = uidropdown(app.UIFigure);
            app.OrderDropDown.Items = {'4', '8', '16', '32', '64'};
            app.OrderDropDown.ValueChangedFcn = createCallbackFcn(app, @OrderDropDownValueChanged, true);
            app.OrderDropDown.Value = '32';
            app.TypeDropDownLabel = uilabel(app.UIFigure);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.UIFigure);
            app.TypeDropDown.Items = {'wh', 'scrambledh', 'raster_scan'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'scrambledh';
            app.SeedEditFieldLabel = uilabel(app.UIFigure);
            app.SeedEditFieldLabel.Text = 'Seed';
            app.SeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.SeedEditField.Value = 222;
            app.CWCheckBox = uicheckbox(app.UIFigure);
            app.CWCheckBox.Text = 'CW';
            app.TransposestackCheckBox = uicheckbox(app.UIFigure);
            app.TransposestackCheckBox.Text = 'Transpose stack';
            app.TransposestackCheckBox.Value = true;
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'PosNeg', 'CW Shifted'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'CW Shifted';
            app.NumberofnegsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofnegsEditFieldLabel.Text = 'Number of negs';
            app.NumberofnegsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofnegsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofnegsEditFieldValueChanged, true);
            app.NumberofnegsEditField.Value = 40;
            app.IncludeoffsetfromCCDFOVCheckBox = uicheckbox(app.UIFigure);
            app.IncludeoffsetfromCCDFOVCheckBox.Text = 'Include offset from CCD FOV';
            app.IncludeoffsetfromCCDFOVCheckBox.Value = true;
            app.IncludesizefromCCDFOVCheckBox = uicheckbox(app.UIFigure);
            app.IncludesizefromCCDFOVCheckBox.Text = 'Include size from CCD FOV';
            app.IncludesizefromCCDFOVCheckBox.Value = true;
            app.OutputfileDropDownLabel = uilabel(app.UIFigure);
            app.OutputfileDropDownLabel.Text = 'Output file';
            app.OutputfileDropDown = uidropdown(app.UIFigure);
            app.OutputfileDropDown.Items = {'hdf', 'png', 'tif', 'mat'};
            app.OutputfileDropDown.Value = 'hdf';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.NbanksTCSPCEditFieldLabel = uilabel(app.UIFigure);
            app.NbanksTCSPCEditFieldLabel.Text = 'Nbanks TCSPC';
            app.NbanksTCSPCEditField = uieditfield(app.UIFigure, 'numeric');
            app.NbanksTCSPCEditField.Value = 34;
            app.CurvebankTCSPCEditFieldLabel = uilabel(app.UIFigure);
            app.CurvebankTCSPCEditFieldLabel.Text = 'Curve/bank TCSPC';
            app.CurvebankTCSPCEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurvebankTCSPCEditField.ValueChangedFcn = createCallbackFcn(app, @CurvebankTCSPCEditFieldValueChanged, true);
            app.CurvebankTCSPCEditField.Value = 32;
            app.FilenamesuffixEditFieldLabel = uilabel(app.UIFigure);
            app.FilenamesuffixEditFieldLabel.Text = 'Filename suffix';
            app.FilenamesuffixEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_CSPatternGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end