classdef APP_CreateDMDPattern < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PatterngenerationLabel       matlab.ui.control.Label
        TemplatepathEditFieldLabel   matlab.ui.control.Label
        TemplatepathEditField        matlab.ui.control.EditField
        NofpatternsEditFieldLabel    matlab.ui.control.Label
        NofpatternsEditField         matlab.ui.control.EditField
        InputPrefixEditFieldLabel    matlab.ui.control.Label
        InputPrefixEditField         matlab.ui.control.EditField
        LoadcalibrationButton        matlab.ui.control.Button
        CalibLamp                    matlab.ui.control.Lamp
        PathButton                   matlab.ui.control.Button
        RotationEditFieldLabel       matlab.ui.control.Label
        RotationEditField            matlab.ui.control.NumericEditField
        MagnificationEditFieldLabel  matlab.ui.control.Label
        MagnificationEditField       matlab.ui.control.NumericEditField
        XoffsetEditFieldLabel        matlab.ui.control.Label
        XoffsetEditField             matlab.ui.control.NumericEditField
        YoffsetEditFieldLabel        matlab.ui.control.Label
        YoffsetEditField             matlab.ui.control.NumericEditField
        GeneratepatternButton        matlab.ui.control.Button
        SelectButton                 matlab.ui.control.Button
        OutputfolderEditFieldLabel   matlab.ui.control.Label
        OutputfolderEditField        matlab.ui.control.EditField
        UnloadButton                 matlab.ui.control.Button
        OutputprefixEditFieldLabel   matlab.ui.control.Label
        OutputprefixEditField        matlab.ui.control.EditField
        DisplayPatternSpinnerLabel   matlab.ui.control.Label
        DisplayPatternSpinner        matlab.ui.control.Spinner
        Switch                       matlab.ui.control.Switch
        BitDepthSpinnerLabel         matlab.ui.control.Label
        BitDepthSpinner              matlab.ui.control.Spinner
        SaveCWCheckBox               matlab.ui.control.CheckBox
        NegativeCheckBox             matlab.ui.control.CheckBox
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        dmd_area % Description
        pattern_preview
        tform
        pixels
    end
    
    methods (Access = private)
        
        function results = refresh_image(app)
            filename = [app.TemplatepathEditField.Value filesep app.InputPrefixEditField.Value '_',num2str(app.DisplayPatternSpinner.Value-1),'.png'];
            if exist(filename)>0
                image = imread(filename);
            else
                return
            end
            
            app.dmd_area = uint8(zeros(768,1024));
            init_dmd = size(app.dmd_area);
            image = imrotate(image,app.RotationEditField.Value,'nearest');
            
            if not(size(app.tform,1)==0)
            image = imwarp(image,app.tform,'OutputView',imref2d(app.pixels));
            end
            
            if app.MagnificationEditField.Value>(size(app.dmd_area,1)/size(image,1))
                app.MagnificationEditField.Value = floor(100.*size(app.dmd_area,1)./size(image,1))/100;
            end
            
            if not(app.RotationEditField.Value) == 0
            K = sqrt(str2double(app.NofpatternsEditField.Value)/2);
            S_im = size(image,1)/(abs(sin(app.RotationEditField.Value))+abs(cos(app.RotationEditField.Value)));
            app.MagnificationEditField.Value = round(K*floor(S_im*app.MagnificationEditField.Value/K)/S_im,2);
            end
            
            image = imresize(image,app.MagnificationEditField.Value,'nearest');
            %image = 255.*image;
            
            %% OFFSET
            xstart = floor((size(app.dmd_area,2)-size(image,2))/2) + app.XoffsetEditField.Value; 
            if xstart<1
                xstart = 1;
                app.XoffsetEditField.Value = 1 - floor((size(app.dmd_area,2)-size(image,2))/2);
            elseif (xstart + size(image,2) - 1) > size(app.dmd_area,2)
                app.XoffsetEditField.Value = 1-floor((size(app.dmd_area,2)-size(image,2))/2) - size(image,2)  + size(app.dmd_area,2);
                xstart = floor((size(app.dmd_area,2)-size(image,2))/2) + app.XoffsetEditField.Value;
            end
            xstop = xstart + size(image,2) - 1;
            ystart = floor((size(app.dmd_area,1)-size(image,1))/2) + app.YoffsetEditField.Value;
            if ystart<1
                ystart = 1;
                app.YoffsetEditField.Value = 1 - floor((size(app.dmd_area,1)-size(image,1))/2);
            elseif (ystart + size(image,1) - 1) > size(app.dmd_area,1)
                app.YoffsetEditField.Value = 1-floor((size(app.dmd_area,1)-size(image,1))/2) - size(image,1)  + size(app.dmd_area,1);
                ystart = floor((size(app.dmd_area,1)-size(image,1))/2) + app.YoffsetEditField.Value;
            end
            ystop = ystart + size(image,1) - 1;
            
            app.dmd_area(ystart:ystop,xstart:xstop) = image;
            app.dmd_area = uint8(app.dmd_area(1:init_dmd(1),1:init_dmd(2)));
            if app.NegativeCheckBox.Value == 1
               app.dmd_area = 255-app.dmd_area;    
            end
            
            app.pattern_preview.CData = app.dmd_area;
            drawnow
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dmd_area = uint8(zeros(768,1024));
            app.pattern_preview = imagesc(app.UIAxes,app.dmd_area);
            axis(app.UIAxes,'image');
            colormap(app.UIAxes,'gray');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: PathButton
        function PathButtonPushed(app, event)
            path = uigetdir;
            figure(app.UIFigure)
            if path == 0
                return;
            end
            
            app.TemplatepathEditField.Value = path;
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            path = uigetdir;
            figure(app.UIFigure)
            if path == 0
                return;
            end
            app.OutputfolderEditField.Value = path;
            app.GeneratepatternButton.Enable = 1;
            
            refresh_image(app);
        end

        % Value changed function: MagnificationEditField
        function MagnificationEditFieldValueChanged(app, event)
            refresh_image(app);
            
        end

        % Value changed function: RotationEditField
        function RotationEditFieldValueChanged(app, event)
            
            refresh_image(app);
        end

        % Value changed function: XoffsetEditField
        function XoffsetEditFieldValueChanged(app, event)
            refresh_image(app);
        end

        % Value changed function: YoffsetEditField
        function YoffsetEditFieldValueChanged(app, event)
            
            refresh_image(app);
        end

        % Value changed function: DisplayPatternSpinner
        function DisplayPatternSpinnerValueChanged(app, event)
            if app.DisplayPatternSpinner.Value > str2double(app.NofpatternsEditField.Value)
                app.DisplayPatternSpinner.Value = str2double(app.NofpatternsEditField.Value)-1;
            end
            refresh_image(app);
        end

        % Button pushed function: GeneratepatternButton
        function GeneratepatternButtonPushed(app, event)
            for i = 1:str2num(app.NofpatternsEditField.Value)
                
                filename = [app.TemplatepathEditField.Value filesep app.InputPrefixEditField.Value '_',num2str(i-1),'.png'];
                if ~exist(filename)
                    warndlg('Number of required patterns exceed templates.')
                    break
                end
                image = imread(filename);
                
                if app.SaveCWCheckBox.Value == 0 && (sum(image,1:2) == size(image,1)*size(image,2)*255)
                    image = uint8(zeros(size(image,1),size(image,2)));
                end
                
                app.dmd_area = uint8(zeros(768,1024));
                init_dmd = size(app.dmd_area);
                image = imrotate(image,app.RotationEditField.Value,'nearest');
                image = imresize(image,app.MagnificationEditField.Value,'box');
                %image = 255.*image;
                xstart = floor((size(app.dmd_area,2)-size(image,2))/2) + app.XoffsetEditField.Value;
                xstop = xstart + size(image,2) - 1;
                ystart = floor((size(app.dmd_area,1)-size(image,1))/2) + app.YoffsetEditField.Value;
                ystop = ystart + size(image,1) - 1;
                
                app.dmd_area(ystart:ystop,xstart:xstop) = image;
                app.dmd_area = uint8(app.dmd_area(1:init_dmd(1),1:init_dmd(2)));
                app.pattern_preview.CData = app.dmd_area;
                drawnow
                filename_out = [app.OutputfolderEditField.Value filesep app.OutputprefixEditField.Value];
                
                if app.NegativeCheckBox.Value == 1
                    app.dmd_area = 255-app.dmd_area;
                end
                
                switch app.BitDepthSpinner.Value
                    case 1
                        app.dmd_area = logical(app.dmd_area);
                    case 8
                        app.dmd_area = uint8(app.dmd_area);
                end

                if strcmp(app.Switch.Value,'PNG')
                imwrite(app.dmd_area, [filename_out,'_',num2str(i),'.png'],'png');
                else
                    if i ==1
                    imwrite(app.dmd_area', [filename_out,'.tif'],'tif');
                    else
                    imwrite(app.dmd_area', [filename_out,'.tif'],'WriteMode','append');
                    end
                end
            end
            if strcmp(app.Switch.Value,'PNG')
            % Add a 0 pattern for background measurement
            if app.NegativeCheckBox.Value == 0
            imwrite(zeros(768,1024), [app.OutputfolderEditField.Value filesep app.OutputprefixEditField.Value, '_',num2str(0),'.png'],'png');
            else
                imwrite(255.*ones(768,1024), [app.OutputfolderEditField.Value filesep app.OutputprefixEditField.Value, '_',num2str(0),'.png'],'png');
            
            end
            end
            
            rotation = app.RotationEditField.Value;
            magnification = app.MagnificationEditField.Value;
            xoffset = app.XoffsetEditField.Value;
            yoffset = app.YoffsetEditField.Value;
            
            save([app.OutputfolderEditField.Value, filesep,app.OutputprefixEditField.Value,'_Pattern_settings.mat'],'rotation','magnification','xoffset','yoffset')
            
        end

        % Button pushed function: LoadcalibrationButton
        function LoadcalibrationButtonPushed(app, event)
            [file, path] = uigetfile('*.mat');
            figure(app.UIFigure)
            if path == 0
                return;
            end
            a = load([path file]);
            app.tform = invert(a.tform);
            app.pixels = a.pixels;
            app.RotationEditField.Value = 0;
            app.MagnificationEditField.Value = 1;
            refresh_image(app);
            app.CalibLamp.Enable = 1;
            app.UnloadButton.Enable = 1;
        end

        % Button pushed function: UnloadButton
        function UnloadButtonPushed(app, event)
            app.CalibLamp.Enable = 0;
            app.RotationEditField.Value = 1;
            app.tform = [];
            app.pixels = [];
            app.UnloadButton.Enable = 0;
            refresh_image(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PatterngenerationLabel = uilabel(app.UIFigure);
            app.PatterngenerationLabel.Text = 'Pattern generation';
            app.TemplatepathEditFieldLabel = uilabel(app.UIFigure);
            app.TemplatepathEditFieldLabel.Text = 'Template path';
            app.TemplatepathEditField = uieditfield(app.UIFigure, 'text');
            app.NofpatternsEditFieldLabel = uilabel(app.UIFigure);
            app.NofpatternsEditFieldLabel.Text = 'N. of patterns';
            app.NofpatternsEditField = uieditfield(app.UIFigure, 'text');
            app.NofpatternsEditField.Value = '2048';
            app.InputPrefixEditFieldLabel = uilabel(app.UIFigure);
            app.InputPrefixEditFieldLabel.Text = 'Input Prefix';
            app.InputPrefixEditField = uieditfield(app.UIFigure, 'text');
            app.InputPrefixEditField.Value = 'SH_32';
            app.LoadcalibrationButton = uibutton(app.UIFigure, 'push');
            app.LoadcalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcalibrationButtonPushed, true);
            app.LoadcalibrationButton.Enable = 'off';
            app.LoadcalibrationButton.Text = 'Load calibration';
            app.CalibLamp = uilamp(app.UIFigure);
            app.CalibLamp.Enable = 'off';
            app.PathButton = uibutton(app.UIFigure, 'push');
            app.PathButton.ButtonPushedFcn = createCallbackFcn(app, @PathButtonPushed, true);
            app.PathButton.Text = 'Path';
            app.RotationEditFieldLabel = uilabel(app.UIFigure);
            app.RotationEditFieldLabel.Text = 'Rotation';
            app.RotationEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotationEditField.ValueChangedFcn = createCallbackFcn(app, @RotationEditFieldValueChanged, true);
            app.MagnificationEditFieldLabel = uilabel(app.UIFigure);
            app.MagnificationEditFieldLabel.Text = 'Magnification';
            app.MagnificationEditField = uieditfield(app.UIFigure, 'numeric');
            app.MagnificationEditField.Limits = [0 Inf];
            app.MagnificationEditField.ValueChangedFcn = createCallbackFcn(app, @MagnificationEditFieldValueChanged, true);
            app.MagnificationEditField.Value = 0.5;
            app.XoffsetEditFieldLabel = uilabel(app.UIFigure);
            app.XoffsetEditFieldLabel.Text = 'X offset';
            app.XoffsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.XoffsetEditField.ValueChangedFcn = createCallbackFcn(app, @XoffsetEditFieldValueChanged, true);
            app.YoffsetEditFieldLabel = uilabel(app.UIFigure);
            app.YoffsetEditFieldLabel.Text = 'Y offset';
            app.YoffsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.YoffsetEditField.ValueChangedFcn = createCallbackFcn(app, @YoffsetEditFieldValueChanged, true);
            app.GeneratepatternButton = uibutton(app.UIFigure, 'push');
            app.GeneratepatternButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratepatternButtonPushed, true);
            app.GeneratepatternButton.Enable = 'off';
            app.GeneratepatternButton.Text = 'Generate pattern';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.OutputfolderEditFieldLabel = uilabel(app.UIFigure);
            app.OutputfolderEditFieldLabel.Text = 'Output folder';
            app.OutputfolderEditField = uieditfield(app.UIFigure, 'text');
            app.UnloadButton = uibutton(app.UIFigure, 'push');
            app.UnloadButton.ButtonPushedFcn = createCallbackFcn(app, @UnloadButtonPushed, true);
            app.UnloadButton.Enable = 'off';
            app.UnloadButton.Text = 'Unload';
            app.OutputprefixEditFieldLabel = uilabel(app.UIFigure);
            app.OutputprefixEditFieldLabel.Text = 'Output prefix';
            app.OutputprefixEditField = uieditfield(app.UIFigure, 'text');
            app.OutputprefixEditField.Value = 'SH_32_DMD1_0';
            app.DisplayPatternSpinnerLabel = uilabel(app.UIFigure);
            app.DisplayPatternSpinnerLabel.Text = 'Display Pattern';
            app.DisplayPatternSpinner = uispinner(app.UIFigure);
            app.DisplayPatternSpinner.Limits = [1 Inf];
            app.DisplayPatternSpinner.ValueChangedFcn = createCallbackFcn(app, @DisplayPatternSpinnerValueChanged, true);
            app.DisplayPatternSpinner.Value = 1;
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'PNG', 'TIF'};
            app.Switch.Value = 'PNG';
            app.BitDepthSpinnerLabel = uilabel(app.UIFigure);
            app.BitDepthSpinnerLabel.Text = 'Bit Depth';
            app.BitDepthSpinner = uispinner(app.UIFigure);
            app.BitDepthSpinner.Step = 8;
            app.BitDepthSpinner.Limits = [1 8];
            app.BitDepthSpinner.Value = 8;
            app.SaveCWCheckBox = uicheckbox(app.UIFigure);
            app.SaveCWCheckBox.Text = 'Save CW';
            app.SaveCWCheckBox.Value = true;
            app.NegativeCheckBox = uicheckbox(app.UIFigure);
            app.NegativeCheckBox.Text = 'Negative';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_CreateDMDPattern

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end